classdef DSE_v400 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiscontinuitySetExtractor4UIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        menu_loadxyz                    matlab.ui.container.Menu
        menu_loadddb                    matlab.ui.container.Menu
        menu_saveddbb                   matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        NormalspacingMenu               matlab.ui.container.Menu
        PersistenceMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool_Load3DP                matlab.ui.container.toolbar.PushTool
        PushTool_LoadProject            matlab.ui.container.toolbar.PushTool
        PushTool_SaveProject            matlab.ui.container.toolbar.PushTool
        PushTool_Clear                  matlab.ui.container.toolbar.PushTool
        PushTool_Colouring_normals      matlab.ui.container.toolbar.PushTool
        Normals_Panel                   matlab.ui.container.Panel
        CoplanaritytestSwitchLabel      matlab.ui.control.Label
        box_tolerancia                  matlab.ui.control.EditField
        toleranceEditFieldLabel         matlab.ui.control.Label
        CoplanaritytestSwitch           matlab.ui.control.RockerSwitch
        box_nneighbours                 matlab.ui.control.EditField
        knnEditFieldLabel               matlab.ui.control.Label
        pushbutton_2setupplanes         matlab.ui.control.Button
        LogListBoxLabel                 matlab.ui.control.Label
        STEP2DensityandprincipalpolesextractionPanel  matlab.ui.container.Panel
        UITable_principalplanes         matlab.ui.control.Table
        NmaxDSEditField                 matlab.ui.control.NumericEditField
        NmaxDSEditFieldLabel            matlab.ui.control.Label
        pushbutton_31editppalpoles      matlab.ui.control.Button
        MinangleEditField               matlab.ui.control.NumericEditField
        MinangleLabel                   matlab.ui.control.Label
        BinslevelSpinner                matlab.ui.control.Spinner
        BinslevelSpinnerLabel           matlab.ui.control.Label
        pushbutton_3statanalysis        matlab.ui.control.Button
        ClassifypointsassignedtoeachDiscontinuitySetPanel  matlab.ui.container.Panel
        ConeEditField                   matlab.ui.control.NumericEditField
        ConeLabel                       matlab.ui.control.Label
        Button_assign_ppalpoles         matlab.ui.control.Button
        ClusteranalysisPanel            matlab.ui.container.Panel
        UITable_clusters                matlab.ui.control.Table
        ksigmasEditField                matlab.ui.control.NumericEditField
        ksigmasEditFieldLabel           matlab.ui.control.Label
        Clusters_restore                matlab.ui.control.Button
        Clusters_merge                  matlab.ui.control.Button
        DBSCAN_edit                     matlab.ui.control.Button
        FixnormalvectorCheckBox         matlab.ui.control.CheckBox
        DBSCAN                          matlab.ui.control.Button
        Displays                        matlab.ui.container.Panel
        pushbutton_plot3dpointsppal     matlab.ui.control.Button
        pushbutton_plotstereopolesppal  matlab.ui.control.Button
        pushbutton_plotdensitypoles     matlab.ui.control.Button
        pushbutton_plotstereopoles      matlab.ui.control.Button
        pushbutton_plot3dpoints         matlab.ui.control.Button
        ExportdataPanel                 matlab.ui.container.Panel
        Button_Export_Alldata           matlab.ui.control.Button
        Button_Save_database            matlab.ui.control.Button
        Button_Save_XYZJs               matlab.ui.control.Button
        LogListBox                      matlab.ui.control.ListBox
    end


    properties (Access = private)
        data % String en el que guardo los datos que tengo
        gui %
        aux %
        DialogApp % para enviar a otra ventana datos
    end

    methods (Access = private)


        function results = HSV_previo(app)

            % Datos que necesito para calcular el mapa de colores
            % app.data.polos_cartesianas_proyectadas
            % Resultados que genera
            % app.data.polos_cartesianas_proyectadas_HSV_previo
            % app.data.XYZ_RGB_HSV_previo

            results=0;
            proyeccion=app.data.preferences.Projection;
            % Activo el DSC
            [ dipdir, dip ] = f_cart2clar_v02(app.data.polos_cartesianas_proyectadas(:,1) ,app.data.polos_cartesianas_proyectadas(:,2),proyeccion );
            dipdir=dipdir/180*pi; % la salida hay que convertirla a radianes
            dip=dip/180*pi;

            % Calculo el ángulo alpha
            % alpha es el ángulo desde el OX antihorario hasta la proyección del polo
            alfa=zeros(size(dipdir));
            I=find(dipdir>=0 & dipdir<pi/2); alfa(I)=pi/2-dipdir(I)+pi;
            I=find(dipdir>=pi/2 & dipdir <pi);alfa(I)=pi-(dipdir(I)-pi/2);
            I=find(dipdir>=pi & dipdir<3*pi/2);alfa(I)=pi/2-(dipdir(I)-pi);
            I=find(dipdir>=3*pi/2 & dipdir<2*pi);alfa(I)=7*pi/2-dipdir(I);

            % Ahora represento los polos en mi falsilla con colores
            % HSV son los polos con el color HSV
            HSV=zeros(length(alfa),3);
            % Conversión normal de COLTOP
            % HSV(:,1)=(alfa)/(2*pi); % Hue, original
            HSV(:,1)=(alfa-pi/2)/(2*pi); % Hue, lo retraso 90d para que coincida con COLTOP3D
            % regularizo los valores negativos, sumando 1 a los que han
            % ido al cuarto cuadrante y los pongo en positivo
            I=find(HSV(:,1)<0);
            HSV(I,1)=HSV(I,1)+1;
            % Conversión alternativa que repite  el patrón
            %I =find(alfa(:,1)<=pi);
            %HSV(I,1)=alfa(I)/pi;
            %I =find(alfa(:,1)>pi);
            %HSV(I,1)=(alfa(I)-pi)/pi;

            % Calculo la saturación para el dip, que es normal siempre
            HSV(:,2)=dip/(pi/2); % Saturation

            % Pruebo una distribución diferente
            % Los de la primera mitad:
            %I=find(alfa<=pi);
            %HSV(I,1)=(2*alfa(I))/(2*pi); % Hue
            %J=find(alfa>pi);
            %HSV(J,1)=(2*alfa(J)-2*pi)/(2*pi); % Hue
            %HSV(:,2)=dip/(pi/2); % Saturation
            % por si acaso, corrijo estas columnas
            HSV(HSV(:,1)>1,1)=1;
            HSV(HSV(:,2)>1,2)=1;
            HSV(:,3)=1; % Value
            % Calculo los colores según el punto
            rgb = hsv2rgb(HSV)*255;
            % Exportamos los puntos
            XYZ_RGB_HSV=zeros(length(alfa),9);
            XYZ_RGB_HSV(:,1:3)=app.data.P(:,1:3); % las coordenadas
            XYZ_RGB_HSV(:,7:9)=app.data.normales; % las normales
            XYZ_RGB_HSV(:,4:6)=floor(rgb(:,1:3)); % los colores, que los paso a RGB256
            app.data.polos_cartesianas_proyectadas_HSV_previo=hsv2rgb(HSV);
            app.data.XYZ_RGB_HSV_previo=XYZ_RGB_HSV;
        end

        function results = clear_gui(app)
            results=0;
            % Menús
            app.menu_saveddbb.Enable='off';
            % Cuadrante de normales
            app.pushbutton_2setupplanes.Enable='off';
            app.box_nneighbours.Enable='off';
            app.box_tolerancia.Enable='off';
            app.PushTool_Colouring_normals.Enable="off";
            % Cuadrante de plots
            app.pushbutton_plot3dpoints.Enable='off';
            app.pushbutton_plotstereopoles.Enable='off';
            app.pushbutton_plotstereopolesppal.Enable='off';
            app.pushbutton_plotdensitypoles.Enable='off';
            app.pushbutton_plot3dpointsppal.Enable='off';
            app.pushbutton_3statanalysis.Enable='off';
            % Cuadrante de las densidades
            app.BinslevelSpinner.Enable='off';
            app.MinangleEditField.Enable='off';
            app.NmaxDSEditField.Enable='off';
            app.pushbutton_31editppalpoles.Enable='off';
            app.UITable_principalplanes.Enable='off';
            app.UITable_principalplanes.Data=[];
            % Cuadrante asignación de polos
            app.ConeEditField.Enable='off';
            app.Button_assign_ppalpoles.Enable='off';
            % Cuadrante de análisis clúster
            app.UITable_clusters.Enable='off';
            app.UITable_clusters.Data=[];
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.FixnormalvectorCheckBox.Enable='off';
            app.DBSCAN.Enable='off';
            % Panel de guardado
            app.Button_Save_database.Enable='off';
            app.Button_Save_XYZJs.Enable='off';
            app.Button_Export_Alldata.Enable='off';
            % reseteo la información en el log
            
            
            % para mantener el log, S=[a S2]; lo limpio todo
            a = app.LogListBox.Items; % para pillar lo que había en el log
            DateString=char(string(datetime));
            S2 = [DateString, ' - I have deleted all the data from memory. The interface has been reset to its initial state.'];
            S3 = [DateString, ' - How about a nice game of chess?'];
            S=[a S2, S3];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom');
            app.data.log = S;
            % Borro los datos de app.data. Antes intento mantener el
            % pathname, sin es que existe
            if isfield(app.data,'pathname') % exist('pathname', 'var')
                pathname=app.data.pathname;
            end
            app.data=[]; % Borro los datos, incluyendo el pathname
            % Si el pathname existía, lo vuelvo a dejar para que al cargar
            % de nuevo datos sigua en la misma ruta
            if exist('pathname', 'var')
                app.data.pathname=pathname; % dejo el pathname
            end
            % reinicio el startup
            startupFcn(app);
        end

        function results = enable_fase_1_normales(app)
            results=0;
            app.pushbutton_2setupplanes.Enable='on';
            app.box_nneighbours.Enable='on';
            app.box_tolerancia.Enable='on';
            app.CoplanaritytestSwitch.Enable='on';
            app.menu_saveddbb.Enable='on';
            app.Button_Save_database.Enable='on';
            app.pushbutton_plot3dpoints.Enable='on';
            app.PushTool_Colouring_normals.Enable="on";

            % Activo el toolbox de representación normales coloreadas
            % Sólo lo hago si tengo el toolbox de computer vision, porque
            % de lo contrario es un jaleo la visualización
            version=ver;
            toolboxes={version.Name};
            if isscalar(length(find(contains(toolboxes,'Computer Vision Toolbox')==1))) % miro si tengo instalado ese toolbox para representar nubes 3D
                % Además, tiene que ser la versión 6.2
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    app.PushTool_Colouring_normals.Enable="on";
                end
            end
            

        end

        function results = save_database(app)
            results=0;
            tic;
            % preparamos las handles a guardar
            app.gui.FileMenu=app.FileMenu;
            app.gui.CleanMenu=app.ClearMenu;
            app.gui.menu_loadxyz=app.menu_loadxyz;
            app.gui.menu_loadddb=app.menu_loadddb;
            app.gui.menu_saveddbb=app.menu_saveddbb;
            app.gui.ToolsMenu=app.ToolsMenu;
            app.gui.NormalspacingMenu=app.NormalspacingMenu;
            app.gui.PersistenceMenu=app.PersistenceMenu;
            app.gui.HelpMenu=app.HelpMenu;
            app.gui.AboutMenu=app.AboutMenu;
            app.gui.Normals_Panel=app.Normals_Panel;
            app.gui.pushbutton_2setupplanes=app.pushbutton_2setupplanes;
            app.gui.knnEditFieldLabel=app.knnEditFieldLabel;
            app.gui.box_nneighbours=app.box_nneighbours;
            app.gui.toleranceEditFieldLabel=app.toleranceEditFieldLabel;
            app.gui.box_tolerancia=app.box_tolerancia;
            app.gui.CoplanaritytestSwitchLabel=app.CoplanaritytestSwitchLabel;
            app.gui.CoplanaritytestSwitch=app.CoplanaritytestSwitch;
            app.gui.Plots_Panel=app.Displays;
            app.gui.pushbutton_plot3dpoints=app.pushbutton_plot3dpoints;
            app.gui.pushbutton_plotstereopoles=app.pushbutton_plotstereopoles;
            app.gui.pushbutton_plotstereopolesppal=app.pushbutton_plotstereopolesppal;
            app.gui.pushbutton_plotdensitypoles=app.pushbutton_plotdensitypoles;
            app.gui.pushbutton_plot3dpointsppal=app.pushbutton_plot3dpointsppal;
            % app.gui.ConsoleListBoxLabel=app.ConsoleListBoxLabel;
            app.gui.LogListBox=app.LogListBox;
            app.gui.DensityandprincipalpolesextractionPanel=app.STEP2DensityandprincipalpolesextractionPanel;
            app.gui.pushbutton_3statanalysis=app.pushbutton_3statanalysis;
            app.gui.BinslevelSpinnerLabel=app.BinslevelSpinnerLabel;
            app.gui.BinslevelSpinner=app.BinslevelSpinner;
            app.gui.MinangleEditFieldLabel=app.MinangleLabel;
            app.gui.MinangleEditField=app.MinangleEditField;
            app.gui.NmaxDSEditFieldLabel=app.NmaxDSEditFieldLabel;
            app.gui.NmaxDSEditField=app.NmaxDSEditField;
            app.gui.pushbutton_31editppalpoles=app.pushbutton_31editppalpoles;
            app.gui.UITable_principalplanes=app.UITable_principalplanes;
            app.gui.Classifythe3DPCwithtthediscontinuitysetsPanel=app.ClassifypointsassignedtoeachDiscontinuitySetPanel;
            app.gui.ConeEditFieldLabel=app.ConeLabel;
            app.gui.ConeEditField=app.ConeEditField;
            app.gui.Button_assign_ppalpoles=app.Button_assign_ppalpoles;
            app.gui.SavefilesPanel=app.ExportdataPanel;
            app.gui.Button_Save_database=app.Button_Save_database;
            app.gui.Button_Save_XYZJs=app.Button_Save_XYZJs;
            app.gui.Button_Save_results=app.Button_Export_Alldata;
            app.gui.ClusteranalysisPanel=app.ClusteranalysisPanel;
            app.gui.UITable_clusters=app.UITable_clusters;
            app.gui.ksigmasEditFieldLabel=app.ksigmasEditFieldLabel;
            app.gui.ksigmasEditField=app.ksigmasEditField;
            app.gui.FixnormalvectorCheckBox=app.FixnormalvectorCheckBox;
            app.gui.Button_DBSCAN=app.DBSCAN;
            app.gui.DBSCAN_edit=app.DBSCAN_edit;
            app.gui.Clusters_merge=app.Clusters_merge;
            app.gui.Clusters_restore=app.Clusters_restore;
            if isfield(app.data,'pathname') % & app.data.pathname ~= 0
                pathname = app.data.pathname;
                filename = app.data.filename;
                [filename, pathname] = uiputfile('*.mat', 'Save the ddbb in the file:',[pathname,filename,'.mat']);
            else
                [filename, pathname] = uiputfile('*.mat','Save the ddbb in the file:');
            end
            % guardo en las handles el nombre del archivo y el path
            app.data.pathname = pathname;
            app.data.filename = filename;
            datos=app.data;
            interfaz=app.gui;
            save(strcat(pathname,filename),'datos','interfaz');
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            tiempoempleado=floor(toc);
            S2 = [DateString, ' - The state was saved in a dababase: ',[pathname, filename],'.mat. Total time: ', num2str(tiempoempleado),' seconds'];
            % S = strvcat(a,S2)  ;
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        function output_txt = myfunction(~)
            % Display the position of the data cursor
            pos = get(event_obj,'Position');
            x = pos(1);
            y = pos(2);
            [dipdir, dip]=f_cart2clar(x,y);
            output_txt = {['Dip direction: ',num2str(dipdir,3),'[º]'],...
                ['Dip: ',num2str(dip,3),'[º]']};

            % If there is a Z-coordinate in the position, display it as well
            if length(pos) > 2
                output_txt{end+1} = ['Density: ',num2str(pos(3),4)];
            end
        end
        
        function results = clean_after_density(app)
            results=0;
            if isvariable(app.data, 'polos_estereoppalasignados_afterdbscan_limpio')==1
                app.data.polos_estereoppalasignados_afterdbscan_limpio=[];
                app.data.polos_estereoppalasignados_afterdbscan=[];
                app.data.polos_estereoppalasignados=[];
            end
            
        end
        
        function results = normalescalculadas_preparaapp(app)
            results=0; % le doy un valor para que no de aviso
            % Parto de que tengo las normales calculadas, que pueden ser
            % importadas del ply o calculadas con este programa
            % Como se que tengo las normales, habilito el color del punto
            % por normal
            app.PushTool_Colouring_normals.Enable="on";      
            % Se convierte el plano a proyección estereográfica
            [planos_Clar]=prepara_estereo(app.data.normales);
            app.data.planos_Clar=planos_Clar;
            projection=app.data.preferences.Projection;
            % preparo la representación de los planos
            [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(planos_Clar,projection);
            % activamo el análisis estadístico para obtener las familias principales (siguiente paso)
            app.pushbutton_3statanalysis.Enable='on';
            app.BinslevelSpinner.Enable='on';
            app.MinangleEditField.Enable='on';
            app.NmaxDSEditField.Enable='on';
            % activo el botón de representar los polos en stereo
            app.pushbutton_plotstereopoles.Enable='on';
            % como vengo de cargar nuevas normales, voy a dejar los polos
            % principales en 0 y dejo activado el botón de cálculo avanzado
            % de densidad
            app.UITable_principalplanes.Data=[0 0 0 0];
            app.data.polos_pples=[0 0];
            app.data.polos_pples_cart=[0 0];
            app.pushbutton_31editppalpoles.Enable="on";
            % Informo que he calculado los polos
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - The poles of normals have been computed using the ', app.data.preferences.Projection, ' projection.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
            app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas;


        end
        
        
        function results = update_contours(app)
            results=0;
            % Compruebo si ha cambiado la proyección, y en ese
            if isequal(app.data.preferences.Projection,app.data.preferences.Projection_previa)
            	% No tengo que calcular los polos de nuevo.
            else
            	% Ha cambiado la proyección, recalculo los polos
            	[polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.data.preferences.Projection);
            	app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas; % guardo esta variable machacando la anterior
            	app.data.preferences.Projection_previa=app.data.preferences.Projection;
            	% Informo que he recalculado los polos
            	a = app.LogListBox.Items;
            	DateString=char(string(datetime));
            	S2 = [DateString, ' - The poles of the normal vectors have been recomputed using the ', app.data.preferences.Projection, ' projection.'];
            	S=[a S2];
            	app.LogListBox.Items=S;
            	% fijo la vista del listbox en la última entrada
            	scroll(app.LogListBox, 'bottom')
            	app.data.log = S;

                % sectorización de la malla para el kde
                nsec=2^app.BinslevelSpinner.Value;
                angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
                maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
                % guardo los datos con los que calculamos
                app.data.nsec=nsec;
                app.data.angulovpples=angulovpples;
                % preparamos la función de densidad con el kde
                [~, ~,density, X, Y]=f_densidad_y_polospples_v00(polos_cartesianas_proyectadas,nsec, angulovpples, maxpples,app.data.preferences.Normalisedcontour,app.data.preferences.Projection);
                app.data.X=X;
                app.data.Y=Y;
                app.data.density=density;

            	% Tomo los polos principales de la tabla y calculo su densidad
            	polos_pples=app.UITable_principalplanes.Data; % Están en notación de Clar
            	[ xp,yp ] = f_clar2cart( polos_pples(:,1), polos_pples(:,2), app.data.preferences.Projection );
            	densidad_estimada = griddata(X,Y,density,xp,yp,app.data.interpolacionpicos);
            	polos_pples(:,3)=densidad_estimada; % Actualizo la densidad
            	app.data.polos_pples=polos_pples;
                polos_pples_cart=[xp yp densidad_estimada];
            	app.data.polos_pples_cart=polos_pples_cart;

                % Informo que he recalculado los polos
            	a = app.LogListBox.Items;
            	DateString=char(string(datetime));
            	S2 = [DateString, ' - I have recomputed the contours using the ', app.data.preferences.Projection, ' projection. The principal poles were mantained, and their Z values have been interpolated.'];
            	S=[a S2];
            	app.LogListBox.Items=S;
            	% fijo la vista del listbox en la última entrada
            	scroll(app.LogListBox, 'bottom')
            	app.data.log = S;
            end
            
        end
        
        function results = polos_pples_calculados_preparaapp(app)
            results=0;
            % set visible the uipanel for assignment poles to the points
            app.Button_assign_ppalpoles.Enable='on';
            app.ConeEditField.Enable='on';
            % activate the principal poles editor
            app.pushbutton_31editppalpoles.Enable='on';
            % make visible the plot principal poles button
            app.pushbutton_plotdensitypoles.Enable='on';

            % apago el resto de los botones, por si he vuelto atrás
            app.pushbutton_plotstereopolesppal.Enable='off';
            app.pushbutton_plot3dpointsppal.Enable='off';
            % Ahora el paso del dbscan, sólo activo tras el cono
            app.DBSCAN.Enable='off';
            app.UITable_clusters.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.FixnormalvectorCheckBox.Enable='off';
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            % los botones de exportación y guardado
            app.Button_Export_Alldata.Enable='off';
            app.Button_Save_XYZJs.Enable='off';
        end
    end
    
    methods (Access = public)
        
        function results = updatefunctionclusters(app,datos)
            % fijo el resulato para no tener el aviso
            results=0;
            % me acaban de pasar los resultados de la edición de clusters
            % en datos, tengo que actualizar la UI y guardarlo todo
            % Primero, hago visible la UI principal
            app.DiscontinuitySetExtractor4UIFigure.Visible="on";
            
            % Guardo el grupo de datos en data
            app.data.puntos_familia_cluster=datos.puntos_familia_cluster;
            app.data.familia_cluster_plano=datos.familia_cluster_plano;
            app.data.puntos_familia_cluster_fullclusters=datos.puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano_fullclusters=datos.familia_cluster_plano_fullclusters;
            app.data.polos_estereoppalasignados_afterdbscan=datos.polos_estereoppalasignados_afterdbscan;
            % app.data=datos;

            % guardo la salida en la tabla de la gui
            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;
            % calculo el número de puntos
            [npuntos,~]=size(app.data.puntos_familia_cluster);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Cluster edited manually. Number of points: ',num2str(npuntos)];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end
        
        function results = updatefunction_editppalpoles(app,datos)
            results=0;
            % Esta función recibe el resultado de editar los polos
            % principales de otra ventana, en la estructura datos
            
            % Lo primero es activar de nuevo la ventana principal
            app.DiscontinuitySetExtractor4UIFigure.Visible="on";
            
            % Le paso los datos de la edición de los polos principales
            app.data.polos_pples=datos.polos_pples;
            app.data.angulos=datos.angulos;
            app.data.preferences.Projection=datos.proyeccion;
            app.data.preferences.Normalisedcontour=datos.normaliza;
            % introducimos los planos principales en la tabla
            app.UITable_principalplanes.Enable='on';
            app.UITable_principalplanes.Data=app.data.polos_pples;

            % convertimos la notación de Clar a las coordenadas de los polos
            % principales
            dirbuz=app.data.polos_pples(:,1);
            buz=app.data.polos_pples(:,2);
            app.data.polos_pples_cart=zeros(size(app.data.polos_pples));
            [ x,y ] = f_clar2cart( dirbuz,buz,app.data.preferences.Projection );
            app.data.polos_pples_cart(:,1)=x;
            app.data.polos_pples_cart(:,2)=y;
            % guardo la densidad en los nuevos planos pples
            % con esto puedo ver la densidad en 3D
            app.data.polos_pples_cart(:,3)=app.data.polos_pples(:,3);
                       
            % preparo el resto de la GUI, para continuar adelante
            polos_pples_calculados_preparaapp(app)
                        
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Manual edition of the principal poles' ];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
            
        end
        
        function results = updatefunction_coloured_normals(app,datos)
            % Esto es lo que se ejecuta cuando se cierra la ventana de
            % optimización de normales y vuelvo a la gui principal
            
            % Primero, hago visible la UI principal
            app.DiscontinuitySetExtractor4UIFigure.Visible="on";

            % acabo de calcular los colores de las normales
            % Guardo los resultados de la optimización
            % en la app secundaria es app.data.resultado_optimizacion_vectores
            app.data.angulos=datos.angulos;

            results=0; % fijo el resulato para no tener el aviso
            
        end
        
        
        function results = updatefunction_preferences(app,datos)
            % Esto es lo que se ejecuta cuando se cierra la ventana y vuelvo a la gui principal
            % Primero, hago visible la UI principal
            app.DiscontinuitySetExtractor4UIFigure.Visible="on";
            % He cambiado las preferencias, las guardo
            app.data.preferences=datos.preferences;
            results=0; % fijo el resulato para no tener el aviso
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Aquí meto el código que se ejecuta cuando se han creado todos
            % los componentes de la GUI
            % Creo la escala de colores
            app.data.base_colores=[
                0	90	1	0	0
                120	90	0	0	1
                240	90	0	1	0
                0	0	1	1	1
                60	90	0	1	0
                180	90	1	0	0
                300	90	0	0	1
                30	45	1	0.5	0
                90	45	0	1	1
                150	45	1	0	1
                210	45	1	1	0
                270	45	0	0.5	1
                330	45	0.5	0	1
                ];
            app.data.k_colores=1.5; % potencia de la distancia
            app.data.q_colores=5; % número de vecinos para calcular color

            % Inicio la variable ángulos y la dejo a cero
            app.data.angulos=[0 0 0];

            % Inicio las preferencias
            app.data.preferences.Projection='Equal-angle';
            app.data.preferences.Projection_previa='Equal-angle';
            app.data.preferences.Normalisedcontour=1;
            % Estoy en pruebas, vamos a ver cómo sacar los picos
            % "nearest", "linear", "natural" y "cubic"
            app.data.interpolacionpicos='linear';
            try
                % mensaje = {'Pendientes:', ...
                %     '- Poner en scale los índices de familias, sólo los enteros'};
                % % Mostrar el mensaje en un cuadro de diálogo
                % msgbox(mensaje, 'Recordatorios', 'help');
            catch ME
                disp(['Error displaying message box: ' ME.message]);
            end


        end

        % Menu selected function: menu_loadxyz
        function menu_load3DPCSelected(app, event)
            % Compruebo si estoy ya trabajando en alguna carpeta
            if isfield(app.data,'pathname')
                % Tengo una carpeta de trabajo, el menú abre ahí
                [filename, pathname] = uigetfile({'*.txt; *.ply','3DPC Files (*.txt, *.ply)'},'Select the 3D point cloud file. Tip: .txt files load XYZ, .ply can also load normals.',app.data.pathname);
            else
                % Es la primera vez que abro el programa, irá a carpeta por
                % defecto
                [filename, pathname] = uigetfile({'*.txt; *.ply','3DPC Files (*.txt, *.ply)'},'Select the 3D point cloud file. Tip: .txt files load XYZ, .ply can also load normals.');
            end
            
            if filename~=0
                app.data.pathname = pathname;
                [~,nombrearchivo,extension] = fileparts(filename);
                app.data.filename = nombrearchivo;
                app.data.extension = extension;
                % Si es un txt cargo sólo las coordenadas, si es un ply
                % miro si tiene las normales y las cargo directamente.
                switch extension
                    case '.txt'
                        P=load(strcat(pathname, filename));
                        % Por si acaso, guardo las columnas de la 4 al final
                        [~,ncolumnas]=size(P);
                        if ncolumnas >=4
                            app.data.P_extradata=P(:,ncolumnas:4);
                        end
                        % limpio y me quedo sólo con las coordenadas, las tres primeras
                        % columnas
                        P=P(:,1:3);
                        % Elimino los puntos que no estén duplicados
                        [P,~,~] = unique(P, 'rows', 'first'); % He machacado P con los valores únicos. I y J los dejo por si más adelante quiero dejar campos extra en P.
                        app.data.P = P;
                        app.data.Poriginal=P; % guardo el original por si se machaca P
                        % Aunque haya cargado un .txt, puedo tener el Computer Vision Toolbox. En ese caso, guardo la nube para poder reprsentarla después.
                        version=ver;
                        toolboxes={version.Name};
                        % uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
                        if isscalar(find(contains(toolboxes,'Computer Vision Toolbox')==1))
                                app.data.ptCloud = pointCloud(P);
                                app.data.ptCloudoriginal=app.data.ptCloud; % Guardo el ptcloud original, por si acaso
                        end 
                        % activo la preparación de planos
                        enable_fase_1_normales(app);
                        % guardo la información en el log
                        a = app.LogListBox.Items;
                        DateString=char(string(datetime));
                        S2 = [DateString, ' - Point cloud loaded. File: ',filename,' number of points: ',num2str(length(P))];
                        % S = strvcat(a,S2)  ;
                        S=[a S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    case '.ply'
                        % Para cargar el .ply necesito el Computer Vision
                        % Toolbox, primero veo si lo tiene o no
                        version=ver;
                        toolboxes={version.Name};
                        % uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
                        if isscalar(find(contains(toolboxes,'Computer Vision Toolbox')==1))
                            % usamos el toolbox para calcular las normales
                            ptCloud=pcread(strcat(pathname, filename));
                            P=ptCloud.Location; % Cargo las coordenadas
                            % Elimino los puntos que no estén duplicados
                            [P,I,~] = unique(P, 'rows', 'first'); % He machacado P con los valores únicos
                            % Del ptCloud debo de guardar Location, Color,
                            % Normal e Intensity
                            app.data.ptCloud = pointCloud(P);
                            app.data.P=P; % Guardo las coordenadas de los puntos
                            app.data.Poriginal=P; % guardo el original por si se machaca P
                            if ~isempty(ptCloud.Normal)
                                app.data.ptCloud.Normal=ptCloud.Normal(I,:);
                            end
                            if ~isempty(ptCloud.Color)
                                app.data.ptCloud.Color=ptCloud.Color(I,:);
                            end
                            if ~isempty(ptCloud.Intensity)
                                app.data.ptCloud.Intensity=ptCloud.Intensity(I,:);
                            end
                            app.data.ptCloudoriginal=ptCloud; % Guardo el ptcloud original, por si acaso

                            % Independientemente de si ahora después puedo
                            % cargar normales o no, las dejo activadas;
                            enable_fase_1_normales(app);

                            [npuntos,~]=size(P);
                            % compruebo si el .ply tiene las normales
                            [n,~]=size(ptCloud.Normal);
                            switch n
                                case 0
                                    % No hay normales, no lo cargo

                                    % guardo la información en el log
                                    a = app.LogListBox.Items;
                                    DateString=char(string(datetime));
                                    S1 = [DateString, ' - Point cloud loaded. File: ',filename,' number of points: ',num2str(npuntos)];
                                    S2 = [DateString, ' - The .ply file does not contain normal vectors, so you will need to compute them.'];
                                    S=[a S1 S2];
                                    app.LogListBox.Items=S;
                                    % fijo la vista del listbox en la última entrada
                                    scroll(app.LogListBox, 'bottom')
                                    app.data.log = S;
                                otherwise
                                    % Tengo normales, vamos allá
                                    % Primero guardo las variables
                                    app.data.normales=app.data.ptCloud.Normal; %cargo las normales
                                    % Llamo a la función que se ejecuta
                                    % tras el cálculo de normales
                                    normalescalculadas_preparaapp(app);

                                    % guardo la información en el log
                                    a = app.LogListBox.Items;
                                    DateString=char(string(datetime));
                                    S1 = [DateString, ' - The file contains the normals, so you can now calculate the pole density.'];
                                    S2 = [DateString, ' - You can also colour the point cloud using the HSV colour map according to its normals.'];
                                    S3 = [DateString, ' - Point cloud loaded successfully. File: ',filename,' number of points: ',num2str(npuntos)];
                                    S=[a S1 S2 S3];
                                    app.LogListBox.Items=S;
                                    % fijo la vista del listbox en la última entrada
                                    scroll(app.LogListBox, 'bottom')
                                    app.data.log = S;
                            end

                        else
                            % No tengo el Computer Vision Toolbox, se lo
                            % digo al usuario
                            warndlg('Loading .ply files needs Computar Vision Toolbox.','Warning');
                            % guardo la información en el log
                            a = app.LogListBox.Items;
                            DateString=char(string(datetime));
                            S2 = [DateString, 'I could not load the .ply file because Computer Vision Toolbox is not available.'];
                            S3 = [DateString, 'Please load an ASCII .txt file.'];
                            S=[a S2 S3];
                            app.LogListBox.Items=S;
                            % fijo la vista del listbox en la última entrada
                            scroll(app.LogListBox, 'bottom')
                            app.data.log = S;
                        end                    
                end
            end
            figure(app.DiscontinuitySetExtractor4UIFigure); % Traer la ventana de la aplicación al frente
        end

        % Button pushed function: pushbutton_2setupplanes
        function pushbutton_2setupplanesButtonPushed(app, event)
            % Inputs de la función:
            % - P, las coordenadas de los puntos
            % - npb: tb knn, que es el número de vecinos paraa normales
            % - tolerancia: el valor de eta, si lo necesita
            % Outputs:
            % - planos
            % - planos_Clar
            % - polos_estereo_cartesianas: las xy en el stereonet
            % Preparo una barra de progreso indeterminada
            proyeccion=app.data.preferences.Projection;
            fig=uifigure;
            uiprogressdlg(fig,'Title','Computing normals','Indeterminate','on','Cancelable','on');
            % d = uiprogressdlg(fig,'Title','Computing normals','Indeterminate','on','Cancelable','on');
            drawnow;
            value = app.CoplanaritytestSwitch.Value;
            switch value
                case 'On'
                    % Cálculo con el test de coplanaridad
                    tic;
                    P=app.data.P;
                    npb=str2double(app.box_nneighbours.Value); app.data.npb=npb;
                    tolerancia=str2double(app.box_tolerancia.Value); app.data.tolerancia=tolerancia;
                    % preparamos los planos
                    % d = uiprogressdlg(fig,'Title','Computing normals','Indeterminate','on','Cancelable','on');
                    uiprogressdlg(fig,'Title','Computing normals','Indeterminate','on','Cancelable','on');
                    [P, calidad_tin, normales]=f_setup_planes_v07(P, npb, tolerancia); % el número de puntos se habrá reducido por el análisis
                    % establecemos el número de puntos en la pantalla
                    [npuntos,~]=size(P);
                    % Actualizo los puntos
                    app.data.P=P;
                    app.data.calidad_tin=calidad_tin;
                    app.data.normales=normales;
                    % convertimos los planos a la proyección estereográfica
                    [planos_Clar]=prepara_estereo(normales);
                    app.data.planos_Clar=planos_Clar;
                    % preparamos la representación de los planos
                    uiprogressdlg(fig,'Title','Computing normals without Toolbox','Indeterminate','on','Cancelable','on');
                    [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(planos_Clar,proyeccion);
                    app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas;
                    
                    % activamos el cálculo de planos (siguiente paso)
                    app.pushbutton_3statanalysis.Enable='on';
                    app.BinslevelSpinner.Enable='on';
                    app.MinangleEditField.Enable='on';
                    app.NmaxDSEditField.Enable='on';
                    
                    % activamos el botón de representar los polos en stereo
                    app.pushbutton_plotstereopoles.Enable='on';
                    
                    % registro el tiempo que hemos empleado
                    tiempoempleado=floor(toc);
                    
                    % guardo la información en el log
                    a = app.LogListBox.Items;
                    DateString=char(string(datetime));
                    S2 = [DateString, ' - Normals were calculated,', ' num of neighbours: ',num2str(npb),', tolerance = ',num2str(tolerancia),', n of points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' seconds'];
                    S=[a S2];
                    app.LogListBox.Items=S;
                    % fijo la vista del listbox en la última entrada
                    scroll(app.LogListBox, 'bottom')
                    app.data.log = S;
                case 'Off'
                    % Cálculo sin el test de coplanaridad
                    tic;
                    P=app.data.P;
                    [npuntos,~]=size(P);
                    npb=str2double(app.box_nneighbours.Value);
                    app.data.npb=npb;
                    % preparamos los planos
                    
                    % Uso o no del Computer Vision Toolbox de MATLAB
                    version=ver;
                    toolboxes={version.Name};
                    uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
                    % En realidad, pccnormals está siempre por lo que se puede ejecutar independientemente de su versión
                    if uso==1
                        % usamos el toolbox para calcular las normales
                        ptCloud=pointCloud(P);
                        uiprogressdlg(fig,'Title','Computing normals via Toolbox','Indeterminate','on','Cancelable','on');
                        normales=pcnormals(ptCloud,npb);
                    else
                        % usamos la función nuestra
                        uiprogressdlg(fig,'Title','Computing normals without Toolbox','Indeterminate','on','Cancelable','on');
                        [idx, dist, ~, calidad_tin, normales]=f_preparaTIN_v03_nocoplanartest(P, npb);
                        app.data.idx = idx; % En realidad, esto no se usa después
                        app.data.dist = dist; % Tampoco se usa después
                        app.data.calidad_tin=calidad_tin; % No se usa
                    end
                    
                    % Ahora guardo las variables
                    app.data.normales=normales; % Guardo los planos en las app

                    % Tengo los planos calculados. Llamo a la función que
                    % prepara la GUI para el siguiente paso
                    normalescalculadas_preparaapp(app);

                    % Ya terminado todo, guardamos el log
                    if uso==1
                        % hemos usado el toolbox para calcular las normales
                        tiempoempleado=floor(toc);
                        % guardo la información en el log
                        a = app.LogListBox.Items;
                        DateString=char(string(datetime));
                        S2 = [DateString, ' - Normals calculated with Computer Vision Toolbox, num of neighbours: ',num2str(npb),', n points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' s.'];
                        S=[a S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    else
                        % No hemos usado el toolbox sino que hemos
                        % calculado con nuestra función de normales
                        % usamos la función nuestra
                        tiempoempleado=floor(toc);
                        % guardo la información en el log
                        a = app.LogListBox.Items;
                        DateString=char(string(datetime));
                        S2 = [DateString, ' - Normals calculated without compalarity test, num of neighbours: ',num2str(npb),', n points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' s.'];
                        S=[a S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    end
            end %Fin del switch
            % En cualquiera de los casos, ya tengo las normales y habilito
            % la representación de puntos por colores de normales
            app.PushTool_Colouring_normals.Enable="on";
            % Fin del cálculo de las normales
            close(fig);
        end

        % Button pushed function: pushbutton_plot3dpoints
        function pushbutton_plot3dpointsButtonPushed(app, event)
            P=app.data.P;
            version=ver;
            toolboxes={version.Name};
            % uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
            if isscalar(length(find(contains(toolboxes,'Computer Vision Toolbox')==1))) % miro si tengo instalado ese toolbox para representar nubes 3D
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    % Tengo el Toolbox para nubes de puntos y puedo usarlo
                    % Compruebo si tengo la nube del ply
                    if isfield(app.data,'ptCloud')
                        h=figure;
                        pcshow(app.data.ptCloud);
                        h.Name='3D Point Cloud from .ply';
                    else
                        ptCloud=pointCloud(P(:,1:3));
                        h=figure;
                        pcshow(ptCloud);
                        h.Name='3D Point Cloud from .txt';
                    end
                    % Preparo la representación con fondo blanco
                    h.Color=[ 1 1 1]; % pongo el fondo blanco
                    h.CurrentAxes.Color=[1 1 1]; % el fondo de los axes tb en blanco
                    % Pongo los ejes en negro
                    h.CurrentAxes.GridColor=[0 0 0]; % los ejes en negro
                    h.CurrentAxes.XColor=[0 0 0];
                    h.CurrentAxes.YColor=[0 0 0];
                    h.CurrentAxes.ZColor=[0 0 0];
                    h.CurrentAxes.Projection='orthographic';
                    h.Name='3D point cloud, original';
                    % si he calculado los vectores normales represento el
                    % HSV
                    switch app.pushbutton_3statanalysis.Enable
                        case 'on'
                            % Represento la nube en el CCM
                            if isfield(app.data,'XYZ_RGB_CCM_previo')==1
                                ptCloud=pointCloud(app.data.XYZ_RGB_CCM_previo(:,1:3));
                                ptCloud.Color=uint8(app.data.XYZ_RGB_CCM_previo(:,4:6));
                                ptCloud.Normal=app.data.XYZ_RGB_CCM_previo(:,7:9);
                                h=figure;
                                pcshow(ptCloud);
                                pcwrite(ptCloud,[app.data.pathname, app.data.filename, ' XYZ-CCM-normals.ply'],'PLYFormat','binary');
                                h.Name='3D Point Cloud coloured using a custom colourmap';
                                % Ahora lo pongo en color blanco
                                h.Color=[ 1 1 1]; % pongo el fondo blanco
                                h.CurrentAxes.Color=[1 1 1]; % el fondo de los axes tb en blanco
                                % Pongo los ejes en negro
                                h.CurrentAxes.GridColor=[0 0 0]; % los ejes en negro
                                h.CurrentAxes.XColor=[0 0 0];
                                h.CurrentAxes.YColor=[0 0 0];
                                h.CurrentAxes.ZColor=[0 0 0];
                                h.CurrentAxes.Projection='orthographic';

                                % Ahora dibujo el estereograma con colores
                                % Dibujo la falsilla para comparar
                                h=figure;
                                switch app.data.preferences.Projection
                                    case 'Equal-angle'
                                        falsilla_riquelme_Equal-angle_v02;
                                    case 'Equal-area'
                                        falsilla_riquelme_Equalarea_v02
                                    otherwise
                                        % algo ha pasado
                                end
                                h.Color=[1 1 1];
                                axis('square','equal');
                                polos_x=app.data.polos_cartesianas_proyectadas(:,1);
                                polos_y=app.data.polos_cartesianas_proyectadas(:,2);
                                polos_z=zeros(size(polos_x));
                                polos_z(:)=-0.1;
                                polos_colores=app.data.polos_cartesianas_proyectadas_CCM_previo;
                                scatter3(polos_x,polos_y,polos_z,10,polos_colores,'filled');
                                % preparo el puntero
                                dcm = datacursormode(gcf);
                                datacursormode on;
                                set(dcm,'updatefcn',@f_stereopickcolour_v00)
                                h.Name='Custom Colourmap scheme (dip direction - dip) of the normal vector poles';
                            end
                            % Represento la nube en el HSV
                            if isfield(app.data,'XYZ_RGB_HSV_previo')==1
                                ptCloud=pointCloud(app.data.XYZ_RGB_HSV_previo(:,1:3));
                                ptCloud.Color=uint8(app.data.XYZ_RGB_HSV_previo(:,4:6));
                                ptCloud.Normal=app.data.XYZ_RGB_HSV_previo(:,7:9);
                                h=figure;
                                pcshow(ptCloud);
                                pcwrite(ptCloud,[app.data.pathname, app.data.filename, ' XYZ-HSV-normals.ply'],'PLYFormat','binary');
                                h.Name='3D Point Cloud coloured using the HSV colourmap';
                                % Ahora lo pongo en color blanco
                                h.Color=[ 1 1 1]; % pongo el fondo blanco
                                h.CurrentAxes.Color=[1 1 1]; % el fondo de los axes tb en blanco
                                % Pongo los ejes en negro
                                h.CurrentAxes.GridColor=[0 0 0]; % los ejes en negro
                                h.CurrentAxes.XColor=[0 0 0];
                                h.CurrentAxes.YColor=[0 0 0];
                                h.CurrentAxes.ZColor=[0 0 0];
                                h.CurrentAxes.Projection='orthographic';
                                % Ahora dibujo el estereograma con colores
                                % Dibujo la falsilla para comparar
                                h=figure;
                                switch app.data.preferences.Projection
                                    case 'Equal-angle'
                                        falsilla_riquelme_Equalangle_v02;
                                    case 'Equal-area'
                                        falsilla_riquelme_Equalarea_v02
                                    otherwise
                                        % algo ha pasado
                                end
                                h.Color=[1 1 1];
                                axis('square','equal');
                                polos_x=app.data.polos_cartesianas_proyectadas(:,1);
                                polos_y=app.data.polos_cartesianas_proyectadas(:,2);
                                polos_z=zeros(size(polos_x));
                                polos_z(:)=-0.1;
                                polos_colores=app.data.polos_cartesianas_proyectadas_HSV_previo;
                                scatter3(polos_x,polos_y,polos_z,10,polos_colores,'filled');
                                % preparo el puntero
                                dcm = datacursormode(gcf);
                                datacursormode on;
                                set(dcm,'updatefcn',@myupdatefcn)
                                h.Name='HSV scheme (dip direction - dip) of the normal vector poles';
                            end
                    end
                else
                    % No tengo ese toolbox y empleo la programación antigua
                    h=figure;
                    h.Color=[1 1 1];
                    scatter3(P(:,1),P(:,2),P(:,3),10,P(:,3),'filled');
                    title(['3D Points: ',num2str(length(P)),' points']);
                    axis('equal') ,xlabel('axis X'),ylabel('axis Y'),zlabel('axis Z');
                end
            end
            [npuntos,~]=size(P);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - 3D point cloud plotted, number of points: ',num2str(npuntos)];
            % S = strvcat(a,S2)  ;
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Value changed function: BinslevelSpinner
        function BinslevelSpinnerValueChanged(app, event)
            value = app.BinslevelSpinner.Value;
            if value<=0
                app.BinslevelSpinner.Value=6;
            end
        end

        % Button pushed function: pushbutton_plotstereopoles
        function pushbutton_plotstereopolesButtonPushed(app, event)
           % Tomo los polos, pero verifico en qué proyección están 
            if isequal(app.data.preferences.Projection,app.data.preferences.Projection_previa)
                % No tengo que calcular los polos de nuevo. Carlos los
                % polos
                polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas;
            else
                % Ha cambiado la proyección, recalculo los polos
                [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.data.preferences.Projection);
                app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas; % guardo esta variable machacando la anterior
                app.data.preferences.Projection_previa=app.data.preferences.Projection;
                % Informo que he recalculado los polos
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                S2 = [DateString, ' - The poles of the normal vectors have been recomputed using the ', app.data.preferences.Projection, ' projection.'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
            % [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.data.preferences.Projection);
            % dibujamos la falsilla de Riquelme :D
            h=figure;
            switch app.data.preferences.Projection
                case 'Equal-angle'
                    falsilla_riquelme_Equalangle_v02;
                case 'Equal-area'
                    falsilla_riquelme_Equalarea_v02;
                otherwise
                    % algo ha pasado
            end
            h.Color=[1 1 1];
            axis('square','equal');
            % scatter(polos_estereo_cartesianas(:,1),polos_estereo_cartesianas(:,2),calidad_tin(:,1));
            z=zeros(size(polos_cartesianas_proyectadas(:,1)));
            z(:,1)=-0.1;
            scatter3(polos_cartesianas_proyectadas(:,1),polos_cartesianas_proyectadas(:,2),z,1);
            % h.Name=['Stereographic Projection, Normal Vector Poles: ',num2str(length(polos_estereo_cartesianas)),' points'];
            h.Name=[app.data.preferences.Projection,' Projection, Normal Vector Poles: ',num2str(length(polos_cartesianas_proyectadas)),' points'];
            g=gca;
            g.Title.String={[app.data.preferences.Projection, ' plot of ', num2str(length(polos_cartesianas_proyectadas)),' normal poles'],''}; % Le añado dos líneas
             % Guardo la imagen en un fichero
            % Primero verifico si existe el pathname
            if and(isfield(app.data,'pathname'),app.data.pathname ~= 0)
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if and(isfield(app.data,'filename'),app.data.filename ~= 0)
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end
            % Ya puedo guardar la imagen
            name_png = [pathname,filename, app.data.preferences.Projection, ' - Poles of normals - ', '.png']; saveas(gcf, name_png);
            name_pdf = [pathname, filename, app.data.preferences.Projection, ' - Poles - of normals', '.pdf']; saveas(gcf, name_pdf);

            % preparo el puntero
            dcm = datacursormode(gcf);
            datacursormode on;
            proyeccion=app.data.preferences.Projection;
            % angulos=app.data.angulos;
            angulos=[0 0 0]; % No roto los ángulos, eso lo hace la otra gui
            set(dcm,'updatefcn',{@myupdatefcn_rotate,angulos,proyeccion} )

            % Informo que he dibujado los polos
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - I have plotted the poles of the normal vectors in an ', app.data.preferences.Projection, ' projection.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: pushbutton_3statanalysis
        function pushbutton_3statanalysisButtonPushed(app, event)
            tic;
            if isequal(app.data.preferences.Projection,app.data.preferences.Projection_previa)
                % No tengo que calcular los polos de nuevo. Cargo los polos
                polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas;
            else
                % Ha cambiado la proyección, recalculo los polos
                [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.data.preferences.Projection);
                app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas; % guardo esta variable machacando la anterior
                app.data.preferences.Projection_previa=app.data.preferences.Projection;
                % Informo que he recalculado los polos
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                S2 = [DateString, ' - The poles of the normal vectors have been recomputed using the ', app.data.preferences.Projection, ' projection.'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
            % sectorización de la malla para el kde
            nsec=2^app.BinslevelSpinner.Value;
            angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
            maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
            % guardo los datos con los que calculamos
            app.data.nsec=nsec;
            app.data.angulovpples=angulovpples;
            % preparamos la función de densidad con el kde
            % [polos_pples, polos_pples_cart,density, X, Y]=f_densidad_y_polospples_v00(polos_cartesianas_proyectadas,nsec, angulovpples, maxpples,app.data.preferences.Normalisedcontour,app.data.preferences.Projection);
            bandwitdth=0; % Utilizo el kde2d con el óptimo
            mindensity=10^-3;
            [polos_pples, polos_pples_cart, density, X, Y]=f_densidad_y_polospples_v01(polos_cartesianas_proyectadas,nsec, angulovpples, maxpples,app.data.preferences.Normalisedcontour,app.data.preferences.Projection, bandwitdth,mindensity);
            app.data.polos_pples=polos_pples;
            app.data.polos_pples_cart=polos_pples_cart;
            app.data.X=X;
            app.data.Y=Y;
            app.data.density=density;
            
            % buscamos los planos principales
            % introducimos los planos principales en la tabla
            app.UITable_principalplanes.Enable='on';
            app.UITable_principalplanes.Data=polos_pples;
            % Preparo la GUI
            polos_pples_calculados_preparaapp(app);
            
            % Guardo la info en el cuadro de texto
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Statistic calculation, Nº of bins: ',num2str(nsec),', min angle btwn ppal poles: ',num2str(angulovpples),', max nº of ppal poles: ',num2str(maxpples),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Value changing function: BinslevelSpinner
        function BinslevelSpinnerValueChanging(app, event)
            % changingValue = event.Value;
            if app.BinslevelSpinner.Value<=0
                app.BinslevelSpinner.Value=6;
            end
        end

        % Button pushed function: pushbutton_plotdensitypoles
        function pushbutton_plotdensitypolesButtonPushed(app, event)
            tic;
            % Verifico si ha cambiado la proyección para el contour plot
            if isequal(app.data.preferences.Projection,app.data.preferences.Projection_previa)
                % No tengo que calcular los polos de nuevo. 
            else
                % Ha cambiado la proyección, recalculo polos y densidad
                update_contours(app);
            end

            % Comprueba si existen las variables necesarias en app.data
            if isfield(app.data, 'X') && isfield(app.data, 'Y') && isfield(app.data, 'density')
                % Si existen, toma los datos directamente
                polos_pples_cart = app.data.polos_pples_cart;    % Polos principales en coordenadas cartesianas
                X = app.data.X;                                 % Coordenada X para malla de densidad
                Y = app.data.Y;                                 % Coordenada Y para malla de densidad
                density = app.data.density;                     % Valores de densidad
            else
                % las variables no existen, las calculo, pero los polos
                % principales sí que deben de existir
                polos_pples_cart = app.data.polos_pples_cart;    % Polos principales en coordenadas cartesianas
                % Si no existen, calcula las variables
                if isequal(app.data.preferences.Projection, app.data.preferences.Projection_previa)
                    % No tengo que calcular los polos de nuevo. Cargo los polos
                    polos_cartesianas_proyectadas = app.data.polos_cartesianas_proyectadas;    % Usa proyección existente
                else
                    % Ha cambiado la proyección, recalculo los polos
                    [polos_cartesianas_proyectadas] = f_calcula_coordenadas_polosvn(app.data.planos_Clar, app.data.preferences.Projection);    % Recalcula proyecciones
                    app.data.polos_cartesianas_proyectadas = polos_cartesianas_proyectadas;    % Guarda variable actualizando la anterior
                    app.data.preferences.Projection_previa = app.data.preferences.Projection;   % Actualiza la proyección previa

                    % Informo que he recalculado los polos
                    a = app.LogListBox.Items;                                                  % Obtiene items actuales del log
                    DateString = char(string(datetime));                                       % Obtiene fecha y hora actual
                    S2 = [DateString, ' - The poles of the normal vectors have been recomputed using the ', app.data.preferences.Projection, ' projection.'];    % Mensaje informativo
                    S = [a S2];                                                                % Añade mensaje al log
                    app.LogListBox.Items = S;                                                  % Actualiza listbox
                    % fijo la vista del listbox en la última entrada
                    scroll(app.LogListBox, 'bottom')                                           % Desplaza a la última línea
                    app.data.log = S;                                                          % Guarda log en los datos
                end
                % Establezco unas variables necesarias, sólo para la
                % densidad
                nsec = 2^app.BinslevelSpinner.Value;                                          % Número de sectores para el análisis
                angulovpples = app.MinangleEditField.Value;                                    % Mínimo ángulo entre polos principales
                maxpples = app.NmaxDSEditField.Value;                                          % Máximo número de polos principales
                bandwitdth = 0;                                                                % Utiliza el kde2d con el óptimo
                mindensity = 10^-3;                                                            % Densidad mínima a considerar
                % Calcula la densidad y los polos principales
                [~, ~, density, X, Y] = f_densidad_y_polospples_v01(polos_cartesianas_proyectadas, nsec, angulovpples, maxpples, app.data.preferences.Normalisedcontour, app.data.preferences.Projection,bandwitdth, mindensity);  % Calcula densidad y las coordenadas del grid
                % Guarda los resultados en app.data
                app.data.X = X;                                                                % Guarda matriz X
                app.data.Y = Y;                                                                % Guarda matriz Y
                app.data.density = density;                                                    % Guarda matriz de densidad
            end


            % representamos la salida
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            densidad_estimada = griddata(X,Y,density,xa,xb);
            if nc==2
                xc=0.1;
            else
                % xc=polos_pples_cart(1:kk,3)'+0.1;
                xc=densidad_estimada(1:kk)'+0.1;
            end
            % maxd=max(max(density)); mind=min(min(density));
            numberisolines=80; % número de isoloníneas
            % isolines=(maxd-mind)/numberisolines;

            filename = app.data.filename;
            h=figure('name',[filename, ' density function']);
            switch app.data.preferences.Projection
                case 'Equal-angle'
                    falsilla_riquelme_Equalangle_v02;
                case 'Equal-area'
                    falsilla_riquelme_Equalarea_v02
                otherwise
                    % algo ha pasado
            end
            h.Color=[1 1 1]; % Paso a fondo en blanco
            hold on; contour3(X,Y,density,numberisolines); % dibuja con contornos
            axis ([-1 1 -1 1]); axis('square','equal');
            % Pongo los labels de los máximos
            g=gca;
            for ii=1:kk
                posx=xa(ii); posy=xb(ii); posz=xc(ii);
                if isnan(posz)
                    posz=0.1;
                end
                text(g,posx,posy,posz, ['J_{',num2str(ii),'}'],'FontSize',18, 'EdgeColor','k','BackgroundColor','w');
            end
            % markerfacecolor antiguo: [0 .75 .75])
            % otro chulo es carmesí [0.6350 0.0780 0.1840]
            scatter3(polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3), tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',"red");
            % g.Title.String={['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'],''.''}; % Le añado dos líneas
            g.Title.String={[app.data.preferences.Projection, ' contours of poles concentration. Isolines each ',num2str(100/numberisolines),'%'],''.''}; % Le añado dos líneas

            % preparo el puntero
            dcm = datacursormode(gcf);
            datacursormode on;
            proyeccion=app.data.preferences.Projection;
            % angulos=app.data.angulos;
            angulos=[0 0 0]; % No roto los ángulos, eso lo hace la otra gui
            set(dcm,'updatefcn',{@myupdatefcn_rotate,angulos,proyeccion} )

            % Primero verifico si existe el pathname
            if and(isfield(app.data,'pathname'), app.data.pathname ~= 0)
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if and(isfield(app.data,'filename'),app.data.filename ~= 0)
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end

            % Guardo la imagen
            name_png = [pathname,filename, ' - ', app.data.preferences.Projection, ' Contour plot', '.png'];
            name_pdf = [pathname,filename, ' - ', app.data.preferences.Projection, ' Contour plot', '.pdf'];
            % export_fig(name, '-m2.5', '-transparent');
            saveas(gcf, name_png);
            saveas(gcf, name_pdf);

            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - The contours of pole concentration was plotted. Total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: pushbutton_31editppalpoles
        function pushbutton_31editppalpolesButtonPushed(app, event)
            % Edición manual de los polos principales
            % Ahora preparo los datos a enviar al editor de polos
            % Lo meto todo en la estructura datos
            datos.normales=app.data.normales; % Las normales
            datos.polos_pples=app.data.polos_pples; % Son los que ya tengo, que puede que vengan de una edición anterior. Es dipdir, dip y densidad en rd
            datos.polos_pples_cart=app.data.polos_pples_cart;
            % datos.X=app.data.X;
            % datos.Y=app.data.Y;
            % datos.density=app.data.density;
            % datos.polos_estereo_cartesianas=app.data.polos_cartesianas_proyectadas;
            datos.P = app.data.Poriginal; % Le paso los datos originales
            datos.angulos=app.data.angulos;
            datos.pathname=app.data.pathname;
            datos.filename=app.data.filename;
            datos.preferences=app.data.preferences;

            % Si los planos principales todavía no tienen el % de puntos asignados, la matriz tendrá dimensión 3 filas.
            % Regularizo esa matriz para que tenga dimensión 4 columnas.
            % [n,c]=size(app.data.ppal_poles);
            % if c==3
            %     app.data.ppal_poles=[datos.ppal_poles zeros(n,1)];
            % end
            
            % Deshabilito la ventana principal para ver
            % sólo el editor de polos principales
            app.DiscontinuitySetExtractor4UIFigure.Visible="off";
            
            % Finalmente, llamo a la aplicación
            app.DialogApp=gui_ppalpoleseditor_v10(app,datos);         
        end

        % Button pushed function: Button_assign_ppalpoles
        function Button_assign_ppalpolesPushed(app, event)
            % Inputs
            % - P
            % - cone: apertura del cono
            % - polos_estereo_cartesianas
            % - polos_pples_cart
            % - ptCloud, si está disponible
            % Outputs
            % - P
            % - planos
            % - polos_pples
            % - polos_estereoppalasignados
            % - puntos_ppalasignados
            % - ptCloud_cono, que es la nube tras aplicar el cono y que
            % tiene en el campo de intensidad el valor de la familia de ese
            % punto
            tic; % Inicio un contador para ver el tiempo empleado
            proyeccion=app.data.preferences.Projection;
            cone=app.ConeEditField.Value;
            cone=cone/180*pi;
            
            % [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.data.preferences.Projection);
            % [polos_pples_cart]=f_calcula_coordenadas_polosvn(app.data.polos_pples(:,1:2),app.data.preferences.Projection);
            [planos_Clar]=prepara_estereo(app.data.normales);
            app.data.planos_Clar=planos_Clar;
            projection=app.data.preferences.Projection;
            
            % Tomo los polos, pero verifico en qué proyección están
            if isequal(app.data.preferences.Projection,app.data.preferences.Projection_previa)
                % No tengo que calcular los polos de nuevo. Carlos los
                % polos
                polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas;
            else
                % Ha cambiado la proyección, recalculo los polos
                [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.data.preferences.Projection);
                app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas; % guardo esta variable machacando la anterior
                app.data.preferences.Projection_previa=app.data.preferences.Projection;
                % Informo que he recalculado los polos
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                S2 = [DateString, ' - The poles of the normal vectors have been recomputed using the ', app.data.preferences.Projection, ' projection.'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
            % [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(planos_Clar,projection);
            
            [polos_pples_cart]=f_calcula_coordenadas_polosvn(app.data.polos_pples(:,1:2)/180*pi,projection);

            % polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas;
            % polos_pples_cart=app.data.polos_pples_cart;
            
             % Cuidado, hay que cargar el que está sin tratar todavía
            P=app.data.P;
            [polos_estereoppalasignados, puntos_ppalasignados] = f_setppal2planes_v02( cone, polos_cartesianas_proyectadas, polos_pples_cart ,P,proyeccion);
            app.data.polos_estereoppalasignados=polos_estereoppalasignados;
            app.data.puntos_ppalasignados=puntos_ppalasignados;
            app.pushbutton_plotstereopolesppal.Enable='on';
            app.pushbutton_plot3dpointsppal.Enable='on';
            % guardo los datos con los que se ha hecho el cálculo, para el
            % reporte
            app.data.cone=cone;
            % actualizo el numero de puntos que tengo
            % I es el índice de los puntos con cluster asignado
            I=find(polos_estereoppalasignados(:,3)>0);
            % Si he cargado un .ply, es probable que tenga normales,
            % colores e incluso escalares. Vamos a mantenerlos.
            if isempty(app.data.ptCloud)
                % No tengo el ptCloud, por lo que no hago nada
            else
                % Tengo un ptCloud, por lo que sencillamente le quito los
                % puntos que no tienen normales asignadas
                app.data.ptCloud_cono = select(app.data.ptCloud,I);
                % Observa que si tiene normales o colores, los mantiene
                % Le pongo como intensidad el índice de la familia
                app.data.ptCloud_cono.Intensity=polos_estereoppalasignados(I,3);
                % Si tuviera intensidad, la machaco con el índice de la familia, porque el ptCloud sólo
                % me deja tener un escalar en el campo de intensidad
            end
            % actualizo los datos a aquellos con cluster
             % Si quisiseras guardar los resultados intermedios, debería de
             % poner aquí un apellido tipo _trascono o algo así
            P=P(I,:);
            app.data.P=P;
            % actualizo el número de puntos que representan los polos principales
            polos_pples=app.data.polos_pples;
            [n,~]=size(P); % número de puntos totales
            [nf,~]=size(polos_pples); % número de familias que hay
            for i=1:nf
                % calculo el % de puntos que hay y lo pongo en la cuarta columna
                [npuntos,~]=size(polos_estereoppalasignados(polos_estereoppalasignados(:,3)==i));
                polos_pples(i,4)=npuntos/n*100;
            end
            app.data.polos_pples=polos_pples;
            
            % En la versión 3.02 aquí calculaba los HSV, pero como lo he
            % movido a otra parte del programa, lo puedo eliminar

            % activamos la fase de análisis cluster
            app.DBSCAN.Enable='on';
            app.FixnormalvectorCheckBox.Enable='on';
            app.Button_Save_XYZJs.Enable='on';
            
            % Como acabo de aplicar el cono, el DBScan sólo tiene que tener
            % operativo el botón de cálculo, el resto tiene que estar
            % deshabilitado. Los deshabilito por si estoy volviendo atrás
            % en algún paso más avanzado.
            app.UITable_clusters.Enable='off';
            app.ksigmasEditField.Enable='off';
            app.DBSCAN_edit.Enable='off';
            app.Clusters_merge.Enable='off';
            app.Clusters_restore.Enable='off';
            % los botones de exportación y guardado
            app.Button_Export_Alldata.Enable='off';
            
            
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Ppal poles assignment to each point, cone angle from the axis to the generatrix: ',num2str(cone/pi*180,2),'°. N of points: ',num2str(length(I)),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: pushbutton_plotstereopolesppal
        function pushbutton_plotstereopolesppalButtonPushed(app, event)
            
            % Si ha cambiado la proyección, no valen los datos que tengo
            % porque la asignación también estará mal, hay que recalcular
            % la asignación de polos
            if isequal(app.data.preferences.Projection,app.data.preferences.Projection_previa)
                % No ha cambiado y puedo seguir con la representación de
                % polos asignados
            else
                % Ha cambiado la proyección, recalculo los polos y la
                % asignación
                app.Button_assign_ppalpoles;
                % Informo que he recalculado los polos
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                S2 = [DateString, ' - The projection has changed to ',app.data.preferences.Projection,', so I need to reprocess the poles and its assignements.'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
            
            % Primero vamos a ver qué campos existen
            if app.DBSCAN_edit.Enable=="on"
                % Ya hemos hecho el dbscan y represento con los limpios
                if isfield(app.data,'polos_estereoppalasignados_afterdbscan_limpio')==1 && isempty(app.data.polos_estereoppalasignados_afterdbscan_limpio)==0
                    A=app.data.polos_estereoppalasignados_afterdbscan_limpio;
                else
                    if isfield(app.data,'polos_estereoppalasignados_afterdbscan')==1 && isempty(app.data.polos_estereoppalasignados_afterdbscan)==0
                        A=app.data.polos_estereoppalasignados_afterdbscan;
                    else
                        if isfield(app.data,'polos_estereoppalasignados')==1 && isempty(app.data.polos_estereoppalasignados)==0
                            A=app.data.polos_estereoppalasignados;
                        else
                            % Ya no hay mas casos, dejo A vacío para que falle
                        end
                    end
                end
            else
                % Todavía no hemos hecho el DBSCAN y tengo que usar el que
                % sale de la asignación del cono
                A=app.data.polos_estereoppalasignados;
            end
            % I=find(A(:,3)>0);
            A=A(A(:,3)>0,:);
            % represento las estereográficas
            h=figure;
            % dibujamos la falsilla
            switch app.data.preferences.Projection
                case 'Equal-angle'
                    falsilla_riquelme_Equalangle_v02;
                case 'Equal-area'
                    falsilla_riquelme_Equalarea_v02
                otherwise
                    % algo ha pasado
            end
            h.Color=[1 1 1];
            axis('square','equal');

             % Dibujo los polos según familias
            tamanyopunto=1;
            % Separar puntos por categoría
            puntos_cero = A(A(:, 3) == 0, :);
            puntos_no_cero = A(A(:, 3) > 0, :);
            % Dibuja los puntos con categoría 0 (gris)
            if ~isempty(puntos_cero)
                scatter(h, puntos_cero(:, 1), puntos_cero(:, 2), tamanyopunto, [0.5 0.5 0.5], 'filled'); % 20 es el tamaño de los puntos
            end
            % Dibujo los puntos con categoría mayor que 0 (escala de colores)
            if ~isempty(puntos_no_cero)
                nds = max(puntos_no_cero(:, 3));
                map = aux_colorcet('R4', 'N', nds); % Asumiendo que aux_colorcet está en el path
                colormap(h, map);
                % scatter(h, puntos_no_cero(:, 1), puntos_no_cero(:, 2), tamanyopunto, puntos_no_cero(:, 3), 'filled');
                scatter(puntos_no_cero(:, 1), puntos_no_cero(:, 2), tamanyopunto, puntos_no_cero(:, 3), 'filled');
            end

            % Activo la barra de colores con etiquetas
            % color_edges = 0.5:1:(nds+0.5); % Crear bordes de colores
            tick_positions = 1:nds; % Crear posiciones de etiquetas
            c = colorbar; % Crear colorbar
            c.Ticks = tick_positions; % Establecer posiciones de ticks
            c.TickLabels = num2cell(1:nds); % Establecer etiquetas de ticks
            c.Limits = [0.5 nds+0.5]; % Establecer límites del colorbar
            c.TickLength = 0; % Eliminar marcas de tick
            c.FontSize = 10; % Tamaño de fuente
            clim([0.5 nds+0.5]); % Establecer límites de color
            
            h.Name=['Stereographic Projection Assigned Principal Poles: ',num2str(length(A)),' points'];
            g=gca;
            g.Title.String={[app.data.preferences.Projection, ' plot of ', num2str(length(A)),' cassified normals'],'',''}; % Le añado dos líneas
            % Guardo la imagen en un fichero
            % Primero verifico si existe el pathname
            if and(isfield(app.data,'pathname'),app.data.pathname ~= 0)
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if and(isfield(app.data,'filename'),app.data.filename ~= 0)
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end
            % Ya puedo guardar la imagen
            name_png = [pathname, filename, ' - Assigned Poles', '.png']; saveas(gcf, name_png);
            name_pdf = [pathname, filename, ' - Assigned Poles', '.pdf']; saveas(gcf, name_pdf);

            % preparo el puntero
            dcm = datacursormode(gcf);
            datacursormode on;
            proyeccion=app.data.preferences.Projection;
            % angulos=app.data.angulos;
            angulos=[0 0 0]; % No roto los ángulos, eso lo hace la otra gui
            set(dcm,'updatefcn',{@myupdatefcn_rotate,angulos,proyeccion} )

            % Informo que he dibujado los polos
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - I have plotted the assigned poles of the normal vectors in an ', app.data.preferences.Projection, ' projection.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;

        end

        % Button pushed function: pushbutton_plot3dpointsppal
        function pushbutton_plot3dpointsppalButtonPushed(app, event)
            % Uso o no del Computer Vision Toolbox de MATLAB
            version=ver;
            toolboxes={version.Name};
            % uso=length(find(contains(toolboxes,'Computer Vision Toolbox')==1));
            if isscalar(find(contains(toolboxes,'Computer Vision Toolbox')==1))
                if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
                    % Tengo el Toolbox para nubes de puntos y puedo usarlo
                    % Este código es para mostrar la nube clasificada
                    % Voy a trasladar al origen la nube
                    ptCloud = app.data.ptCloud_cono;     % Obtiene la nube de puntos del objeto app
                    xyzPoints = ptCloud.Location;      % Extrae las coordenadas X, Y, Z
                    centroGravedad = min(xyzPoints);   % Calcula el centro de gravedad
                    xyzPoints_desplazado = xyzPoints - centroGravedad; % Desplaza las coordenadas
                    ptCloud = pointCloud(xyzPoints_desplazado); % Crea nueva nube desplazada
                    ptCloud.Intensity=app.data.ptCloud_cono.Intensity; % Le paso la intensidad
                    h = figure;                         % Crea una nueva figura
                    pcshow(ptCloud, ColorSource="Intensity"); % Muestra la nube desplazada
                    % Le pongo un mapa de colores discreto, que puedo hacer
                    % con jet
                    nds=max(ptCloud.Intensity);
                    % colormap(h,jet(nds));
                    map=aux_colorcet('R4','N',nds); % Se supone que está el subfolder añadido
                    colormap(h,map);
                    % colormap(h,jet); % Cambio el mapa de color a jet
                    h.Color=[ 1 1 1]; % pongo el fondo blanco
                    h.CurrentAxes.Color=[1 1 1]; % el fondo de los axes tb en blanco
                    % Pongo los ejes en negro
                    h.CurrentAxes.GridColor=[0 0 0]; % los ejes en negro
                    h.CurrentAxes.XColor=[0 0 0];
                    h.CurrentAxes.YColor=[0 0 0];
                    h.CurrentAxes.ZColor=[0 0 0];
                    h.CurrentAxes.Projection='orthographic';
                    h.Name='3D point cloud coloured using one colour for each DS';
                    % color_edges = 0.5:1:(nds+0.5); % Crear bordes de colores
                    tick_positions = 1:nds; % Crear posiciones de etiquetas
                    c = colorbar; % Crear colorbar
                    c.Ticks = tick_positions; % Establecer posiciones de ticks
                    c.TickLabels = num2cell(1:nds); % Establecer etiquetas de ticks
                    c.Limits = [0.5 nds+0.5]; % Establecer límites del colorbar
                    c.TickLength = 0; % Eliminar marcas de tick
                    c.FontSize = 10; % Tamaño de fuente
                    clim([0.5 nds+0.5]); % Establecer límites de color
                else
                    % No tengo el ptcloud, por lo que tengo que preparar
                    % los puntos para representarlos
                    puntos_ppalasignados=app.data.puntos_ppalasignados;
                    % I=find(puntos_ppalasignados(:,4)>0);
                    A=puntos_ppalasignados(puntos_ppalasignados(:,4)>0,:);
                    h=figure; h.Color=[1 1 1];
                    scatter3(A(:,1),A(:,2),A(:,3),20,A(:,4),'filled');
                    h.Name=['3D Points Family assigned: ',num2str(length(A)),' points'];
                    axis equal ,xlabel('axis X'),ylabel('axis Y'),zlabel('axis Z');
                end
            end
        end

        % Button pushed function: DBSCAN
        function DBSCANPushed(app, event)
            % cálculo de los clústers sin mínimo de puntos por cluster. Eso se asigna
            % con el editor de clústers
            % Inputs
            % - puntos_ppalasignados
            % - polos_pples
            % - polos_estereoppalasignados
            % Outputs
            % - familia_cluster_plano_fullclusters
            % - puntos_familia_cluster_fullclusters
            % - familia_cluster_plano
            % - puntos_familia_cluster
            % - polos_estereoppalasignados
            tic % para tener el tiempo que tardo en hacer el análisis cluster, se pondrá en el log
            % guardo los datos con los que he calculado los clusters
            ppcluster=4; % Es el número mínimo de puntos que tienen que tener un cluster para ser considerado como tal
            app.data.ppcluster=ppcluster;
            puntos_ppalasignados=app.data.puntos_ppalasignados;
            polos_pples=app.data.polos_pples;
            polos_estereoppalasignados=app.data.polos_estereoppalasignados;
            vnfamilia=app.FixnormalvectorCheckBox.Value; app.data.vnfamilia = vnfamilia;
            % ksigmas=app.ksigmasEditField.Value; app.data.ksigmas = ksigmas;
            [ puntos_familia_cluster_fullclusters, familia_cluster_plano_fullclusters, polos_estereoppalasignados_afterdbscan ] = f_ppal2cluster_v10( puntos_ppalasignados, polos_pples, ppcluster,vnfamilia, polos_estereoppalasignados);
            % Guardamos en las app la clasificación con todos los clusters
            % la salida es con todos los clusters sin filtrar, luego los guardamos en
            % los datos globales (app) para utilizarlos siempre
            app.data.puntos_familia_cluster_fullclusters=puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano_fullclusters=familia_cluster_plano_fullclusters;
            % a partir de aquí, utilizamos siempre estas dos salidas, que se irán
            % modificando al editar el número mínimo de puntos por cluster
            app.data.puntos_familia_cluster=puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano=familia_cluster_plano_fullclusters;
            % Además, hay que dejar las variables en el workspace local
            % para seguir trabajando
            % puntos_familia_cluster=puntos_familia_cluster_fullclusters;
            % familia_cluster_plano=familia_cluster_plano_fullclusters;
            % Guardo los polos en cartesianas tras el dbscan
            app.data.polos_estereoppalasignados_afterdbscan=polos_estereoppalasignados_afterdbscan;
            % Preparo los siguientes botones
            app.UITable_clusters.Enable='on';

            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;

            % activo la edición de clusters
            app.DBSCAN_edit.Enable='on';
            app.Clusters_merge.Enable='on';
            app.Clusters_restore.Enable='on';
            app.ksigmasEditField.Enable='on';
            % set(app.popupmenu_save_output.Enable='on';
            app.Button_Export_Alldata.Enable='on';
            % calculo el número de puntos tras el análisis cluster
            [npuntos,~]=size(puntos_familia_cluster_fullclusters);
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            if vnfamilia ==1
                S3 = 'Cl`s planes are oriented with its ppal pole normal vector.';
            else
                S3 = 'Cl`s planes are oriented with its best fit plane.';
            end
            S2 = [DateString, ' - Cluster analysis. ',S3,', n of points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            clear_gui(app);
        end

        % Value changed function: CoplanaritytestSwitch
        function CoplanaritytestSwitchValueChanged(app, event)
            value = app.CoplanaritytestSwitch.Value;
            switch value
                case 'On'
                    app.box_tolerancia.Enable='on';
                case 'Off'
                    app.box_tolerancia.Enable='off';
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end

        % Button pushed function: DBSCAN_edit
        function DBSCAN_editPushed(app, event)
            % Desactivo la UI principal para trabajar sólo con el editor
            app.DiscontinuitySetExtractor4UIFigure.Visible="off";
            % Los datos que necesito están en app.data, sólo necesito
            % algunos. Guardo en una variable llamada datos lo que hace
            % falta para editar los clusters
            datos.puntos_familia_cluster=app.data.puntos_familia_cluster;
            datos.familia_cluster_plano=app.data.familia_cluster_plano;
            datos.puntos_familia_cluster_fullclusters=app.data.puntos_familia_cluster_fullclusters;
            datos.familia_cluster_plano_fullclusters=app.data.familia_cluster_plano_fullclusters;
            datos.polos_estereoppalasignados_afterdbscan=app.data.polos_estereoppalasignados_afterdbscan;

            % Llamo al editor de clusters y le paso la estructura datos
            app.DialogApp=gui_clusterseditor_v03(app, datos);

        end

        % Button pushed function: Clusters_merge
        function Clusters_mergePushed(app, event)
            % value = app.Clusters_merge.Value;
            % cálculo de los clústers sin mínimo de puntos por cluster. Eso se asigna
            % con el editor de clústers
            tic % para tener el tiempo que tardo en hacer el análisis cluster, se pondrá en el log
            % guardo los datos con los que he calculado los clusters
            app.data.vnfamilia=app.FixnormalvectorCheckBox.Value;
            app.data.ksigmas=app.ksigmasEditField.Value;
            [app.data.familia_cluster_plano] = f_merge_clusters_v01(app.data.ksigmas, app.data.vnfamilia, app.data.puntos_familia_cluster, app.data.familia_cluster_plano);% Guardamos en las handles la clasificación con todos los clusters
            % la salida es con todos los clusters sin filtrar, luego los guardamos en
            % Pongo la matriz de familia cluster planos en la interfaz
            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;
            % calculo el número de puntos tras el análisis cluster
            [npuntos,~]=size(app.data.puntos_familia_cluster);
            tiempoempleado=floor(toc);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            if app.data.vnfamilia ==1
                S3 = 'Cl`s planes are oriented with its ppal pole normal vector.';
            else
                S3 = 'Cl`s planes are oriented with its best fit plane.';
            end
            S2 = [DateString, ' - Coplanar clusters were merged. ',S3,', n of points: ',num2str(npuntos),'; total time: ', num2str(tiempoempleado),' seconds'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: Clusters_restore
        function Clusters_restoreButtonPushed(app, event)
            % cargo los datos
            % guardo la situación actual de los clusters en la tabla de la gui
            % Para guardar la tabla, traslado D
            [tabla_homogeneizada] = f_tabla_homogeneizaD(app.data.familia_cluster_plano);
            app.UITable_clusters.Data=tabla_homogeneizada;
            % guardo la información en las handles
            app.data.puntos_familia_cluster=app.data.puntos_familia_cluster_fullclusters;
            app.data.familia_cluster_plano=app.data.familia_cluster_plano_fullclusters;
            % calculo el número de puntos
            [npuntos,~]=size(app.data.puntos_familia_cluster);
            % guardo la información en el log
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Cluster restored without minimum number of points per cluster. N of points: ',num2str(npuntos)];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: Button_Save_XYZJs
        function Button_Save_XYZJsPushed(app, event)
            tic % pongo el contador a 0
            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname') % & app.data.pathname ~= 0
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if isfield(app.data,'filename') % & app.data.filename ~= 0
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end
            puntos_ppalasignados=app.data.puntos_ppalasignados;
            % comprobamos si hay alguna familia asignada
            if max(puntos_ppalasignados(:,4))>0
                % I=find(puntos_ppalasignados(:,4)>0);
                puntos_ppalasignados=puntos_ppalasignados(puntos_ppalasignados(:,4)>0,:);
            end
            im=puntos_ppalasignados(:,4); %guardamos las familias
            % transforma escala de intensidades (0 a n) a RGB (0 a 1)  from http://www.alecjacobson.com/weblog/?p=1655
            n = size(unique(reshape(im,size(im,1)*size(im,2),size(im,3))),1);
            im= double(im);
            im=im*255/n;
            im=uint8(im);
            rgb = ind2rgb(im,jet(255));
            % Crear nube de puntos con atributo RGB (0 a 255)
            colores=zeros(size(puntos_ppalasignados,1),3);
            colores(:,1)=reshape(round(rgb(:,:,1)*255),[],1);
            colores(:,2)=reshape(round(rgb(:,:,2)*255),[],1);
            colores(:,3)=reshape(round(rgb(:,:,3)*255),[],1);
            % IDEA IMPORTANTE, METER EL JS COMO INTENSITY Y PASAR DEL TXT OUTPUT
            % Guardo únicamente el XYZ_Js, el RGB no sirve para nada en CC
            XYZ_JS=puntos_ppalasignados(:,1:3);
            XYZ_JS(:,4)=puntos_ppalasignados(:,4); % le pongo también las familias para que el escalar las pueda representar
            [~]=f_savematrix([pathname,filename, ' XYZ-JS-early_classification.txt'], XYZ_JS);
            % NOTA. ESTO ESTÁ DANDO FALLO, REVISAR.
            % Ahora guardo el archivo del pointcloud
            % XYZ_RGB_HSV=app.data.XYZ_RGB_HSV;
            % % Uso o no del Computer Vision Toolbox de MATLAB
            % version=ver;
            % toolboxes={version.Name};
            % if isscalar(find(contains(toolboxes,'Computer Vision Toolbox')==1))
            %     if str2double(getfield(version,{find(contains(toolboxes,'Computer Vision Toolbox')==1)},'Version'))>=6.2
            %         % Tengo el Toolbox para nubes de puntos y puedo usarlo
            %         % guardamos un archivo ply
            %         app.data.ptCloud_RGB=pointCloud(puntos_ppalasignados(:,1:3));
            %         app.data.ptCloud_RGB.Color=uint8(colores);
            %         % Si lo quiero guardar hago esto
            %         % pcwrite(ptCloud_early,[pathname, filename, ' XYZ-RGB-early_classification.ply'],'PLYFormat','binary');
            %         % Si lo quiero dibujar hago esto:
            %         % figure; pcshow(ptCloud_early);
            % 
            %         % Preparo la nube con el HSV y la guardo
            %         app.data.ptCloud_HSV=pointCloud(XYZ_RGB_HSV(:,1:3));
            %         app.data.ptCloud_HSV.Normal=XYZ_RGB_HSV(:,7:9);
            %         app.data.ptCloud_HSV.Color=uint8(XYZ_RGB_HSV(:,4:6));
            %         pcwrite(app.data.ptCloud_HSV,[pathname, filename, ' XYZ-HSV-early_classification.ply'],'PLYFormat','binary');
            %     else
            %         % No lo tengo y empleo la programación antigua
            %     end
            % end
            % guardo la información en el log
            tiempo=floor(toc);
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - An early classification was saved: ',filename, ' XYZ-JS-early_classification.txt. ', num2str(tiempo), ' s.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Menu selected function: menu_loadddb
        function menu_loadddbMenuSelected(app, event)
            warning off;
            tic;
            % Primero verifico si existe el pathname
            if isfield(app.data,'pathname')
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            [filename, pathname] = uigetfile([pathname,'*.mat'],'Abra el archivo de datos');
            if filename~=0
                S= load(strcat(pathname,filename));
                % Ahora nos quedamos sólo con el nombre del archivo
                [~,filename,~] = fileparts(filename);
                % Pongo el nombre del archivo en la ventana
                app.data.filename=filename;
                app.data.pathname=pathname;
                % El directorio podría haber cambiado, así que dejamos el
                % directorio desde el que han cargado el archivo
                S.datos.pathname=pathname;
                S.datos.filename=filename;
                app.data=S.datos;
                % Restauramos la configuración
                % Aquí únicamente establecemos si el menú está activo o no
                app.FileMenu.Enable=S.interfaz.FileMenu.Enable;
                app.ClearMenu.Enable=S.interfaz.CleanMenu.Enable;
                app.menu_loadxyz.Enable=S.interfaz.menu_loadxyz.Enable;
                app.menu_loadddb.Enable=S.interfaz.menu_loadddb.Enable;
                app.menu_saveddbb.Enable=S.interfaz.menu_saveddbb.Enable;
                app.ToolsMenu.Enable=S.interfaz.ToolsMenu.Enable;
                app.NormalspacingMenu.Enable=S.interfaz.NormalspacingMenu.Enable;
                app.PersistenceMenu.Enable=S.interfaz.PersistenceMenu.Enable;
                app.HelpMenu.Enable=S.interfaz.HelpMenu.Enable;
                app.AboutMenu.Enable=S.interfaz.AboutMenu.Enable;

                % Al cargar la nube, ya se puede representar
                app.pushbutton_plot3dpoints.Enable=S.interfaz.pushbutton_plot3dpoints.Enable;

                % 2 activamos el panel de normales, si procede
                app.pushbutton_2setupplanes.Enable=S.interfaz.pushbutton_2setupplanes.Enable;
                % Si el botón de normales está activado, lo estarán los
                % colores para las normales
                app.knnEditFieldLabel.Enable=S.interfaz.knnEditFieldLabel.Enable;
                app.box_nneighbours.Enable=S.interfaz.box_nneighbours.Enable;app.box_nneighbours.Value=S.interfaz.box_nneighbours.Value;
                app.toleranceEditFieldLabel.Enable=S.interfaz.toleranceEditFieldLabel.Enable;
                app.box_tolerancia.Enable=S.interfaz.box_tolerancia.Enable;app.box_tolerancia.Value=S.interfaz.box_tolerancia.Value;
                % Los test de coplanaridad:
                app.CoplanaritytestSwitchLabel.Enable=S.interfaz.CoplanaritytestSwitchLabel.Enable;
                app.CoplanaritytestSwitch.Enable=S.interfaz.CoplanaritytestSwitch.Enable;app.CoplanaritytestSwitch.Value=S.interfaz.CoplanaritytestSwitch.Value;
                % Si hemos calculado las normales, puedo representar los
                % polos
                app.pushbutton_plotstereopoles.Enable=S.interfaz.pushbutton_plotstereopoles.Enable;

                % 3 Activamos en análisis de densidades
                app.UITable_principalplanes.Enable=S.interfaz.UITable_principalplanes.Enable;app.UITable_principalplanes.Data=S.interfaz.UITable_principalplanes.Data;
                app.pushbutton_3statanalysis.Enable=S.interfaz.pushbutton_3statanalysis.Enable;
                app.pushbutton_31editppalpoles.Enable=S.interfaz.pushbutton_31editppalpoles.Enable;
                app.BinslevelSpinnerLabel.Enable=S.interfaz.BinslevelSpinnerLabel.Enable;
                app.BinslevelSpinner.Enable=S.interfaz.BinslevelSpinner.Enable;app.BinslevelSpinner.Value=S.interfaz.BinslevelSpinner.Value;
                app.MinangleLabel.Enable=S.interfaz.MinangleEditFieldLabel.Enable;
                app.MinangleEditField.Enable=S.interfaz.MinangleEditField.Enable;app.MinangleEditField.Value=S.interfaz.MinangleEditField.Value;
                app.NmaxDSEditFieldLabel.Enable=S.interfaz.NmaxDSEditFieldLabel.Enable;
                app.NmaxDSEditField.Enable=S.interfaz.NmaxDSEditField.Enable;app.NmaxDSEditField.Value=S.interfaz.NmaxDSEditField.Value;
                % puedo representar la densidad
                app.pushbutton_plotdensitypoles.Enable=S.interfaz.pushbutton_plotdensitypoles.Enable;

                % 4 Activamos la asignación de polos a polo principal
                app.Button_assign_ppalpoles.Enable=S.interfaz.Button_assign_ppalpoles.Enable;
                app.ConeLabel.Enable=S.interfaz.ConeEditFieldLabel.Enable;
                app.ConeEditField.Enable=S.interfaz.ConeEditField.Enable;app.ConeEditField.Value=S.interfaz.ConeEditField.Value;
                % He asignado polos, puedo representar cosas
                app.pushbutton_plot3dpointsppal.Enable=S.interfaz.pushbutton_plot3dpointsppal.Enable;
                app.pushbutton_plotstereopolesppal.Enable=S.interfaz.pushbutton_plotstereopolesppal.Enable;
                               
                % 5 Análisis clúster
                % Si tengo en análisis cluster encendido, es que he
                % asignado los polos y puedo hacer análisis estadístico
                app.UITable_clusters.Enable=S.interfaz.UITable_clusters.Enable;app.UITable_clusters.Data=S.interfaz.UITable_clusters.Data;
                app.ksigmasEditFieldLabel.Enable=S.interfaz.ksigmasEditFieldLabel.Enable;
                app.ksigmasEditField.Enable=S.interfaz.ksigmasEditField.Enable;app.ksigmasEditField.Value=S.interfaz.ksigmasEditField.Value;
                app.FixnormalvectorCheckBox.Enable=S.interfaz.FixnormalvectorCheckBox.Enable;app.FixnormalvectorCheckBox.Value=S.interfaz.FixnormalvectorCheckBox.Value;
                app.DBSCAN.Enable=S.interfaz.Button_DBSCAN.Enable;
                app.DBSCAN_edit.Enable=S.interfaz.DBSCAN_edit.Enable;
                app.Clusters_merge.Enable=S.interfaz.Clusters_merge.Enable;
                app.Clusters_restore.Enable=S.interfaz.Clusters_restore.Enable;

                % Menú de botones para exportar resultados
                app.Button_Save_database.Enable=S.interfaz.Button_Save_database.Enable;
                app.Button_Save_XYZJs.Enable=S.interfaz.Button_Save_XYZJs.Enable;
                app.Button_Export_Alldata.Enable=S.interfaz.Button_Save_results.Enable;
                
                % Ventana del log del log
                % app.ConsoleListBoxLabel.Enable=S.interfaz.ConsoleListBoxLabel.Enable;
                app.LogListBox.Enable=S.interfaz.LogListBox.Enable;app.LogListBox.Items=S.interfaz.LogListBox.Items;
               
                % guardo la información en el log
                tiempo=floor(toc);
                a = app.LogListBox.Items;
                DateString=char(string(datetime));
                S2 = [DateString, ' - Database recovered: ',pathname,filename, 'Total time: ',num2str(tiempo), ' seconds.'];
                S=[a S2];
                app.LogListBox.Items=S;
                % fijo la vista del listbox en la última entrada
                scroll(app.LogListBox, 'bottom')
                app.data.log = S;
            end
            figure(app.DiscontinuitySetExtractor4UIFigure); % Traer la ventana de la aplicación al frente
        end

        % Menu selected function: menu_saveddbb
        function menu_saveddbbMenuSelected(app, event)
            save_database(app);
        end

        % Button pushed function: Button_Save_database
        function Button_Save_databasePushed(app, event)
            save_database(app);
        end

        % Menu selected function: NormalspacingMenu
        function NormalspacingMenuSelected(app, event)
            gui_normalspacing;
        end

        % Menu selected function: PersistenceMenu
        function PersistenceMenuSelected(app, event)
            gui_persistence;
        end

        % Button pushed function: Button_Export_Alldata
        function Button_Export_AlldataPushed(app, event)
            tic
            % Primero verifico si existe el pathname
            if and(isfield(app.data,'pathname'),app.data.pathname ~= 0)
                % Si existe lo pongo
                pathname = app.data.pathname;
            else
                % Si no existe tomo en el que está
                pathname = [pwd,'\'];
            end
            if and(isfield(app.data,'filename'),app.data.filename ~= 0)
                filename = app.data.filename;
                [~,filename,~] = fileparts(filename);
            else
                filename = 'Output';
            end

            % Se genera un reporte con los datos
            [npoints,~]=size(app.data.P);
            if isfield(app.data,'npb')
                knn = app.data.npb;
            else
                app.data.npb=0;
                knn=0;
            end
            if isfield(app.data,'tolerancia')
                tolerancia = app.data.tolerancia;
            else
                app.data.tolerancia=0;
                tolerancia=0;
            end

            nsec = app.data.nsec;
            angulovpples = app.data.angulovpples;
            cone = app.data.cone;
            vnfamilia = app.data.vnfamilia;
            if isfield(app.data,'ksigmas')
                ksigmas = app.data.ksigmas;
            else
                ksigmas = app.ksigmasEditField.Value;
            end

            [nfamilias,~] = size(app.data.polos_pples); % calculada al principio de este callback
            [ ~ ] = f_report_v02( pathname, filename, npoints, knn, tolerancia, nsec, angulovpples, cone, vnfamilia, ksigmas, nfamilias, app.data.polos_pples, app.data.familia_cluster_plano);

            %guardamos las coordenadas, familia y clúster
            A=app.data.puntos_familia_cluster;
            [~]=f_savematrix([pathname,filename, ' xyz-js-c.txt'], A);

            %guardamos la familia clúster parámetros del plano
            A=app.data.familia_cluster_plano;
            [~] = f_savematrix([pathname,filename, ' js-c-abcd.txt'],A);

            % guardamos todas las coordenadas con todos los datos
            A=app.data.puntos_familia_cluster;
            [np,~]=size(A); % número de puntos en el point cloud
            B=app.data.familia_cluster_plano;
            C=zeros(np,9); C(:,1:5)=A(:,1:5);
            jointset=unique(B(:,1));
            [njs,~]=size(jointset);
            for i=1:njs
                clusters=unique(B(B(:,1)==jointset(i),2));
                [ncs,~]=size(clusters);
                for j=1:ncs
                    I=find(C(:,4)==jointset(i) & C(:,5)==clusters(j));
                    C(I,6)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),4);
                    C(I,7)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),5);
                    C(I,8)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),6);
                    C(I,9)=B(B(:,1)==jointset(i) & B(:,2)==clusters(j),7);
                end
            end
            [~] = f_savematrix([pathname,filename, ' xyz-js-c-abcd.txt'],C);
            
            % Genero un fichero que me de la normal del punto y su dipdir y dip
            % A es la matriz con el subconjunto de puntos. B es la matriz con el conjunto más grande de puntos
            [~, indices] = ismember(app.data.puntos_familia_cluster(:, 1:3), app.data.puntos_ppalasignados(:, 1:3), 'rows');
            normales=app.data.normales(indices,:);
            % Genero una matriz con los datos xyz-js-c-normales-dipdir-dip
            A=zeros(length(indices),3+2+3+2);
            A(:,1:3)=app.data.puntos_familia_cluster(:,1:3); % Paso los puntos
            A(:,7:8)=app.data.puntos_familia_cluster(:,4:5); % Paso la familia y el cluster
            A(:,4:6)=normales;
            [ dipdir, dip ] = f_vnorm2clar_v02( normales );
            A(:,9:10)=[ dipdir, dip ];
            [~]=f_savematrix([pathname,filename, ' xyz-NxNyNz-js-c-dipdir-dip.txt'], A);

            % genero la salida Dipdir_Dip_Density
            [~] = f_savematrix([pathname,filename,' Dipdir-Dip-Density.txt'],app.data.polos_pples);

            % guardo la información en el log
            tiempo=floor(toc);
            a = app.LogListBox.Items;
            DateString=char(string(datetime));
            S2 = [DateString, ' - Saved all the results to text files; total time: ', num2str(tiempo),' seconds.'];
            S=[a S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;

            % Y ya puestos, guardo el log en un log.txt
            f = fopen([pathname,filename,' - log.txt'], 'wt');
            % [n,~]=size(S);
            n=length(S);
            for i=1:n
                fprintf(f, '%s \n', S{1,i});
            end
            fclose(f);
        end

        % Close request function: DiscontinuitySetExtractor4UIFigure
        function DiscontinuitySetExtractor4UIFigureCloseRequest(app, event)
            % Borro las ventanas auxiliares, por si acaso
            delete(app.DialogApp);
            delete(app);
        end

        % Callback function: PushTool_Clear
        function PushTool_ClearClicked(app, event)
            clear_gui(app);
        end

        % Callback function: PushTool_Load3DP
        function PushTool_Load3DPClicked(app, event)
            menu_load3DPCSelected(app);
        end

        % Callback function: PushTool_SaveProject
        function PushTool_SaveProjectClicked(app, event)
            save_database(app);
        end

        % Callback function: PushTool_LoadProject
        function PushTool_LoadProjectClicked(app, event)
            menu_loadddbMenuSelected(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Value changed function: LogListBox
        function LogListBoxValueChanged(app, event)
            value = app.LogListBox.ValueIndex;
            switch value
                case 2 % Selección de método
                    web('https://doi.org/10.1016/j.cageo.2014.03.014', '-browser');
                case 3 % Selección de espaciado
                    web('https://doi.org/10.1016/j.enggeo.2015.06.009', '-browser');
                case 4 % Selección de persistencia
                    web('https://doi.org/10.1016/j.ijrmms.2015.12.008', '-browser');
                case 5 % Selección de wiki
                    web('https://personal.ua.es/en/ariquelme/dse-wiki/dse-wiki.html', '-browser');
            end

            
        end

        % Callback function: PushTool_Colouring_normals
        function PushTool_Colouring_normalsClicked(app, event)
            % Representación de la nube de puntos con color según normales
            
            % En primer lugar, deshabilito la ventana principal para ver
            % sólo el editor de polos principales
            app.DiscontinuitySetExtractor4UIFigure.Visible="off";

            % Ahora preparo los datos a enviar al editor de polos
            % Lo meto todo en la estructura datos
            datos.normales=app.data.normales; % Los vectores normales que he cargado
            datos.planos_Clar=app.data.planos_Clar;
            datos.polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas; % polos en cartesianas, que son las normales de los puntos
            datos.angulos=app.data.angulos;
            datos.preferences=app.data.preferences;

            if isempty(app.data.ptCloud)
                % Sólo cargo P, porque no tengo el ptCloud
                datos.P=app.data.P; % coordenadas de los puntos, evidentemente en el mismo orden que los polos. De lo contrario, a ver qué hacemos.
                datos.P=app.data.Poriginal;
            else
                % Cargo P y el ptCloud
                datos.P=app.data.P; % coordenadas de los puntos, evidentemente en el mismo orden que los polos. De lo contrario, a ver qué hacemos.
                datos.P=app.data.Poriginal;
                datos.ptCloud=app.data.ptCloud; % la nube de puntos con sus normales.
            end
            datos.normales=app.data.normales; % los vectores normales
            datos.pathname=app.data.pathname; % ruta y nombre del fichero
            datos.filename=app.data.filename;
            
            % Finalmente, llamo a la aplicación
            app.DialogApp=gui_coloured_normals_v00(app,datos);  
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            % Edición de la configuración del programa
            
            % Si quisiera deshabilitar la GUI, activo esto
            app.DiscontinuitySetExtractor4UIFigure.Visible="off";

            % Ahora preparo los datos a enviar al editor de polos
            % Lo meto todo en la estructura datos
            % datos.preferences.projection=app.data.preferences.projection; 
            % datos.preferences.normalised_density=app.data.preferences.normalised_density;
            datos.preferences=app.data.preferences;
            
            % Finalmente, llamo a la aplicación
            app.DialogApp=gui_preferences(app,datos);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DiscontinuitySetExtractor4UIFigure = uifigure('Visible', 'off');
            app.DiscontinuitySetExtractor4UIFigure.Name = 'Discontinuity Set Extractor 4.00';
            app.DiscontinuitySetExtractor4UIFigure.Icon = fullfile(pathToMLAPP, 'img', 'DSE_captura_03.png');
            app.DiscontinuitySetExtractor4UIFigure.CloseRequestFcn = createCallbackFcn(app, @DiscontinuitySetExtractor4UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DiscontinuitySetExtractor4UIFigure);
            app.FileMenu.Text = 'File';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Tooltip = {'Clear all data and start a new project'};
            app.ClearMenu.Text = 'Clear';
            app.menu_loadxyz = uimenu(app.FileMenu);
            app.menu_loadxyz.MenuSelectedFcn = createCallbackFcn(app, @menu_load3DPCSelected, true);
            app.menu_loadxyz.Tooltip = {'Load the point cloud in ASCII (.txt) or binary (.ply). Note that .ply file can load normals.'};
            app.menu_loadxyz.Text = 'Load 3DPC';
            app.menu_loadddb = uimenu(app.FileMenu);
            app.menu_loadddb.MenuSelectedFcn = createCallbackFcn(app, @menu_loadddbMenuSelected, true);
            app.menu_loadddb.Tooltip = {'Load a project'};
            app.menu_loadddb.Text = 'Load database.m';
            app.menu_saveddbb = uimenu(app.FileMenu);
            app.menu_saveddbb.MenuSelectedFcn = createCallbackFcn(app, @menu_saveddbbMenuSelected, true);
            app.menu_saveddbb.Tooltip = {'Save the current project'};
            app.menu_saveddbb.Enable = 'off';
            app.menu_saveddbb.Text = 'Save database.m';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.DiscontinuitySetExtractor4UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.NormalspacingMenu = uimenu(app.ToolsMenu);
            app.NormalspacingMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalspacingMenuSelected, true);
            app.NormalspacingMenu.Text = 'Normal spacing';
            app.PersistenceMenu = uimenu(app.ToolsMenu);
            app.PersistenceMenu.MenuSelectedFcn = createCallbackFcn(app, @PersistenceMenuSelected, true);
            app.PersistenceMenu.Text = 'Persistence';
            app.HelpMenu = uimenu(app.DiscontinuitySetExtractor4UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.DiscontinuitySetExtractor4UIFigure);
            app.PushTool_Load3DP = uipushtool(app.Toolbar);
            app.PushTool_Load3DP.Tooltip = {'Load a ''txt'' or ''ply'' 3DPC'};
            app.PushTool_Load3DP.ClickedCallback = createCallbackFcn(app, @PushTool_Load3DPClicked, true);
            app.PushTool_Load3DP.Icon = fullfile(pathToMLAPP, 'img', 'fa-file-o.png');
            app.PushTool_LoadProject = uipushtool(app.Toolbar);
            app.PushTool_LoadProject.Tooltip = {'Load an existing project'};
            app.PushTool_LoadProject.ClickedCallback = createCallbackFcn(app, @PushTool_LoadProjectClicked, true);
            app.PushTool_LoadProject.Icon = fullfile(pathToMLAPP, 'img', 'fa-folder-open-o.png');
            app.PushTool_SaveProject = uipushtool(app.Toolbar);
            app.PushTool_SaveProject.Tooltip = {'Save the current project'};
            app.PushTool_SaveProject.ClickedCallback = createCallbackFcn(app, @PushTool_SaveProjectClicked, true);
            app.PushTool_SaveProject.Icon = fullfile(pathToMLAPP, 'img', 'fa-save.png');
            app.PushTool_Clear = uipushtool(app.Toolbar);
            app.PushTool_Clear.Tooltip = {'Clear memory and GUI'};
            app.PushTool_Clear.ClickedCallback = createCallbackFcn(app, @PushTool_ClearClicked, true);
            app.PushTool_Clear.Icon = fullfile(pathToMLAPP, 'img', 'fa-trash-o.png');
            app.PushTool_Colouring_normals = uipushtool(app.Toolbar);
            app.PushTool_Colouring_normals.Tooltip = {'Assign a colour to each point according to the orientation of the normal. Obtain the rotation that optimises the colours of the normals.'};
            app.PushTool_Colouring_normals.ClickedCallback = createCallbackFcn(app, @PushTool_Colouring_normalsClicked, true);
            app.PushTool_Colouring_normals.Enable = 'off';
            app.PushTool_Colouring_normals.Icon = fullfile(pathToMLAPP, 'imagen_HSV_icono.png');
            app.LogListBox = uilistbox(app.DiscontinuitySetExtractor4UIFigure);
            app.LogListBox.Items = {'Wellcome to Discontinuity Set Extractor 3.03', 'This software is based on the methodology described in the following reference: https://doi.org/10.1016/j.cageo.2014.03.014.', 'Normal spacing follows the approach detailed in this reference: https://doi.org/10.1016/j.enggeo.2015.06.009,', 'and persistence is handled according to this reference: https://doi.org/10.1016/j.ijrmms.2015.12.008.', 'Additional information can be found on this wiki: https://personal.ua.es/en/ariquelme/dse-wiki/dse-wiki.html'};
            app.LogListBox.ValueChangedFcn = createCallbackFcn(app, @LogListBoxValueChanged, true);
            app.LogListBox.Value = 'Wellcome to Discontinuity Set Extractor 3.03';
            app.ExportdataPanel = uipanel(app.DiscontinuitySetExtractor4UIFigure);
            app.ExportdataPanel.Title = 'Export data';
            app.Button_Save_XYZJs = uibutton(app.ExportdataPanel, 'push');
            app.Button_Save_XYZJs.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_XYZJsPushed, true);
            app.Button_Save_XYZJs.Icon = 'imagen_3DPC_clasificada_export.png';
            app.Button_Save_XYZJs.Enable = 'off';
            app.Button_Save_XYZJs.Tooltip = {'Export XYZ-Js (early classification), before the cluster analysis'};
            app.Button_Save_XYZJs.Text = '';
            app.Button_Save_database = uibutton(app.ExportdataPanel, 'push');
            app.Button_Save_database.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_databasePushed, true);
            app.Button_Save_database.Icon = 'imagen_icons8-save-50.png';
            app.Button_Save_database.Enable = 'off';
            app.Button_Save_database.Tooltip = {'Save the the current state of the process. Exports a .m file with all the info.'};
            app.Button_Save_database.Text = '';
            app.Button_Export_Alldata = uibutton(app.ExportdataPanel, 'push');
            app.Button_Export_Alldata.ButtonPushedFcn = createCallbackFcn(app, @Button_Export_AlldataPushed, true);
            app.Button_Export_Alldata.Icon = 'imagen_export.png';
            app.Button_Export_Alldata.Enable = 'off';
            app.Button_Export_Alldata.Tooltip = {'Export all the results in ASCII files to the current folder.'};
            app.Button_Export_Alldata.Text = '';
            app.Displays = uipanel(app.DiscontinuitySetExtractor4UIFigure);
            app.Displays.Title = 'Display';
            app.pushbutton_plot3dpoints = uibutton(app.Displays, 'push');
            app.pushbutton_plot3dpoints.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot3dpointsButtonPushed, true);
            app.pushbutton_plot3dpoints.Icon = fullfile(pathToMLAPP, 'imagen_3DPC_cubo.png');
            app.pushbutton_plot3dpoints.Enable = 'off';
            app.pushbutton_plot3dpoints.Tooltip = {'Plot the 3DPC'};
            app.pushbutton_plot3dpoints.Text = '';
            app.pushbutton_plotstereopoles = uibutton(app.Displays, 'push');
            app.pushbutton_plotstereopoles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotstereopolesButtonPushed, true);
            app.pushbutton_plotstereopoles.Icon = 'imagen_stereonet_poles.png';
            app.pushbutton_plotstereopoles.Enable = 'off';
            app.pushbutton_plotstereopoles.Tooltip = {'Plot all the poles in a stereonet'};
            app.pushbutton_plotstereopoles.Text = '';
            app.pushbutton_plotdensitypoles = uibutton(app.Displays, 'push');
            app.pushbutton_plotdensitypoles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotdensitypolesButtonPushed, true);
            app.pushbutton_plotdensitypoles.Icon = fullfile(pathToMLAPP, 'imagen_stereonet.png');
            app.pushbutton_plotdensitypoles.Enable = 'off';
            app.pushbutton_plotdensitypoles.Tooltip = {'Plot the density of poles'};
            app.pushbutton_plotdensitypoles.Text = '';
            app.pushbutton_plotstereopolesppal = uibutton(app.Displays, 'push');
            app.pushbutton_plotstereopolesppal.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plotstereopolesppalButtonPushed, true);
            app.pushbutton_plotstereopolesppal.Icon = fullfile(pathToMLAPP, 'imagen_stereonet_poles_classified.png');
            app.pushbutton_plotstereopolesppal.Enable = 'off';
            app.pushbutton_plotstereopolesppal.Tooltip = {'Plot the poles. Colours depend on the assigned DS.'};
            app.pushbutton_plotstereopolesppal.Text = '';
            app.pushbutton_plot3dpointsppal = uibutton(app.Displays, 'push');
            app.pushbutton_plot3dpointsppal.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot3dpointsppalButtonPushed, true);
            app.pushbutton_plot3dpointsppal.Icon = fullfile(pathToMLAPP, 'imagen_3DPC_clasificada_cubo.png');
            app.pushbutton_plot3dpointsppal.Enable = 'off';
            app.pushbutton_plot3dpointsppal.Tooltip = {'View the 3DPC classified according the selected DS.'};
            app.pushbutton_plot3dpointsppal.Text = '';
            app.ClusteranalysisPanel = uipanel(app.DiscontinuitySetExtractor4UIFigure);
            app.ClusteranalysisPanel.Title = '[4] Cluster analysis';
            app.DBSCAN = uibutton(app.ClusteranalysisPanel, 'push');
            app.DBSCAN.ButtonPushedFcn = createCallbackFcn(app, @DBSCANPushed, true);
            app.DBSCAN.Icon = 'imagen_DBSCAN.png';
            app.DBSCAN.Enable = 'off';
            app.DBSCAN.Tooltip = {'Find the clusters of each DS using the DBSCAB algorithm. This figure was downloaded from Wikipedia.'};
            app.DBSCAN.Text = '';
            app.FixnormalvectorCheckBox = uicheckbox(app.ClusteranalysisPanel);
            app.FixnormalvectorCheckBox.Enable = 'off';
            app.FixnormalvectorCheckBox.Text = 'Fix normal vector';
            app.FixnormalvectorCheckBox.Value = true;
            app.DBSCAN_edit = uibutton(app.ClusteranalysisPanel, 'push');
            app.DBSCAN_edit.ButtonPushedFcn = createCallbackFcn(app, @DBSCAN_editPushed, true);
            app.DBSCAN_edit.Icon = 'imagen_DBSCAN_clean.png';
            app.DBSCAN_edit.Enable = 'off';
            app.DBSCAN_edit.Tooltip = {'Edit the clusters, removing those clusters with less than a certain number of points.'};
            app.DBSCAN_edit.Text = '';
            app.Clusters_merge = uibutton(app.ClusteranalysisPanel, 'push');
            app.Clusters_merge.ButtonPushedFcn = createCallbackFcn(app, @Clusters_mergePushed, true);
            app.Clusters_merge.Icon = 'imagen_merge_clusters.png';
            app.Clusters_merge.Enable = 'off';
            app.Clusters_merge.Tooltip = {'Merge the coplanar clusters according to a coplanarity test. The test is managed by the parameter k. Values of 2 are normal.'};
            app.Clusters_merge.Text = '';
            app.Clusters_restore = uibutton(app.ClusteranalysisPanel, 'push');
            app.Clusters_restore.ButtonPushedFcn = createCallbackFcn(app, @Clusters_restoreButtonPushed, true);
            app.Clusters_restore.Icon = 'imagen_DBSCAN_restore.png';
            app.Clusters_restore.Enable = 'off';
            app.Clusters_restore.Tooltip = {'Restore the clusters to the initial situation.'};
            app.Clusters_restore.Text = '';
            app.ksigmasEditFieldLabel = uilabel(app.ClusteranalysisPanel);
            app.ksigmasEditFieldLabel.Enable = 'off';
            app.ksigmasEditFieldLabel.Text = 'k sigmas';
            app.ksigmasEditField = uieditfield(app.ClusteranalysisPanel, 'numeric');
            app.ksigmasEditField.Enable = 'off';
            app.ksigmasEditField.Value = 2;
            app.UITable_clusters = uitable(app.ClusteranalysisPanel);
            app.UITable_clusters.ColumnName = {'DS'; 'cl'; 'n'; 'A'; 'B'; 'C'; 'D_'; 'sigma'};
            app.UITable_clusters.RowName = {};
            app.UITable_clusters.ColumnSortable = [true true true false false false true false];
            app.UITable_clusters.Tooltip = {'Discontinuity sets and clusters of the classified point cloud. ABCD are the componentes of the plane equation: Ax+By+Cz+D=0. The standard deviation is the distance points of a cluster - best fit plane of that cluster. D_=D-mean(D).'};
            app.UITable_clusters.Enable = 'off';
            app.ClassifypointsassignedtoeachDiscontinuitySetPanel = uipanel(app.DiscontinuitySetExtractor4UIFigure);
            app.ClassifypointsassignedtoeachDiscontinuitySetPanel.Title = '[3] Classify points assigned to each Discontinuity Set';
            app.Button_assign_ppalpoles = uibutton(app.ClassifypointsassignedtoeachDiscontinuitySetPanel, 'push');
            app.Button_assign_ppalpoles.ButtonPushedFcn = createCallbackFcn(app, @Button_assign_ppalpolesPushed, true);
            app.Button_assign_ppalpoles.Icon = 'imagen_cono.png';
            app.Button_assign_ppalpoles.Enable = 'off';
            app.Button_assign_ppalpoles.Tooltip = {'Assign a DS to a point if its normal vector is within the cone. Note that the axis of the cone corresponds with the normal vector of the DS.'};
            app.Button_assign_ppalpoles.Text = '';
            app.ConeLabel = uilabel(app.ClassifypointsassignedtoeachDiscontinuitySetPanel);
            app.ConeLabel.Enable = 'off';
            app.ConeLabel.Text = 'Cone [º]';
            app.ConeEditField = uieditfield(app.ClassifypointsassignedtoeachDiscontinuitySetPanel, 'numeric');
            app.ConeEditField.Enable = 'off';
            app.ConeEditField.Tooltip = {'Maximum angle between the normal vector associated with a point and that of the family (DS), in order to assign that family to the point.'};
            app.ConeEditField.Value = 30;
            app.STEP2DensityandprincipalpolesextractionPanel = uipanel(app.DiscontinuitySetExtractor4UIFigure);
            app.STEP2DensityandprincipalpolesextractionPanel.Title = '[2] Calcutale Density of Nv and extract Discontinuity Sets (DS)';
            app.pushbutton_3statanalysis = uibutton(app.STEP2DensityandprincipalpolesextractionPanel, 'push');
            app.pushbutton_3statanalysis.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_3statanalysisButtonPushed, true);
            app.pushbutton_3statanalysis.Icon = 'imagen_stereonet.png';
            app.pushbutton_3statanalysis.Enable = 'off';
            app.pushbutton_3statanalysis.Tooltip = {'Calculate the probability density function of the normals'' poles.'};
            app.pushbutton_3statanalysis.Text = '';
            app.BinslevelSpinnerLabel = uilabel(app.STEP2DensityandprincipalpolesextractionPanel);
            app.BinslevelSpinnerLabel.Enable = 'off';
            app.BinslevelSpinnerLabel.Text = 'Bins level';
            app.BinslevelSpinner = uispinner(app.STEP2DensityandprincipalpolesextractionPanel);
            app.BinslevelSpinner.ValueChangingFcn = createCallbackFcn(app, @BinslevelSpinnerValueChanging, true);
            app.BinslevelSpinner.Limits = [1 10];
            app.BinslevelSpinner.ValueChangedFcn = createCallbackFcn(app, @BinslevelSpinnerValueChanged, true);
            app.BinslevelSpinner.Enable = 'off';
            app.BinslevelSpinner.Tooltip = {'Level of the Bins. The number of bins is 2^(level). Recommended values are 6 or 7.'};
            app.BinslevelSpinner.Value = 6;
            app.MinangleLabel = uilabel(app.STEP2DensityandprincipalpolesextractionPanel);
            app.MinangleLabel.Enable = 'off';
            app.MinangleLabel.Text = 'Min angle [º]';
            app.MinangleEditField = uieditfield(app.STEP2DensityandprincipalpolesextractionPanel, 'numeric');
            app.MinangleEditField.Enable = 'off';
            app.MinangleEditField.Tooltip = {'Minimum angle between normals of the principal discontinuity sets.'};
            app.MinangleEditField.Value = 30;
            app.pushbutton_31editppalpoles = uibutton(app.STEP2DensityandprincipalpolesextractionPanel, 'push');
            app.pushbutton_31editppalpoles.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_31editppalpolesButtonPushed, true);
            app.pushbutton_31editppalpoles.Icon = 'imagen_stereonet_edit.png';
            app.pushbutton_31editppalpoles.Enable = 'off';
            app.pushbutton_31editppalpoles.Tooltip = {'Edit the principal poles'};
            app.pushbutton_31editppalpoles.Text = '';
            app.NmaxDSEditFieldLabel = uilabel(app.STEP2DensityandprincipalpolesextractionPanel);
            app.NmaxDSEditFieldLabel.Enable = 'off';
            app.NmaxDSEditFieldLabel.Text = 'Nmax DS';
            app.NmaxDSEditField = uieditfield(app.STEP2DensityandprincipalpolesextractionPanel, 'numeric');
            app.NmaxDSEditField.Enable = 'off';
            app.NmaxDSEditField.Tooltip = {'Maximun number of principal planes to extract from the density function'};
            app.NmaxDSEditField.Value = 10;
            app.UITable_principalplanes = uitable(app.STEP2DensityandprincipalpolesextractionPanel);
            app.UITable_principalplanes.ColumnName = {'Dip Dir [º]'; 'Dip [º]'; 'Density'; '%'};
            app.UITable_principalplanes.Enable = 'off';
            app.LogListBoxLabel = uilabel(app.DiscontinuitySetExtractor4UIFigure);
            app.LogListBoxLabel.Text = 'Log';
            app.Normals_Panel = uipanel(app.DiscontinuitySetExtractor4UIFigure);
            app.Normals_Panel.Tooltip = {'Calculate the normals for every point using the knn nearest neighbours and perform the coplanarity test.'};
            app.Normals_Panel.Title = '[1] Calculate normal vectors and poles';
            app.pushbutton_2setupplanes = uibutton(app.Normals_Panel, 'push');
            app.pushbutton_2setupplanes.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_2setupplanesButtonPushed, true);
            app.pushbutton_2setupplanes.Icon = 'imagen_vnormal.png';
            app.pushbutton_2setupplanes.Enable = 'off';
            app.pushbutton_2setupplanes.Tooltip = {'Calculate normals with knn neighbours and the coplanarity test.'};
            app.pushbutton_2setupplanes.Text = '';
            app.knnEditFieldLabel = uilabel(app.Normals_Panel);
            app.knnEditFieldLabel.Enable = 'off';
            app.knnEditFieldLabel.Text = 'knn';
            app.box_nneighbours = uieditfield(app.Normals_Panel, 'text');
            app.box_nneighbours.Enable = 'off';
            app.box_nneighbours.Value = '30';
            app.CoplanaritytestSwitch = uiswitch(app.Normals_Panel, 'rocker');
            app.CoplanaritytestSwitch.ValueChangedFcn = createCallbackFcn(app, @CoplanaritytestSwitchValueChanged, true);
            app.CoplanaritytestSwitch.Enable = 'off';
            app.CoplanaritytestSwitch.Tooltip = {'Switch on to perform the coplanarity test when the normals are calculated. When switched off, if the Computer Vision Toolbox is installed, the calculation is faster.'};
            app.toleranceEditFieldLabel = uilabel(app.Normals_Panel);
            app.toleranceEditFieldLabel.Enable = 'off';
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.box_tolerancia = uieditfield(app.Normals_Panel, 'text');
            app.box_tolerancia.Enable = 'off';
            app.box_tolerancia.Value = '0.2';
            app.CoplanaritytestSwitchLabel = uilabel(app.Normals_Panel);
            app.CoplanaritytestSwitchLabel.Enable = 'off';
            app.CoplanaritytestSwitchLabel.Text = {'Coplanarity'; 'test'};
            % Show the figure after all components are created
            app.DiscontinuitySetExtractor4UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSE_v400

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiscontinuitySetExtractor4UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiscontinuitySetExtractor4UIFigure)
        end
    end
end