classdef gui_coloured_normals_v00 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSEmoduleforoptimisingaxisrotationUIFigure  matlab.ui.Figure
        ProcesaelBatchButton            matlab.ui.control.Button
        DisplayPanel                    matlab.ui.container.Panel
        NmaxDSEditField                 matlab.ui.control.NumericEditField
        NmaxDSEditField_2Label          matlab.ui.control.Label
        MinangleEditField               matlab.ui.control.NumericEditField
        MinangleLabel                   matlab.ui.control.Label
        BinsSpinner                     matlab.ui.control.Spinner
        BinsSpinnerLabel                matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        DisplaySettingsPanel            matlab.ui.container.Panel
        NormalisecontourCheckBox        matlab.ui.control.CheckBox
        ProjectionDropDown              matlab.ui.control.DropDown
        ProjectionDropDownLabel         matlab.ui.control.Label
        DPCoriginalCheckBox             matlab.ui.control.CheckBox
        DPCcolouredCheckBox             matlab.ui.control.CheckBox
        RotatethecloudCheckBox          matlab.ui.control.CheckBox
        SaveGraphsCheckBox              matlab.ui.control.CheckBox
        ShowStereonetCheckBox           matlab.ui.control.CheckBox
        ShowContoursCheckBox            matlab.ui.control.CheckBox
        ShowPolesCheckBox               matlab.ui.control.CheckBox
        ShowPpalPolesCheckBox           matlab.ui.control.CheckBox
        KeepPpalPolesCheckBox           matlab.ui.control.CheckBox
        DisplayDropDown                 matlab.ui.control.DropDown
        ColormapDropDown                matlab.ui.control.DropDown
        ColourmapValueSliderLabel       matlab.ui.control.Label
        ColourmapValueSlider            matlab.ui.control.Slider
        DisplayDropDownLabel            matlab.ui.control.Label
        BackgroundDropDown              matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        ColormapDropDownLabel           matlab.ui.control.Label
        OptimisethenormalscoloursPanel  matlab.ui.container.Panel
        InitialsolutionDropDown         matlab.ui.control.DropDown
        AngulosparaR_UITable            matlab.ui.control.Table
        DOGDropDown                     matlab.ui.control.DropDown
        SubsampleEditField              matlab.ui.control.NumericEditField
        ObjFDropDown                    matlab.ui.control.DropDown
        MaxStallIterationsEditField     matlab.ui.control.NumericEditField
        MaxStallIterationsEditFieldLabel  matlab.ui.control.Label
        MaxtimesEditField               matlab.ui.control.NumericEditField
        MaxtimesEditFieldLabel          matlab.ui.control.Label
        ObjFDropDownLabel               matlab.ui.control.Label
        InitialsolutionDropDownLabel    matlab.ui.control.Label
        DOGDropDownLabel                matlab.ui.control.Label
        SubsampleLabel                  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        LogListBox                      matlab.ui.control.ListBox
        LogListBoxLabel                 matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Es la función que llama a esta ventana, me permite identificarla cuando al final llame al updatefunction
        data % donde voy a guardar todos mis datos durante este programa
        % Los datos que recibo son:
        % datos.polos_cartesianas_proyectadas; Son las coordenadas de los polos
        % en cartesianas, que son las normales de cada punto
        LastPath = ''; % Propiedad para almacenar la última ruta utilizada
    end
    
    methods (Access = private)


        function [polos_cartesianas_proyectadas_rotadas, polos_cartesianas_proyectadas_solocolor_optimizado, XYZ_RGB_NxNyNz_coloreado_optimizado, polos_pples_cart,X,Y,density] = Colorea_nuberotada(app,R, nsec, angulovpples, maxpples)
            % Vamos a rotar los polos con R y colorear la nube
            % El output es: 
            % - polos_cartesianas_proyectadas_rotadas nx2
            % - polos_cartesianas_proyectadas_solocolor_optimizado; es el nx1, en base 255
            % - XYZ_RGB_NxNyNz_coloreado_optimizado % Esta no está rotada, pero su color sí nx9
            % - polos_pples_cart; % para la densidad, están rotados
            % - X; % todo rotado
            % - Y;
            % - density;
            normaliza=app.NormalisecontourCheckBox.Value;
            proyeccion_estereograma=app.ProjectionDropDown.Value;
            % Calculo la matriz de los ángulos dipdir y dip de los polos, con la proyección seleccionada
            % Tomo la matriz de los polos
            [polos_cartesianas_proyectadas]=f_calcula_coordenadas_polosvn(app.data.planos_Clar,app.ProjectionDropDown.Value);
            % polos_x=app.data.polos_cartesianas_proyectadas(:,1);
            % polos_y=app.data.polos_cartesianas_proyectadas(:,2);
            % [ dipdir, dip ] = f_cart2clar_v02(polos_x, polos_y,proyeccion_estereograma);
            % app.data.polos_estereo_clar=zeros(size(app.data.polos_cartesianas_proyectadas));
            % app.data.polos_estereo_clar(:,1)=dipdir/180*pi;0
            % app.data.polos_estereo_clar(:,2)=dip/180*pi;
            app.data.polos_cartesianas_proyectadas=polos_cartesianas_proyectadas;
            % app.data.polos_estereo_clar=app.data.planos_Clar;
            
            % Calculo los vectores normales, para la optimización
            vectores_normales=app.data.normales;
            % app.data.polos_estereo_normales=app.data.normales;
            normales_rotadas=(R*vectores_normales')'; % Calculo los vectores rotados
            % Corrijo hemisferio sur
            I_hemisferio_inferior=find(normales_rotadas(:,3)<0);
            normales_rotadas(I_hemisferio_inferior,:)=-normales_rotadas(I_hemisferio_inferior,:);
            [dipdir,dip]=f_vnorm2clar(normales_rotadas); % Convierto las nuevas normales a Clar
            % % Antes de seguir, los polos rotados los convierto a
            % % cartesianas y los guardo para represerntar después el
            % % estereograma coloreado
            [ polos_rotados_cartesianas_x,polos_rotados_cartesianas_y ] = f_clar2cart( dipdir,dip,proyeccion_estereograma );
            polos_cartesianas_proyectadas_rotadas=[polos_rotados_cartesianas_x polos_rotados_cartesianas_y];
            
            switch app.ColormapDropDown.Value
                case 'none'
                    % No coloreo los polos, pero al rgb le doy un color
                    % verde para que lo pueda dibujar
                    % Realmente, esto está hecho para dibujar los polos sin color
                    [n,~]=size(dip);
                    rgb=zeros(n,3);
                    rgb(:,1)=0;
                    rgb(:,2)=1;
                    rgb(:,3)=0;
                case 'HSV'
                    % El Value en HSV se lo paso en tanto por 1, pero el
                    % slider me lo da en base 100
                    valor=app.ColourmapValueSlider.Value/100;
                    [rgb] = f_polosxy2hsv2rgb(polos_rotados_cartesianas_x,polos_rotados_cartesianas_y,valor,proyeccion_estereograma);
                case 'CIELAB'
                    % La luminancia está en base 100, como el slider
                    L=app.ColourmapValueSlider.Value;
                    [rgb] = f_polosxy2cielab2rgb(polos_rotados_cartesianas_x,polos_rotados_cartesianas_y,L);
                case 'CIELCH'
                    % Igual pero en cilíndricas
                    L=app.ColourmapValueSlider.Value;
                    [rgb] = f_polosxy2cielch2rgb(polos_rotados_cartesianas_x,polos_rotados_cartesianas_y,L);                   
                case 'CIELCHmod'
                    % Igual pero en cilíndricas
                    L=app.ColourmapValueSlider.Value;
                    [rgb] = f_polosxy2cielch2rgb_mod01(polos_rotados_cartesianas_x,polos_rotados_cartesianas_y,L);    
            end
            polos_cartesianas_proyectadas_solocolor_optimizado=rgb;
            
            % Exportamos los puntos para el ptCloud y dibujar el 3D
            XYZ_RGB_NxNyNz_coloreado_optimizado=zeros(length(dip),9);
            XYZ_RGB_NxNyNz_coloreado_optimizado(:,1:3)=app.data.P(:,1:3); % las coordenadas
            XYZ_RGB_NxNyNz_coloreado_optimizado(:,7:9)=app.data.normales; % las normales sin rotar
            % XYZ_RGB_NxNyNz_coloreado_optimizado(:,4:6)=floor(rgb(:,1:3)); % los colores, que los paso a RGB256
            XYZ_RGB_NxNyNz_coloreado_optimizado(:,4:6)=rgb(:,1:3); % los colores, que los paso a RGB256
            
            % Ya tengo los colores, y quiero calcular la nueva función de densidad con esta nueva distribución de polos
            % Preparo la función de densidad con el kde      

            [~, polos_pples_cart, density, X, Y]=f_prepara_planos_kde_v02(polos_cartesianas_proyectadas_rotadas, nsec, angulovpples, maxpples, normaliza, proyeccion_estereograma);
            
        end
        
        
        function results = Show_coloured(app,angulos,XYZ_RGB_NxNyNz_coloreado_optimizado, polos_cartesianas_proyectadas_rotadas, polos_cartesianas_proyectadas_solocolor_optimizado, polos_pples_cart, X, Y, density)
            results=0; % Para que me deje tranquilo
            % Representa la 3DPC original, la coloreada y el estereograma
            % con la densidad, según se haya seleccionado.
            % El mapa de color empleado:
            mapadecolor=app.ColormapDropDown.Value;
            proyeccion=app.ProjectionDropDown.Value; % Proyección empleada

            % 3DPC sin colorear
            if app.DPCoriginalCheckBox.Value==1
                % Tengo el Toolbox para nubes de puntos y puedo usarlo
                % Compruebo si tengo la nube del ply y genero la figura
                if isfield(app.data,'ptCloud')
                    % como tengo los datos en ptcloud, los voy a rotar
                    R=eye(3);
                    puntos_rotados=(R*app.data.ptCloud.Location')';
                    ptCloudr=pointCloud(puntos_rotados);
                    % Compruebo si tiene colores y normales
                    if isfield(app.data.ptCloud,'Color')
                        ptCloudr.Color=app.data.ptCloud.Color;
                    end
                    if isfield(app.data.ptCloud,'Normal')
                        % Hay normales, deberia de rotarlas
                        ptCloudr.Normal=(R*app.data.ptCloud.Normal')';
                    end
                    if isfield(app.data.ptCloud,'Intensity')
                        ptCloudr.Intensity=app.data.ptCloud.Intensity;
                    end
                    h_3D=figure;
                    pcshow(ptCloudr);
                    h_3D.Name='3D Point Cloud from .ply';
                else
                    % No tengo el ptCloud
                    % Tomo los puntos y les aplico la matriz de rotación
                    P=(R*P')';
                    ptCloud=pointCloud(P(:,1:3));
                    h_3D=figure;
                    pcshow(ptCloud);
                    h_3D.Name='3D Point Cloud from .txt';
                end
                switch app.BackgroundDropDown.Value
                    case 'White'
                        % Preparo la representación con fondo blanco
                        h_3D.Color=[ 1 1 1]; % pongo el fondo blanco
                        h_3D.CurrentAxes.Color=[1 1 1]; % el fondo de los axes tb en blanco
                        % Pongo los ejes en negro
                        h_3D.CurrentAxes.GridColor=[0 0 0]; % los ejes en negro
                        h_3D.CurrentAxes.XColor=[0 0 0];
                        h_3D.CurrentAxes.YColor=[0 0 0];
                        h_3D.CurrentAxes.ZColor=[0 0 0];
                    case 'Black'
                        % No hago nada y lo dejo por defecto
                end
                % Le aplico la configuración que le aplicaría en cualquier
                % caso
                h_3D.CurrentAxes.Projection='orthographic';
                h_3D.Name='3D point cloud, original';
            end
            % veo si tengo que rotar la nube para verla
            rotar_nube=app.RotatethecloudCheckBox.Value;
            if rotar_nube==1
                % Se rota la nube. Empleo R con los ángulos que hay
                optimizado_original='Optimised';
                R = f_calcula_matriz_rotacion(angulos);
            else
                % No se rota la nube
                % Calculo R por si hay que rotar los polos
                % R_optimizada = f_calcula_matriz_rotacion(app,angulos);
                R=eye(3);
                optimizado_original='Original';
            end     

            % 3DPC con los colores optimizados
            if app.DPCcolouredCheckBox.Value==1
                % Represento la nube en el HSV
                % Fíjate que si no hay que rotar, R es 1
                ptCloud=pointCloud((R*XYZ_RGB_NxNyNz_coloreado_optimizado(:,1:3)')');
                ptCloud.Color=uint8(XYZ_RGB_NxNyNz_coloreado_optimizado(:,4:6)*255);
                % ptCloud.Color=XYZ_RGB_NxNyNz_coloreado_optimizado(:,4:6));
                ptCloud.Normal=XYZ_RGB_NxNyNz_coloreado_optimizado(:,7:9);
                h_3D=figure; % Creo que esto no hace falta, revisarlo
                pcshow(ptCloud); 
                % METE EL V O L EN EL TÍTULO, TB PARA EL 3D
                switch app.ColormapDropDown.Value
                    case 'HSV'
                        h_3D.Name=['3DPC ',optimizado_original, '; ',mapadecolor,'; V=',num2str(app.ColourmapValueSlider.Value)];
                    case {'CIELAB', 'CIELCH', 'CIELCHmod'}
                        h_3D.Name=['3DPC ',optimizado_original, '; ',mapadecolor,'; L=',num2str(app.ColourmapValueSlider.Value)];
                end
                % Configuro el fondo
                switch app.BackgroundDropDown.Value
                    case 'White'
                        % Ahora lo pongo en color blanco
                        h_3D.Color=[ 1 1 1]; % pongo el fondo blanco
                        h_3D.CurrentAxes.Color=[1 1 1]; % el fondo de los axes tb en blanco
                        % Pongo los ejes en negro
                        h_3D.CurrentAxes.GridColor=[0 0 0]; % los ejes en negro
                        h_3D.CurrentAxes.XColor=[0 0 0];
                        h_3D.CurrentAxes.YColor=[0 0 0];
                        h_3D.CurrentAxes.ZColor=[0 0 0];
                    case 'Black'
                        % No hago nada y lo dejo por defecto
                end
                % ya pongo la configuración que le pongo a todas
                h_3D.CurrentAxes.Projection='orthographic';
            end
            % Guardo la 3DPC
            if app.DPCoriginalCheckBox.Value || app.DPCcolouredCheckBox.Value
                % He dibujado alguna nube, luego la guardo
                switch app.SaveGraphsCheckBox.Value
                    case 1
                        % Define el directorio de salida
                        output_dir = fullfile(app.data.pathname, 'Output_plots');

                        % Comprueba si el directorio existe, si no, lo crea
                        if ~exist(output_dir, 'dir')
                            mkdir(output_dir);
                        end

                        % Guardo el fichero de la nube
                        switch app.data.paso
                            case 0
                                fichero = fullfile(output_dir, [app.data.filename, ' XYZ-', mapadecolor, '-normals-', optimizado_original, '.ply']);
                                fichero_fig = fullfile(output_dir, [app.data.filename, ' XYZ-', mapadecolor, '-normals-', optimizado_original, '.fig']);
                            otherwise
                                fichero = fullfile(output_dir, [app.data.filename, ' - ', num2str(app.data.paso), ' XYZ-', mapadecolor, '-normals-', optimizado_original, '.ply']);
                                fichero_fig = fullfile(output_dir, [app.data.filename, ' - ', num2str(app.data.paso), ' XYZ-', mapadecolor, '-normals-', optimizado_original, '.fig']);
                        end

                        % Guarda los archivos en el directorio "Output_plots"
                        pcwrite(ptCloud, fichero, 'PLYFormat', 'binary');
                        savefig(h_3D, fichero_fig);
                        % Aviso de que he guardado datos
                        DateString=char(string(datetime));
                        S0 = [DateString, '- I saved the .ply and the .fig plots'];
                        S1 = [DateString, ' - ', fichero];
                        S2 = [DateString, ' - ', fichero_fig];
                        % registro el tiempo que hemos empleado
                        a=app.LogListBox.Items;
                        S=[a S0 S1 S2];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    otherwise
                        % No guardo nada
                end

            else
                %No he dibujado nube, y no la guardo
            end

            % ESTEREOGRAMA con polos y/o contour plot
            % Pillo si tengo que dibujar el original o rotado (optimizado)
            switch app.DisplayDropDown.Value
                case 'Original data'
                    optimizado_original='Original';
                case 'Optimised data'
                    optimizado_original='Optimised';
                otherwise
                    % Algo va mal
            end
            Titulo_indice=1;
            Titulo{Titulo_indice}=[proyeccion,' projection of ',optimizado_original,' data'];
            % ESTEREOGRAMA con colores o no
            if app.ShowStereonetCheckBox.Value==1
                % Dibujo la falsilla para comparar
                h_stereonet=figure;
                switch app.ProjectionDropDown.Value
                    case 'Equal-angle'
                        falsilla_riquelme_Equalangle_v02;
                    case 'Equal-area'
                        falsilla_riquelme_Equalarea_v02
                    otherwise
                        % algo ha pasado
                end
                
                % METE EL V O L EN EL TÍTULO, TB PARA EL 3D
                switch app.ColormapDropDown.Value
                    case 'HSV'
                        h_stereonet.Name=[app.data.preferences.Projection, ' contours of poles concentration, ',optimizado_original,', ',mapadecolor,'; V=',num2str(app.ColourmapValueSlider.Value)];
                        % Titulo_indice=Titulo_indice+1;
                        % Titulo{Titulo_indice}=[Titulo{Titulo_indice}, '; Colourmap: ', mapadecolor,'; V=',num2str(app.ColourmapValueSlider.Value)];
                    case {'CIELAB', 'CIELCH', 'CIELCHmod'}
                        h_stereonet.Name=[app.data.preferences.Projection, ' contours of poles concentration, ',optimizado_original,', ',mapadecolor,'; L=',num2str(app.ColourmapValueSlider.Value)];
                        % Titulo_indice=Titulo_indice+1;
                        % Titulo{Titulo_indice}=[Titulo{Titulo_indice}, '; Colourmap: ', mapadecolor,'; L=',num2str(app.ColourmapValueSlider.Value)];
                end
                switch app.BackgroundDropDown.Value
                    case 'White'
                        h_stereonet.Color=[1 1 1];
                    case 'Black'
                        % no hago nada
                end
                axis('square','equal');
                % Dibujo los POLOS
                % Titulo_indice=Titulo_indice+1; % Nueva línea para info de la figura
                % Para dibujar los polos, debo de comprobar si el checkbox
                % está seleccionado
                switch app.ShowPolesCheckBox.Value
                    case 1
                        % Ahora dibujo los polos, con sus colores
                        % Las coordenadas ya vienen rotadas, no tengo que hacerle
                        % nada
                        polos_x=polos_cartesianas_proyectadas_rotadas(:,1);
                        polos_y=polos_cartesianas_proyectadas_rotadas(:,2);
                        polos_z=zeros(size(polos_x));
                        polos_z(:)=-0.005;
                        % tamanyo_punto=1; % Antes lo tenía en 10
                        % C=polos_cartesianas_proyectadas_solocolor_optimizado/255;
                        switch app.ColormapDropDown.Value
                            case 'none'
                                % No doy color a los polos
                                tamanyo_punto=1;
                                scatter3(polos_x,polos_y,polos_z,tamanyo_punto);
                                % Titulo{Titulo_indice}=['Poles of normals - '];
                            otherwise
                                % dibujo con el color correspondiente de
                                % los polos
                                tamanyo_punto=3;
                                scatter3(polos_x,polos_y,polos_z,tamanyo_punto,polos_cartesianas_proyectadas_solocolor_optimizado,'filled');
                        end
                    otherwise
                        % Algo ha fallado
                end
                % Dibujo las curvas de nivel de la densidad, si se ha
                % pedido
                switch app.ShowContoursCheckBox.Value
                    case 1
                        % Tengo que dibujar las curvas (contours)
                        numberisolines=80; % número de isolíneas
                        contour3(X,Y,density,numberisolines); % dibuja con contornos
                        axis ([-1 1 -1 1]); axis('square','equal');
                        % % Añado línea de título
                        % if length(Titulo)==1
                        %     Titulo{Titulo_indice}=['Contours of normal poles'];
                        % else
                        %     Titulo{Titulo_indice}=[Titulo{Titulo_indice},' - Contours of normal poles'];
                        % end
                        % Ahora, dibujo los polos principales, si se pide
                        if app.ShowPpalPolesCheckBox.Value==1
                            % Tengo que dibujar los polos principales.
                            % Comprubebo si tengo que calcularlos de nuevo o
                            % mantengo los originales
                            switch app.KeepPpalPolesCheckBox.Value
                                case 'on'
                                    % Mantengo los originales
                                    polos_pples_cart=app.data.polos_pples_cart;
                                case 'off'
                                    % Dejo los nuevos calculados, así que no hago
                                    % nada porque la variable que tengo en memoria
                                    % es la que se acaba de calcular tras rotar los
                                    % polos
                                otherwise
                                    % Algo ha fallado
                            end
                            [kk, nc]=size(polos_pples_cart);
                            if nc==2
                                tamanyo=1;
                            else
                                tamanyo = 20;
                            end
                            % Defino el desfase del label
                            desfasemos_x=0.05;
                            desfasemos_y=0.05;
                            desfasemos_z=0.05;
                            xa=polos_pples_cart(1:kk,1)'+desfasemos_x;
                            xb=polos_pples_cart(1:kk,2)'+desfasemos_y;
                            if nc==2
                                xc=0;
                            else
                                xc=polos_pples_cart(1:kk,3)'+desfasemos_z;
                            end
                            g=gca;
                            for ii=1:kk
                                text(g,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18, 'EdgeColor','k','BackgroundColor','w');
                            end
                            % markerfacecolor [0 .75 .75] % azul bonito
                            % [0.6350 0.0780 0.1840] rojo carmesí
                            scatter3(polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3), tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0.6350 0.0780 0.1840]);
                            % g.Title.String={['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'],''}; % Le añado una línea
                        else
                            % No está seleccionado, no hago nada
                        end
                    case 0
                        % No tengo que dibujar las curvas, ya he terminado
                    otherwise
                        % Algo ha fallado
                end

                % A la figura del estereograma, le pongo un título
                % Puedo haber dibujado polos y/o estereograma. El título lo
                % he ido generado antes añadiendo info poco a poco. Lo meto
                % ahora:
                g=gca;

                Titulo{1}=[proyeccion,' projection of ',optimizado_original,' data'];
                Titulo{2}='';
                if app.ShowPolesCheckBox.Value==1
                    % Tengo polos
                    switch app.ColormapDropDown.Value
                        case 'HSV'
                            Titulo{2}=[Titulo{2}, 'Poles of normals colourmap: ', mapadecolor,'; V=',num2str(app.ColourmapValueSlider.Value),'.'];
                        case {'CIELAB', 'CIELCH', 'CIELCHmod'}
                            Titulo{2}=[Titulo{2}, 'Poles of normals colourmap: ', mapadecolor,'; L=',num2str(app.ColourmapValueSlider.Value),'.'];
                        otherwise
                            Titulo{2}=[Titulo{2}, 'Poles of normals.'];
                    end
                end
                if app.ShowContoursCheckBox.Value==1
                    % Tengo contour
                    Titulo{2}=[Titulo{2}, ' Contours of normals.'];
                end
                Titulo{3}='';
                g.Title.String=Titulo;

                % Si se pide, guardo los gráficos generados
                switch app.SaveGraphsCheckBox.Value
                    case 1
                        % Define el directorio de salida
                        output_dir = fullfile(app.data.pathname, 'Output_plots_stereonets');

                        % Comprueba si el directorio existe, si no, lo crea
                        if ~exist(output_dir, 'dir')
                            mkdir(output_dir);
                        end

                        % Nombre del fichero
                        switch app.data.paso
                            case 0
                                nombrefichero = fullfile(output_dir, [app.data.filename, ' - Density_', optimizado_original, '_', mapadecolor]);
                            otherwise
                                nombrefichero = fullfile(output_dir, [app.data.filename, ' - ', num2str(app.data.paso), ' - Density_', optimizado_original, '_', mapadecolor]);
                        end

                        % Guarda las imágenes en PNG y PDF
                        saveas(gcf, [nombrefichero, '.png']);
                        saveas(gcf, [nombrefichero, '.pdf']);
                        % Aviso de que he guardado datos
                        DateString=char(string(datetime));
                        S0 = [DateString, '- I saved the .png and the .pdf plots'];
                        S1 = [DateString, ' - ', nombrefichero];
                        % registro el tiempo que hemos empleado
                        a=app.LogListBox.Items;
                        S=[a S0 S1];
                        app.LogListBox.Items=S;
                        % fijo la vista del listbox en la última entrada
                        scroll(app.LogListBox, 'bottom')
                        app.data.log = S;
                    otherwise
                        % No guardo nada
                end

                % preparo el puntero
                % Tengo que ver si represento el original
                switch app.DisplayDropDown.Value
                    case 'Original data'
                        angulos=zeros(size(angulos));
                    case 'Optimised data'
                        % Dejo los ángulos como están
                    otherwise
                        % Algo sale mal
                end
                dcm = datacursormode(gcf);
                datacursormode on;
                
                set(dcm,'updatefcn',{@myupdatefcn_rotate,angulos,proyeccion} )
            end
        end
        
        function resultado = optimiza_normales(app)
            % Quiero optimizar los colores de las normales, alejando todo
            % lo posible los polos del contorno del estereograma
            % Los datos que necesito para optimizar son:
            % app.data.polos_cartesianas_proyectadas
            % Resultados que genera
            % app.data.matriz_rotacion % Es la matriz de rotación que se
            % aplica para optimizar la respresentación de colores con
            % normales
            % -- restos de comentarios
            % app.data.polos_cartesianas_proyectadas_HSV_previo
            % app.data.XYZ_RGB_HSV_previo
            
            normaliza=app.NormalisecontourCheckBox.Value;
            proyeccion_estereograma=app.ProjectionDropDown.Value;
            % Inicio el contador de tiempo, para mostrar los segundos que
            % tarda el optimizador
            tic;
            % Preparo el registro del log, que voy a utilizar todo el rato
            a = app.LogListBox.Items;
            % Veo si empleo dos ángulos o los ángulos de Euler
            tecnica_angulos=app.DOGDropDown.Value;
            switch tecnica_angulos
                case "Euler's angles" % Tengo tres grados de libertad
                    gradosdelibertad=3;
                    % Guardo en el log lo que acaba de pasar
                    DateString=char(string(datetime));
                    S2 = [DateString, ' - Optimising three degrees of freedom (Eulers'' angles)'];
                    a=[a S2];
                    % Le genero soluciones iniciales, como semillas para el algoritmo 
                    switch app.InitialsolutionDropDown.Value
                        case 'None'
                            x0=[0 0 0];
                            % Guardo en el log lo que acaba de pasar
                            DateString=char(string(datetime));
                            S2 = [DateString, ' - The initial solution is [0 0 0]'];
                            a=[a S2];
                        case 'Bisectors'
                            % Le genero soluciones iniciales, como semillas para el algoritmo
                            % Le pongo todos giros a 45 y 90 grados
                            x0=[0 0 0;
                                45 45 0;
                                90 45 0;
                                135 45 0;
                                180 45 0;
                                225 45 0;
                                270 45 0;
                                315 45 0;
                                0 90 0;
                                45 90 0;
                                90 90 0;
                                135 90 0;
                                180 90 0;
                                225 90 0;
                                270 90 0;
                                315 90 0];
                            % Guardo en el log lo que acaba de pasar
                            DateString=char(string(datetime));
                            S2 = [DateString, ' - The initial solutions are all bisectors of the upper hemisphere'];
                            a=[a S2];
                        case 'PCA'
                            % Para calcular la rotación del PCA en la solución inicial, necesito la nube. Para ello, tengo que ver si tengo el ply o no
                            if isfield(app.data,'ptCloud')
                                % Tengo el .ply, tomo los puntos de ahí
                                nube=app.data.ptCloud.Location;
                            else
                                % no tengo el .ply, tomo los datos de P
                                nube=app.data.P;
                            end
                            [rotm]=pca(nube);
                            x0=rotm2eul(rotm)*180/pi;
                            % Guardo en el log lo que acaba de pasar
                            DateString=char(string(datetime));
                            S2 = [DateString, ' - The initial solution is the one resulting from PCA'];
                            a=[a S2];
                        case 'Manual'
                            solucionmanual=app.AngulosparaR_UITable.Data';
                            if length(solucionmanual)==3
                                x0=solucionmanual;
                                % Guardo en el log lo que acaba de pasar
                                DateString=char(string(datetime));
                                S2 = [DateString, ' - The initial solution was selected manually'];
                                a=[a S2];
                            else
                                % no coinciden los grados de libertad y
                                % pongo la nula
                                x0=[0 0 0];
                                % Guardo en el log lo que acaba de pasar
                                DateString=char(string(datetime));
                                S2 = [DateString, ' - The dimension of the initial solution is not valid, so this one was used: [0 0 0].'];
                                a=[a S2];
                            end
                    end
                    % Otra cosa que debo de preparar son los límites, con
                    % sus tres grados de libertad. Para el problema de dos,
                    % es diferente
                    lb=[-180 -180 -180];
                    ub=[180 180 180];
                case "2 Angles"
                    % Tengo dos grados de libertad
                    gradosdelibertad=2;
                    % Guardo en el log lo que acaba de pasar
                    DateString=char(string(datetime));
                    S2 = [DateString, ' - Optimising two degrees of freedom (two rotations)'];
                    a=[a S2];
                    switch app.InitialsolutionDropDown.Value
                        case 'None'
                            x0=[0 0];
                            % Guardo en el log lo que acaba de pasar
                            DateString=char(string(datetime));
                            S2 = [DateString, ' - The initial solution is [0 0]'];
                            a=[a S2];
                        case 'Bisectors'
                            % Le genero soluciones iniciales, como semillas para el algoritmo
                            % Le pongo todos giros a 45 y 90 grados
                            x0=[0 0;
                                0 45;
                                45 45;
                                90 45;
                                135 45;
                                180 45;
                                225 45;
                                270 45;
                                315 45;
                                0 90;
                                45 90;
                                90 90;
                                135 90;
                                180 90;
                                225 90;
                                270 90;
                                315 90];
                             % Guardo en el log lo que acaba de pasar
                            DateString=char(string(datetime));
                            S2 = [DateString, ' - The initial solutions are all bisectors of the upper hemisphere'];
                            a=[a S2];
                        case 'PCA'
                            % warndlg('I cannot define the PCA rotation by two angles. Please, select Eulers angles. Zero initial solution was selected instead.','Warning');
                            x0=[0 0];
                            % Guardo en el log lo que acaba de pasar
                            DateString=char(string(datetime));
                            S2 = [DateString, ' - I cannot define the PCA rotation by two angles. Please, select Eulers angles. Zero initial solution was selected instead.'];
                            a=[a S2];
                        case 'Manual'
                            solucionmanual=app.AngulosparaR_UITable.Data';
                            if length(solucionmanual)==2
                                x0=solucionmanual;
                                % Guardo en el log lo que acaba de pasar
                                DateString=char(string(datetime));
                                S2 = [DateString, ' - The initial solution was selected manually'];
                                a=[a S2];
                            else
                                % no coinciden los grados de libertad y
                                % pongo la nula
                                x0=[0 0];
                                % Guardo en el log lo que acaba de pasar
                                DateString=char(string(datetime));
                                S2 = [DateString, ' - The dimension of the initial solution is not valid, so this one was used: [0 0].'];
                                a=[a S2];
                            end
                    end
                    % Preparo los límites, con dos grados de libertad
                    lb=[-180 -45];
                    ub=[+180 +45];
                otherwise
                    % Hay un problema
            end
            % Preparo la optimización
            errormaximo=1e-1;
            niteracionesmaximo=app.MaxStallIterationsEditField.Value*3;
            niteracionesestancamiento=app.MaxStallIterationsEditField.Value;
            tiempomaximo=app.MaxtimesEditField.Value; % tiempo en segundos máximo de ejecución
            options=optimoptions('ga', ...
                'display','off', ...
                'MaxTime',tiempomaximo, ...
                'MaxStallTime',tiempomaximo/2, ...
                'MaxStallGenerations',niteracionesestancamiento, ...
                'MaxGenerations',niteracionesmaximo,...
                'FunctionTolerance',errormaximo,...
                'PlotFcn', {@gaplotbestf,@gaplotstopping},...
                'InitialPopulationMatrix',x0);
            % Lo que no he utilizado: 'ConstraintTolerance',errormaximo,...
            % Voy a probar a hacer el subsample antes de pasar la función.
            % La optimización será siempre con los mismos datos, para
            % hacerlo más rápido
            % Para calcular los polos principales, tomo los datos de configuracíon de densidad
            % Esto lo usaré haga o no subsample
            nsec=2^app.BinsSpinner.Value;
            angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
            maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
            % Vamos con el subsample a los datos que utilizo en la
            % optimización
            subsample=app.SubsampleEditField.Value/100;
            bandwidth=0; % le dejo el kernel optimizado y que calcule con kde2D
            mindensity=10^-3;
            switch subsample
                case 0
                    % Se optimizan con los pesos
                    % Calculamos la densidad. No hay que rotar, porque esto es para optimizar
                    polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas;
                    % [ vectores_normales ] = f_pole2vnor( polos_cartesianas_proyectadas ); % vectores normales
                    [ vectores_normales ] = f_pole2vnor_v02( polos_cartesianas_proyectadas, proyeccion_estereograma ); % vectores normales
                    % Calculo los vectores rotados
                    [dipdir,dip]=f_vnorm2clar(vectores_normales); % Convierto las nuevas normales a Clar
                    % Antes de seguir, los polos rotados los convierto a cartesianas y los guardo para representar después el estereograma coloreado
                    [ polos_rotados_cartesianas_x,polos_rotados_cartesianas_y ] = f_clar2cart( dipdir,dip,proyeccion_estereograma );
                    polos_cartesianas_proyectadas=[polos_rotados_cartesianas_x polos_rotados_cartesianas_y];
                    % Si quisieras optimizar de nuevo, recupera density, X e Y
                    [~, polos_pples_cart, ~, ~, ~]=f_densidad_y_polospples_v01(polos_cartesianas_proyectadas,nsec, angulovpples, maxpples,normaliza,proyeccion_estereograma,bandwidth,mindensity);
                    app.data.polos_pples_cart=polos_pples_cart;
                    % Opción 2: optimiza sólo con los picos, que controla el usuario. Interesante.
                    [ vectores_normales ] = f_pole2vnor_v02( app.data.polos_pples_cart(:,1:2) , proyeccion_estereograma);
                    vectores_normales(:,4)=app.data.polos_pples_cart(:,3); % le dejo en la cuarta columna la densidad
                otherwise
                    % Le calculo los polos principales pero con un subsample, para que tras la optimización me los mantenga en posición y número
                    polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas;
                    [ vectores_normales ] = f_pole2vnor_v02( polos_cartesianas_proyectadas,proyeccion_estereograma ); % vectores normales
                    % Calculo los vectores rotados
                    [dipdir,dip]=f_vnorm2clar(vectores_normales); % Convierto las nuevas normales a Clar
                    % Antes de seguir, los polos rotados los convierto a cartesianas y los guardo para representar después el estereograma coloreado
                    [ polos_rotados_cartesianas_x,polos_rotados_cartesianas_y ] = f_clar2cart( dipdir,dip,proyeccion_estereograma );
                    polos_cartesianas_proyectadas=[polos_rotados_cartesianas_x polos_rotados_cartesianas_y];
                    normaliza=app.NormalisecontourCheckBox.Value;
                    proyeccion_estereograma=app.ProjectionDropDown.Value;
                    mindensity=10^-3;
                    [~, polos_pples_cart, ~, ~, ~]=f_densidad_y_polospples_v01(polos_cartesianas_proyectadas,nsec, angulovpples, maxpples,normaliza,proyeccion_estereograma,bandwidth,mindensity);
                    app.data.polos_pples_cart=polos_pples_cart;
                    % Ya tengo los polos principales en cartesianas, con su densidad 
                    % Hacemos un subsample de todos los vectores normales y
                    % tiramos adelante
                    [n,~]=size(vectores_normales);
                    n_subsample=floor(n*subsample);
                    % Genero n_subsample indices para extraer los polos
                    r=randi([1,n],n_subsample,1);
                    % Por si acaso, elimino los duplicados
                    r=unique(r);
                    % Ahora tomo los vectores normales tras el muestreo
                    vectores_normales=vectores_normales(r,:);
                    subsample=1; % dejo el subsample en 1 para que la función no haga el diezmado
            end
            funcionobjetivo=app.ObjFDropDown.Value;
            % Por si hay algún valor erróneo, elimino los posibles puntos
            % con NaN de vectores_normales para la optimización. 
            filas_con_nan = any(isnan(vectores_normales), 2);
            filas_a_mantener = ~filas_con_nan; % Crear un vector lógico para indicar qué filas mantener (las que no tienen NaN)
            vectores_normales_optimizacion = vectores_normales(filas_a_mantener, :); % Seleccionar solo las filas que se deben mantener
            fun = @(angulos)aux_optimizacion_colores_funcion_objetivo(angulos,vectores_normales_optimizacion,subsample,funcionobjetivo);
            [resultado,fval,~,output]=ga(fun,gradosdelibertad,[],[],[],[],lb,ub,[],options);
            app.data.resultado_optimizacion_vectores=resultado;
            app.data.resultado_generaciones=output.generations;
            app.data.resultado_fval=fval;
            % Pongo el resultado en la tabla
            app.AngulosparaR_UITable.Data=resultado';
            % Guardo en el log lo que acaba de pasar
            DateString=char(string(datetime));
            S1 = [DateString, ' - I have optimised the rotation with the initial solution: ',app.InitialsolutionDropDown.Value,', and the objective function: ',app.ObjFDropDown.Value,'.'];
            S2 = [DateString, ' - The normals were optimised after ',num2str(output.generations),' generations and ',num2str(output.funccount),' evaluations, resulting in a function value of ',num2str(fval)];
            switch app.ObjFDropDown.Value
                case 'potential_linear'
                    S2b=[];
                case 'potential_quadratic'
                    S2b = [DateString, ' - The objective function used is non-linear. Its equivalent OF value in degrees is: ', num2str(fval^(1/2))];
                    % S2 = [S2 S2b];
                case 'potential_cubic'
                    S2b = [DateString, ' - The objective function used is non-linear. Its equivalent OF value in degrees is: ', num2str(fval^(1/3))];
                    % S2 = [S2 S2b];
                case 'rational_vertical_asymptote'
                    % Lo dejo preparado por si lo meto
                    S2b=[];
                otherwise
                    % Algo raro raro ha pasado
                    S2b=[];
            end
            % registro el tiempo que hemos empleado
            tiempoempleado=(toc); app.data.tiempoempleado=tiempoempleado;
            S3=[DateString, ' - The optimisation of the coloured normals was completed in ', num2str(tiempoempleado, '%.4f'),' seconds'];
            switch gradosdelibertad
                case 2
                    S4=[DateString, ' - The resulting angles are: [', num2str(resultado(1), '%.2f'), ', ', num2str(resultado(2), '%.2f'),'] (degrees).'];

                case 3
                    S4=[DateString, ' - The resulting angles are: [', num2str(resultado(1), '%.2f'), ', ', num2str(resultado(2), '%.2f'),', ', num2str(resultado(3), '%.2f'),'] (degrees).'];
            end
            S=[a S1 S2 S2b S3 S4];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
            end   

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, datos)
            app.CallingApp=caller;

            % Toda la información la tengo en datos, la tengo que pasar a
            % app.data y trabajar con esta estructura hasta el final
            app.data.polos_cartesianas_proyectadas=datos.polos_cartesianas_proyectadas; % polos en cartesianas, que pasa la GUI ppal. Son las normales de cada punto en x,y
            app.data.normales=datos.normales; % Los vectores normales
            app.data.planos_Clar=datos.planos_Clar; % Son los planos en notación de Clar, dirección de buzamiento y buzamiento
            app.data.P=datos.P; % coordenadas de los puntos, evidentemente en el mismo orden que los polos. De lo contrario, a ver qué hacemos.
            app.data.ptCloud=datos.ptCloud; % la nube de puntos con sus normales. Verifica que existe si vienes de calcularlos tú.  
            app.data.pathname=datos.pathname;
            app.data.filename=datos.filename;
            app.data.preferences=datos.preferences;
            app.data.angulos=datos.angulos;
            if datos.angulos*datos.angulos'==0
                % no tengo datos de ángulos, dejo la GUI como está
            else
                % hay ángulos, por lo que activo la opción de dibujar los rotados
                app.DisplayDropDown.Enable="on";
                app.DisplayDropDown.Value='Optimised data';
            end

            % Configuro la tabla del resutlado
            app.AngulosparaR_UITable.Data=datos.angulos';
            app.AngulosparaR_UITable.RowName='numbered';

            % Esta variable la creo para el batch
            app.data.paso=0;

            [n,~]=size(datos.P);

            % Doy la bienvenida y digo el nombre del fichero, con su número
            % de puntos
            % a = app.LogListBox.Items; % Si tuviera que pillar lo que hay,
            % pero como es la primera vez que lo abro, pues no.
            DateString=char(string(datetime));
            S0 = [DateString, '- Welcome to the DSE contour plot optimisation tool'];
            S1 = [DateString, ' - Name of the cloud: ', datos.filename,'. Size: ', num2str(n), '.'];
            S2 = [DateString, ' - Path: ',datos.pathname];
            % registro el tiempo que hemos empleado
            a=app.LogListBox.Items;
            S=[a S0 S1 S2];
            app.LogListBox.Items=S;
            % fijo la vista del listbox en la última entrada
            scroll(app.LogListBox, 'bottom')
            app.data.log = S;
        end

        % Button pushed function: Button
        function OptimiseColousButtonPushed(app, event)
            % Primero busco la rotación que optimiza los polos
            optimiza_normales(app);
            % Ya tengo los ángulos que optimizan los colores para el HSV en
            % app.data.resultado_optimizacion_vectores
            % El mapa de colores lo calculo al pulsar en plot, para que
            % tome los valores de los bins, ángulos y máx pples
            % Activo el botón de plotear la nube con el resultado
            app.DisplayDropDown.Enable="on";
            app.DisplayDropDown.Value='Optimised data';
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Vamos a dibujar la nube y el estereograma
            % Podemos dibujar la original o la rotada. Si es la original,
            % R=id y si es la optimizada, habrá que darle la R calculada
            switch app.DisplayDropDown.Value
                case 'Original data'
                    % Represento la original, tomo R=1
                    R=eye(3);
                case 'Optimised data'
                    % Ya tengo optimizada la rotación, ahora vamos a dibujarla
                    % angulos es el resultado de la optimización, que son dos o
                    % tres ángulos dependiendo de los GDL seleccionados
                    % angulos=app.data.resultado_optimizacion_vectores;
                    % Tomo los ángulos de la tabla, porque los puedo haber querido editar
                    angulos=app.AngulosparaR_UITable.Data';
                    % La técnica que empleo es dos ángulos o euler, que saco de la dimensión de los ángulos
                    gradosdelibertad=length(angulos);
                    switch gradosdelibertad
                        case 3 % Tengo 3 grados de libertad
                            % Ya tengo directamente la matriz de rotación
                            R=eul2rotm(angulos/180*pi); % Como vienen en grados, paso a radianes
                            app.data.R=R;
                        case 2 % Tengo 2 grados de libertad
                            % Tengo los dos ángulos de giro. Primero defino la matriz de rotación
                            % Los valores de alpha y beta se los pasará la optimización
                            alpha=angulos(1)/180*pi; % Como vienen en grados, paso a radianes
                            beta=angulos(2)/180*pi;
                            u=[+cos(alpha) -sin(alpha) 0];
                            v=[cos(beta)*sin(alpha) cos(beta)*cos(alpha) -sin(beta)];
                            w=[sin(beta)*sin(alpha) sin(beta)*cos(alpha) +cos(beta)];
                            R(:,1)=u; R(:,2)=v;R(:,3)=w;
                            % Guardo la matriz de rotación
                            app.data.R=R;
                        otherwise
                            % Algo falla
                    end
                otherwise
                    % Algo ha ido mal
            end
            % Calculo los mapas de colores con los resultados previos
            % sectorización de la malla para el kde
            nsec=2^app.BinsSpinner.Value;
            angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
            maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
            [polos_cartesianas_proyectadas_rotadas, polos_cartesianas_proyectadas_solocolor_optimizado, XYZ_RGB_NxNyNz_coloreado_optimizado, polos_pples_cart,X,Y,density] = Colorea_nuberotada(app,R, nsec, angulovpples, maxpples);
            angulos=app.AngulosparaR_UITable.Data';
            % Llamo a la función para que me represente nube
            Show_coloured(app,angulos,XYZ_RGB_NxNyNz_coloreado_optimizado, polos_cartesianas_proyectadas_rotadas, polos_cartesianas_proyectadas_solocolor_optimizado, polos_pples_cart, X, Y, density);

        end

        % Close request function: 
        % DSEmoduleforoptimisingaxisrotationUIFigure
        function DSEmoduleforoptimisingaxisrotationUIFigureCloseRequest(app, event)
            CloseButtonPushed(app, event);
            % Cuando cierro la ventana sin guardar, activo de nuevo la
            % ventana principal y elimino esta
            app.CallingApp.DiscontinuitySetExtractor3UIFigure.Visible="on";
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.data.angulos=app.AngulosparaR_UITable.Data';
            updatefunction_coloured_normals(app.CallingApp,app.data);
            S=app.LogListBox.Items;
            % Y ya puestos, guardo el log en un log.txt
            f = fopen([app.data.pathname,app.data.filename,' - colouring_log.txt'], 'wt');
            % [n,~]=size(S);
            n=length(S);
            for i=1:n
                fprintf(f, '%s \n', S{1,i});
            end
            fclose(f);
            delete(app);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            switch value
                case 'HSV'
                    app.ColourmapValueSliderLabel.Text='V: ';
                    app.ColourmapValueSlider.Tooltip='Adjust the Value ( V ) in the HSV color map, where 0 represents black and 100 represents white.';
                    app.ColourmapValueSlider.Value=100;
                case {'CIELAB','CIELCH'}
                    app.ColourmapValueSliderLabel.Text='L*: ';
                    app.ColourmapValueSlider.Tooltip='Adjust the lightness ( L ) in the CIELAB or CIELHC color map, where 0 represents black and 100 represents white.';
                    app.ColourmapValueSlider.Value=75;
                otherwise
                    % No hago nada
            end
        end

        % Value changed function: DOGDropDown
        function DOGDropDownValueChanged(app, event)
            value = app.DOGDropDown.Value;
            if isfield(app.data,'resultado_optimizacion_vectores')
                angulos_optimizados=app.data.resultado_optimizacion_vectores;
                gradosdelibertad=length(angulos_optimizados);
            else
                gradosdelibertad=0;
            end
            switch value
                case '2 Angles'
                    if gradosdelibertad==2
                        angulos=app.data.resultado_optimizacion_vectores';
                    else
                        angulos= [0 0]';
                    end
                otherwise
                    if gradosdelibertad==3
                        angulos=app.data.resultado_optimizacion_vectores';
                    else
                        angulos= [0 0 0]';
                    end
            end
            app.AngulosparaR_UITable.Data=angulos;
        end

        % Value changed function: ShowStereonetCheckBox
        function ShowStereonetCheckBoxValueChanged(app, event)
            value = app.ShowStereonetCheckBox.Value;
            switch value
                case 0
                    app.ShowContoursCheckBox.Enable="off";
                    app.ShowPolesCheckBox.Enable="off";
                    app.ShowPpalPolesCheckBox.Enable="off";
                    app.KeepPpalPolesCheckBox.Enable="off";
                case 1
                    app.ShowContoursCheckBox.Enable="on";
                    app.ShowPolesCheckBox.Enable="on";
                    app.ShowPpalPolesCheckBox.Enable="on";
                    app.KeepPpalPolesCheckBox.Enable="on";
            end

        end

        % Cell edit callback: AngulosparaR_UITable
        function AngulosparaR_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % He cambiado los ángulos de rotación, actualizo los datos
            app.data.angulos=app.AngulosparaR_UITable.Data';
            % Activo la representación del rotado
            app.DisplayDropDown.Enable="on";
            app.DisplayDropDown.Value="Optimised data";
        end

        % Button pushed function: ProcesaelBatchButton
        function ProcesaelBatchButtonPushed(app, event)
            normaliza=app.NormalisecontourCheckBox.Value;
            proyeccion=app.ProjectionDropDown.Value;

            if isempty(app.LastPath)
                [filename, pathname] = uigetfile('*.xlsx', 'Selecciona un archivo Excel');
            else
                [filename, pathname] = uigetfile('*.xlsx', 'Selecciona un archivo Excel', app.LastPath);
            end

            if isequal(filename, 0)
                errordlg('No file was selected.', 'File Selection Error');
                return;
            else
                fullpath = fullfile(pathname, filename);
                T = readtable(fullpath);
                app.LastPath = pathname; % Almacena la última ruta
            end

            T.Resultado_fecha = string(T.Resultado_fecha);
            n = height(T);
            pathname_original=pathname; filename_original=filename;
            pathname = 0; filename = 0;

            % Para el seguimiento del optimizado, voy a crear la ventana principal con tamaño ajustado
            fig = uifigure('Name', 'Progreso', 'Position', [100, 100, 400, 150]);
            % Creo el cuadro de progreso dentro de la ventana
            d = uiprogressdlg(fig, ...
                'Title', 'Ten paciencia', ...
                'Message', 'Calculando las optimizaciones', ...
                'Cancelable', 'on');
            % Ahora, ajusto el tamaño de la ventana al contenido del diálogo
            fig.Position(3:4) = [420, 200]; % Ancho x Alto (ajusta según lo necesites)
            % Empezamos con el procesado de los datos
            for i = 1:n
                % Permito que sea cancelable
                if d.CancelRequested
                    break
                end
                % Actualizo la ventana de seguimiento
                d.Value = i/n; d.Message = sprintf('Iteración %d de %d', i, n);
                % Verifico si ya hay resultados en ese cálculo (si hay, se salta la iteración)
                if ~isempty(T.Resultado_F(i)) && ~isnan(T.Resultado_F(i))
                    continue;
                end
                % Cargo los datos en el caso de que el path y el filename cambian
                if (i > 1 && strcmp(pathname, T.Path{i}) && strcmp(filename, T.filename{i}))
                    pathname = strcat(T.Path{i}, "\");
                    filename = T.filename{i};
                    ptCloud = pcread(strcat(pathname, filename));
                    app.data.ptCloud = ptCloud;
                    app.data.P = ptCloud.Location;
                    app.data.Poriginal = ptCloud.Location;
                    normales = ptCloud.Normal;
                    app.data.normales = normales;

                    [planos_Clar] = prepara_estereo(normales);
                    app.data.planos_Clar = planos_Clar;

                    projection = app.ProjectionDropDown.Value;
                    [polos_cartesianas_proyectadas] = f_calcula_coordenadas_polosvn(planos_Clar, projection);
                    app.data.polos_cartesianas_proyectadas = polos_cartesianas_proyectadas;

                    polos_x = app.data.polos_cartesianas_proyectadas(:, 1);
                    polos_y = app.data.polos_cartesianas_proyectadas(:, 2);
                    [dipdir, dip] = f_cart2clar_v02(polos_x, polos_y, proyeccion);

                    app.data.polos_estereo_clar = zeros(size(polos_cartesianas_proyectadas));
                    app.data.polos_estereo_clar(:, 1) = dipdir/180 * pi;
                    app.data.polos_estereo_clar(:, 2) = dip/180 * pi;

                    [x, y] = f_clar2cart(dipdir/180 * pi, dip/180 * pi, proyeccion);
                    [normales] = f_pole2vnor_v02([x, y], projection);
                    app.data.polos_estereo_normales = normales;
                else
                    pathname=pathname_original;
                    filename=filename_original;
                end

                % Actualizo parámetros en la GUI, para ajustar el cálculo
                app.BinsSpinner.Value = T.Bins(i);
                app.MinangleEditField.Value = T.MinAngle(i);
                app.NmaxDSEditField.Value = T.NmaxDS(i);
                app.data.InitialsolutionDropDown.Value = T.InitialSol{i};
                app.DOGDropDown.Value = T.DOG{i};
                app.SubsampleEditField.Value = T.Subsample(i);
                app.ObjFDropDown.Value = T.ObjF{i};
                app.MaxStallIterationsEditField.Value = T.Stall(i);
                app.MaxtimesEditField.Value = T.time(i);

                % Ejecuto la optimización
                optimiza_normales(app);

                % Guardo resultados
                T.Resultado_F(i) = app.data.resultado_fval;
                T.Resultado_tiempo(i) = app.data.tiempoempleado;

                switch length(app.data.resultado_optimizacion_vectores)
                    case 2
                        T.Resultado_vectores_1(i) = app.data.resultado_optimizacion_vectores(1);
                        T.Resultado_vectores_2(i) = app.data.resultado_optimizacion_vectores(2);
                    case 3
                        T.Resultado_vectores_1(i) = app.data.resultado_optimizacion_vectores(1);
                        T.Resultado_vectores_2(i) = app.data.resultado_optimizacion_vectores(2);
                        T.Resultado_vectores_3(i) = app.data.resultado_optimizacion_vectores(3);
                end

                T.Resultado_generaciones(i) = app.data.resultado_generaciones;
                T.Resultado_fecha(i) = char(string(datetime));

                % Exporto resultados
                pathname = num2str(pathname);
                filename = num2str(filename);
                writetable(T, strcat(pathname, filename, '_Optimizacion_resultado.xlsx'));
                writetable(T, strcat(pathname, filename, '_Optimizacion_resultado.txt'), 'Delimiter', 'tab');

                % Genero y guardo gráficas
                app.ColormapDropDown.Value = T.Colormap{i};
                app.data.paso = T.Step(i);
                app.DisplayDropDown.Value = T.Display{i};
                % app.SaveGraphsCheckBox.Value = 1; % No siempre puedo
                % querer guardar las figuras, lo dejo a tu elección
                Button_2Pushed(app);

                h_figuras = findobj('type', 'figure');
                close(h_figuras(1, 1));
                close(h_figuras(2, 1));
            end

            close(fig);

            fig = uifigure;
            message = "Ya he terminado de computar todas las iteraciones.";
            uialert(fig, message, "Atiende", "Icon", "success", 'CloseFcn', @(~, ~) close(fig));

        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            value = app.ProjectionDropDown.Value;
            app.data.preferences.Projection=value;
        end

        % Value changed function: NormalisecontourCheckBox
        function NormalisecontourCheckBoxValueChanged(app, event)
            value = app.NormalisecontourCheckBox.Value;
            app.data.preferences.Normalisecontour=value;
        end

        % Display data changed function: AngulosparaR_UITable
        function AngulosparaR_UITableDisplayDataChanged(app, event)
            newDisplayData = app.AngulosparaR_UITable.DisplayData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DSEmoduleforoptimisingaxisrotationUIFigure = uifigure('Visible', 'off');
            app.DSEmoduleforoptimisingaxisrotationUIFigure.Name = 'DSE Module for Optimising Axis Rotation to Enhance Pole Density Interpretation and Facilitate Normal-Based Colouring';
            app.DSEmoduleforoptimisingaxisrotationUIFigure.CloseRequestFcn = createCallbackFcn(app, @DSEmoduleforoptimisingaxisrotationUIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.DSEmoduleforoptimisingaxisrotationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.LogListBoxLabel = uilabel(app.DSEmoduleforoptimisingaxisrotationUIFigure);
            app.LogListBoxLabel.Text = 'Log';
            app.LogListBox = uilistbox(app.DSEmoduleforoptimisingaxisrotationUIFigure);
            app.LogListBox.Items = {'Welcome to the DSE Colour Optimisation Module for Normals'};
            app.LogListBox.Value = 'Welcome to the DSE Colour Optimisation Module for Normals';
            app.OptimisethenormalscoloursPanel = uipanel(app.DSEmoduleforoptimisingaxisrotationUIFigure);
            app.OptimisethenormalscoloursPanel.Title = 'Optimise the normal''s colours';
            app.Button = uibutton(app.OptimisethenormalscoloursPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @OptimiseColousButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'imagen_rotacion.png');
            app.Button.Text = '';
            app.SubsampleLabel = uilabel(app.OptimisethenormalscoloursPanel);
            app.SubsampleLabel.Text = 'Subsample %';
            app.DOGDropDownLabel = uilabel(app.OptimisethenormalscoloursPanel);
            app.DOGDropDownLabel.Tooltip = {''};
            app.DOGDropDownLabel.Text = 'DOG';
            app.InitialsolutionDropDownLabel = uilabel(app.OptimisethenormalscoloursPanel);
            app.InitialsolutionDropDownLabel.Text = 'Initial solution';
            app.ObjFDropDownLabel = uilabel(app.OptimisethenormalscoloursPanel);
            app.ObjFDropDownLabel.Tooltip = {''};
            app.ObjFDropDownLabel.Text = 'Obj F';
            app.MaxtimesEditFieldLabel = uilabel(app.OptimisethenormalscoloursPanel);
            app.MaxtimesEditFieldLabel.Text = 'Max time (s)';
            app.MaxtimesEditField = uieditfield(app.OptimisethenormalscoloursPanel, 'numeric');
            app.MaxtimesEditField.Limits = [0 Inf];
            app.MaxtimesEditField.Value = 120;
            app.MaxStallIterationsEditFieldLabel = uilabel(app.OptimisethenormalscoloursPanel);
            app.MaxStallIterationsEditFieldLabel.Text = 'Max Stall Iterations';
            app.MaxStallIterationsEditField = uieditfield(app.OptimisethenormalscoloursPanel, 'numeric');
            app.MaxStallIterationsEditField.Limits = [1 Inf];
            app.MaxStallIterationsEditField.Value = 50;
            app.ObjFDropDown = uidropdown(app.OptimisethenormalscoloursPanel);
            app.ObjFDropDown.Items = {'potential_linear', 'potential_quadratic', 'potential_cubic', 'rational_vertical_asymptote', 'steps'};
            app.ObjFDropDown.Tooltip = {'Select the objective function to optimise:'; '1. Potential linear: sum(beta)/n'; '2. Potential quadratic: sum(beta^2)/n'; '3. Potential cubid: sum(beta^3)/n'; '4. Rational with vertical asymptote: sum(1/(90-beta))'};
            app.ObjFDropDown.Value = 'rational_vertical_asymptote';
            app.SubsampleEditField = uieditfield(app.OptimisethenormalscoloursPanel, 'numeric');
            app.SubsampleEditField.Limits = [0 100];
            app.SubsampleEditField.Tooltip = {'To optimise the rotation, every iteration subsamples the point cloud randomly.'; 'To optimise using weight poles (density), set subsample to 0.'};
            app.SubsampleEditField.Value = 100;
            app.DOGDropDown = uidropdown(app.OptimisethenormalscoloursPanel);
            app.DOGDropDown.Items = {'Euler''s angles', '2 Angles'};
            app.DOGDropDown.ValueChangedFcn = createCallbackFcn(app, @DOGDropDownValueChanged, true);
            app.DOGDropDown.Tooltip = {'Degrees of freedom for the optimisation: number of rotations'; '2 angles'; '3 angles (Euler''s angles)'};
            app.DOGDropDown.Value = 'Euler''s angles';
            app.AngulosparaR_UITable = uitable(app.OptimisethenormalscoloursPanel);
            app.AngulosparaR_UITable.ColumnName = {'Angles  [º]'};
            app.AngulosparaR_UITable.RowName = {};
            app.AngulosparaR_UITable.ColumnEditable = true;
            app.AngulosparaR_UITable.CellEditCallback = createCallbackFcn(app, @AngulosparaR_UITableCellEdit, true);
            app.AngulosparaR_UITable.DisplayDataChangedFcn = createCallbackFcn(app, @AngulosparaR_UITableDisplayDataChanged, true);
            app.AngulosparaR_UITable.Tooltip = {'Angles to define R'};
            app.InitialsolutionDropDown = uidropdown(app.OptimisethenormalscoloursPanel);
            app.InitialsolutionDropDown.Items = {'None', 'Bisectors', 'PCA', 'Manual'};
            app.InitialsolutionDropDown.Tooltip = {'Choose the initial population for the genetic algorithm'};
            app.InitialsolutionDropDown.Value = 'None';
            app.DisplaySettingsPanel = uipanel(app.DSEmoduleforoptimisingaxisrotationUIFigure);
            app.DisplaySettingsPanel.Tooltip = {'When the stereonet is generated, if the principal poles (or peaks) are displayed, the original peaks will remain visible even when the normals are rotated. If they are not displayed, the program will search for local peaks again, and their values may change.'};
            app.DisplaySettingsPanel.Title = 'Display Settings';
            app.ColormapDropDownLabel = uilabel(app.DisplaySettingsPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.BackgroundDropDownLabel = uilabel(app.DisplaySettingsPanel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.DisplaySettingsPanel);
            app.BackgroundDropDown.Items = {'White', 'Black'};
            app.BackgroundDropDown.Tooltip = {'Select the background of the window for drawing the point clouds. With light colour maps, a dark background might be interesting in some cases.'; ''};
            app.BackgroundDropDown.Value = 'White';
            app.DisplayDropDownLabel = uilabel(app.DisplaySettingsPanel);
            app.DisplayDropDownLabel.Enable = 'off';
            app.DisplayDropDownLabel.Text = 'Display';
            app.ColourmapValueSlider = uislider(app.DisplaySettingsPanel);
            app.ColourmapValueSlider.Tag = 'ColormapV';
            app.ColourmapValueSlider.Tooltip = {'According to the selected colour map, choose the third parameter. In the case of HSV, select Lightness (0 dark and 100 white). If you select CIELAB, choose the Lightness value (0 dark and 100 light).'};
            app.ColourmapValueSlider.Value = 100;
            app.ColourmapValueSliderLabel = uilabel(app.DisplaySettingsPanel);
            app.ColourmapValueSliderLabel.Text = 'V:';
            app.ColormapDropDown = uidropdown(app.DisplaySettingsPanel);
            app.ColormapDropDown.Items = {'none', 'HSV', 'CIELAB', 'CIELCH', 'CIELCHmod'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Tooltip = {'Select the colour map to be used for colouring the cloud and the stereogram. The available colour maps are HSV and CIELAB.'};
            app.ColormapDropDown.Value = 'HSV';
            app.DisplayDropDown = uidropdown(app.DisplaySettingsPanel);
            app.DisplayDropDown.Items = {'Original data', 'Optimised data'};
            app.DisplayDropDown.Enable = 'off';
            app.DisplayDropDown.Value = 'Original data';
            app.KeepPpalPolesCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.KeepPpalPolesCheckBox.Tooltip = {'Show the principal poles got from the peaks in the original density function. Rotated peaks may have different orientations.'};
            app.KeepPpalPolesCheckBox.Text = 'Keep ppal poles';
            app.KeepPpalPolesCheckBox.Value = true;
            app.ShowPpalPolesCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.ShowPpalPolesCheckBox.Text = 'Show ppal poles';
            app.ShowPolesCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.ShowPolesCheckBox.Tooltip = {'Show the coloured poles in the stereonet. If disabled, only density will be shown.'};
            app.ShowPolesCheckBox.Text = 'Show poles';
            app.ShowPolesCheckBox.Value = true;
            app.ShowContoursCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.ShowContoursCheckBox.Text = 'Show contours';
            app.ShowStereonetCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.ShowStereonetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowStereonetCheckBoxValueChanged, true);
            app.ShowStereonetCheckBox.Text = 'Plot the stereonet';
            app.ShowStereonetCheckBox.Value = true;
            app.SaveGraphsCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.SaveGraphsCheckBox.Tooltip = {'When plotting the point cloud graphs and stereograms, the clouds will be saved in .PLY format and the stereograms in .PNG and .PDF formats.'};
            app.SaveGraphsCheckBox.Text = 'Save Graphs';
            app.RotatethecloudCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.RotatethecloudCheckBox.Tooltip = {'Apply the rotation R to the 3DPC in the representations. The cloud will not have the original orientation, but it will have the optimised poles’ orientation.'};
            app.RotatethecloudCheckBox.Text = 'Rotate the cloud';
            app.DPCcolouredCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.DPCcolouredCheckBox.Text = '3DPC (coloured)';
            app.DPCcolouredCheckBox.Value = true;
            app.DPCoriginalCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.DPCoriginalCheckBox.Text = '3DPC (original)';
            app.ProjectionDropDownLabel = uilabel(app.DisplaySettingsPanel);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.DisplaySettingsPanel);
            app.ProjectionDropDown.Items = {'Equal-angle', 'Equal-area'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'Equal-angle';
            app.NormalisecontourCheckBox = uicheckbox(app.DisplaySettingsPanel);
            app.NormalisecontourCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalisecontourCheckBoxValueChanged, true);
            app.NormalisecontourCheckBox.Tooltip = {'Normalise the maximum density value in the Contour Plot to 1. This is important for visualising the 3D contour if the peaks are highly concentrated in one area.'};
            app.NormalisecontourCheckBox.Text = 'Normalise contour plot';
            app.NormalisecontourCheckBox.Value = true;
            app.DisplayPanel = uipanel(app.DSEmoduleforoptimisingaxisrotationUIFigure);
            app.DisplayPanel.Title = 'Display ';
            app.Button_2 = uibutton(app.DisplayPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'imagen_scatter.png');
            app.Button_2.Tooltip = {'Draw the results (original or optimised): point clouds and stereograms. Use the selected colour configuration.'};
            app.Button_2.Text = '';
            app.BinsSpinnerLabel = uilabel(app.DisplayPanel);
            app.BinsSpinnerLabel.Text = 'Bins';
            app.BinsSpinner = uispinner(app.DisplayPanel);
            app.BinsSpinner.Limits = [2 Inf];
            app.BinsSpinner.Tooltip = {'Level of the Bins. The number of bins is 2^(level). Recommended values are 6 or 7.'};
            app.BinsSpinner.Value = 6;
            app.MinangleLabel = uilabel(app.DisplayPanel);
            app.MinangleLabel.Text = 'Min angle [º]';
            app.MinangleEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.MinangleEditField.Limits = [0 90];
            app.MinangleEditField.Tooltip = {'Minimum angle between normals of the principal discontinuity sets.'};
            app.MinangleEditField.Value = 30;
            app.NmaxDSEditField_2Label = uilabel(app.DisplayPanel);
            app.NmaxDSEditField_2Label.Text = 'Nmax DS';
            app.NmaxDSEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.NmaxDSEditField.Limits = [1 Inf];
            app.NmaxDSEditField.Tooltip = {'Maximun number of principal planes to extract from the density function'};
            app.NmaxDSEditField.Value = 10;
            app.ProcesaelBatchButton = uibutton(app.DSEmoduleforoptimisingaxisrotationUIFigure, 'push');
            app.ProcesaelBatchButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesaelBatchButtonPushed, true);
            app.ProcesaelBatchButton.Text = 'Procesa el Batch';
            % Show the figure after all components are created
            app.DSEmoduleforoptimisingaxisrotationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_coloured_normals_v00(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSEmoduleforoptimisingaxisrotationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSEmoduleforoptimisingaxisrotationUIFigure)
        end
    end
end