classdef gui_density_stats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PlotKnob             matlab.ui.control.DiscreteKnob
        PlotKnobLabel        matlab.ui.control.Label
        PlotButtonGroup      matlab.ui.container.ButtonGroup
        PolesDSButton        matlab.ui.control.RadioButton
        PolesButton          matlab.ui.control.RadioButton
        DensityButton        matlab.ui.control.RadioButton
        ReportTextArea       matlab.ui.control.TextArea
        ReportTextAreaLabel  matlab.ui.control.Label
        RangeEditField       matlab.ui.control.EditField
        RangeEditFieldLabel  matlab.ui.control.Label
        TituloFigura         matlab.ui.control.Label
        DSSpinner            matlab.ui.control.Spinner
        DSSpinnerLabel       matlab.ui.control.Label
        UIAxes_Hist          matlab.ui.control.UIAxes
        UIAxes_Stereo        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Es la función que llama a esta ventana, me permite identificarla cuando al final llame al updatefunction
        data % donde voy a guardar todos mis datos durante este programa
        % Los datos que recibo son:
        % datos.ppal_poles % los polos pples, en dip dir y dip
        % datos.polos_pples_cart % los polos pples en XY
        % datos.polos_estereo_cartesianas % Son todos los polos en XY
        % datos.P la matriz de puntos
        % La densidad, tampoco la pasaría porque creo que es mejor calcularla
        % datos.X
        % datos.Y
        % datos.density
    end

    methods (Access = private)

        function results = plotdensity(app)
            % Función que representa la función de densidad con los nuevos polos principales
            results=0;

            % Primero, tomo los datos
            % polos_estereo_cartesianas = app.data.polos_estereo_cartesianas;
            polos_pples = app.data.ppal_poles;
            polos_pples_cart=polos_pples;
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;
            % P=app.data.P;
            % ppal_poles=app.data.ppal_poles;
            % representamos la salida
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                xc=polos_pples_cart(1:kk,3)'+0.1;
            end
            % maxd=max(max(density)); mind=min(min(density));
            numberisolines=80; % número de isoloníneas
            % isolines=(maxd-mind)/numberisolines;

            cla(app.UIAxes_Stereo,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,app.UIAxes_Stereo);
            % conservo la gráfica para que todo se dibuje encima
            hold(app.UIAxes_Stereo,'on')

            % opción 3D
            % app.UIAxes_Stereo.Color=[1 1 1];

            % Pongo un punto donde está cada polo principal
            scatter3(app.UIAxes_Stereo,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3),tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            % Ahora inserto la etiqueta para ver el polo principal que es
            for ii=1:kk
                text(app.UIAxes_Stereo,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end

            % Dibujo las isolineas
            contour3(app.UIAxes_Stereo,X,Y,density,numberisolines);
            axis (app.UIAxes_Stereo,[-1 1 -1 1]); axis(app.UIAxes_Stereo,'square');
            % Doy formato a la gráfica
            app.TituloFigura.Text=['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'];
            xlabel(app.UIAxes_Stereo,'axis X'); ylabel(app.UIAxes_Stereo,'axis Y');

            % preparo el puntero
            % dcm = datacursormode(gcf);
            % datacursormode on;
            % set(dcm,'updatefcn',@myfunction)

            % Ya he terminado, desbloqueo la gráfica
            hold(app.UIAxes_Stereo,'off')
        end

        function results = falsillariquelme(app,figura)
            results=0;
            % Riquelme -- Program for plotting a stereonet for DSE
            % Written by Adrian Riquelme, September 2016
            % ariquelme@ua.es
            % Department of Civil Engineering, University of Alicante, Spain

            % First, we define the circle and the axis
            % Code extracted from the wulff net
            N = 50;
            cx = cos(0:pi/N:2*pi);                           % points on circle
            cy = sin(0:pi/N:2*pi);
            xh = [-1 1];                                     % horizontal axis
            yh = [0 0];
            xv = [0 0];                                      % vertical axis
            yv = [-1 1];
            plot(figura,xh,yh,'-.k',xv,yv,'-.k');                     %plot green axes
            % axis('square');
            % axis off;
            hold(figura,"on")
            plot(figura,cx,cy,'--k');                                %plot white circle
            % axis(figura,[-1 1 -1 1],'square','off');
            axis(figura,[-1 1 -1 1],'square','off');

            % Now, we draw the circles that refer to the dip angle
            psi = [0:pi/N:2*pi];
            beta=[15 30 45 60 75 90]/180*pi;
            [~,n]=size(beta);
            for i = 1:(n-1)                                   % plot dip circles
                [radio]=f_radio(beta(i));
                x = radio .* sin(psi);
                y = radio .* cos(psi);
                plot(figura,x,y,':r');
                text(figura,radio,0,num2str(beta(i)*180/pi),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);
            end
            % Inserto el label del buzamiento de 90
            [radio]=f_radio(90*pi/180);
            text(figura,radio,0,num2str(90),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);

            % axis(figura,'square','off');
            axis(figura,'square');

            % Finally, we draw the circles that refer to the dip direction
            alpha0 = 0:pi/6:(2*pi-pi/12);
            alpha = pi/2 - alpha0+pi;
            etiqueta = 0:360/12:(330);
            x = 1.2*cos(alpha);
            y = 1.2*sin(alpha);
            [~,n]=size(alpha);
            tamanyoangulos=12;
            for i=1:n
                % le pongo su etiqueta el ángulo respecto al norte
                if etiqueta(i)==0 || etiqueta(i)==180
                    text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','center','VerticalAlignment','cap','FontSize',tamanyoangulos);
                else
                    if etiqueta(i)>0 && etiqueta(i)<180
                        text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Right','FontSize',tamanyoangulos);
                    else
                        text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Left','FontSize',tamanyoangulos);
                    end
                end

                xaux=[f_radio(15*pi/180)*cos(alpha(i))  cos(alpha(i))];
                yaux=[f_radio(15*pi/180)*sin(alpha(i))  sin(alpha(i))];
                plot(figura,xaux,yaux,'r:.');
                % pongo una pequeña línea para marcar dónde va ese label
                xaux=[cos(alpha(i))  1.05*cos(alpha(i))];
                yaux=[sin(alpha(i))  1.05*sin(alpha(i))];
                plot(figura,xaux,yaux,'k-');

            end
            hold(figura,"off")
        end

        function results = plotpoles(app)
            results=0;
            % Preparo los datos
            % polos_estereo_cartesianas = app.data.polos_estereo_cartesianas;
            polos_pples = app.data.ppal_poles;
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            polos_pples_cart=polos_pples;
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );

            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            cla(app.UIAxes_Stereo,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,app.UIAxes_Stereo);

            % conservo la gráfica para que todo se dibuje encima
            hold(app.UIAxes_Stereo,'on')

            % Preparo los colores
            ds=app.DSSpinner.Value; % índice de la familia seleccionada
            angulos=app.data.angulo_polo_principal(:,ds);
            colores=zeros(size(angulos));
            cono=str2double(app.RangeEditField.Value)/180*pi;
            maxangle=app.data.histogram_maxangle/180*pi;
            % asigno los colores
            % menor que la mitad de la media
            I=find(angulos(:,1)<=maxangle/2);
            colores(I)=1;
            % entre el anterior y la media
            I=find(and(angulos(:,1)>maxangle/2,angulos(:,1)<=maxangle));
            colores(I)=2;
            % Sobre la media
            I=find(and(angulos(:,1)>maxangle, angulos(:,1)<=cono));
            colores(I)=3;
            % Fuera del rango
            I=find(angulos(:,1)> cono);
            colores(I)=4;

            % app.UITable.Color=[1 1 1];
            % axis(app.UITable,'square','equal');
            z=zeros(size(polos_estereo_cartesianas(:,1)));
            z(:,1)=0; % originalmente, -0.1
            % Dibujo todos los polos
            % Lo dibujo sin colores
            % scatter3(app.UIAxes_Stereo,polos_estereo_cartesianas(:,1),polos_estereo_cartesianas(:,2),z,1);
            % Con colores, según un criterio
            % scatter3(app.UIAxes_Stereo,polos_estereo_cartesianas(:,1),polos_estereo_cartesianas(:,2),z,1,colores);
            % Con colores según el valor del ángulo
            % limito al cono
            I=find(angulos(:,1)<=cono);
            scatter3(app.UIAxes_Stereo,polos_estereo_cartesianas(I,1),polos_estereo_cartesianas(I,2),z(I),1,app.data.angulo_polo_principal(I));
            % Pongo un punto donde está cada polo principal.
            % Empiezo preparando coordenadas de los polos pples
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                % xc=polos_pples_cart(1:kk,3)'+0.1;
                xc(1:kk)=0.1;
            end
            scatter3(app.UIAxes_Stereo,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),0 ,tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            % Ahora inserto la etiqueta para ver el polo principal que es
            for ii=1:kk
                text(app.UIAxes_Stereo,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end

            % Preparo la información de la figura que he representado
            app.TituloFigura.Text=['Stereographic Projection, Normal Vector Poles: ',num2str(length(polos_estereo_cartesianas)),' points'];

            % Ya he terminado, desbloqueo la gráfica
            hold(app.UIAxes_Stereo,'off')
        end

        function results = plotpolesds(app)
            results=0;

            % Preparo los datos
            cone=str2double(app.RangeEditField.Value)/180*pi;
            polos_estereo_cartesianas=app.data.polos_estereo_cartesianas;
            polos_pples=app.data.ppal_poles;
            polos_pples_cart=zeros(size(polos_pples));
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) );
            app.data.polos_pples_cart=polos_pples_cart;
            P=app.data.P;
            [polos_estereoppalasignados, ~] = f_setppal2planes_v02( cone, polos_estereo_cartesianas, polos_pples_cart ,P);
            A=polos_estereoppalasignados;
            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            cla(app.UIAxes_Stereo,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,app.UIAxes_Stereo);
            % conservo la gráfica para que todo se dibuje encima
            hold(app.UIAxes_Stereo,'on');
            
            % Dibujo los polos según familias
            scatter(app.UIAxes_Stereo,A(:,1),A(:,2),1,A(:,3));
            % colormap(app.UIAxes,jet);
            colormap(app.UIAxes_Stereo,"turbo");

            % Pongo un punto donde está cada polo principal.
            % Empiezo preparando coordenadas de los polos pples
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            xa=polos_pples_cart(1:kk,1)'+0.1;
            xb=polos_pples_cart(1:kk,2)'+0;
            if nc==2
                xc=0;
            else
                xc=polos_pples_cart(1:kk,3)'+0.1;
            end
            scatter3(app.UIAxes_Stereo,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),polos_pples_cart(1:kk,3),tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
            % Ahora inserto la etiqueta para ver el polo principal que es
            for ii=1:kk
                text(app.UIAxes_Stereo,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
            end

            % Preparo la información de la figura que he representado
            app.TituloFigura.Text=['Stereographic Projection Assigned Principal Poles: ',num2str(length(A)),' points'];

            % Ya he terminado, desbloqueo la gráfica
            hold(app.UIAxes_Stereo,'off');

        end
        
        function results = calcula_histograma(app)
            results=0;
            % Reseteo la gráfica
            cla(app.UIAxes_Hist,'reset');
            % tomo el DS
            ds=app.DSSpinner.Value;
            % Tomo los ángulos
            angulo_polo_principal=app.data.angulo_polo_principal(:,ds);
            % Tengo todos los ángulos, filtro a los que están dentro del
            % rango, o cono, como se mire
            rango=str2double(app.RangeEditField.Value)/180*pi;
            I=find(angulo_polo_principal(:,1)<=rango);
            angulos_rad=angulo_polo_principal(I,1);
            % X es la serie de ángulos calculados en grados (degree)
            X=angulos_rad*180/pi;
            h=histogram(app.UIAxes_Hist,X,'Normalization','pdf');
            app.data.histogram_maxvalue=max(h.Values);
            app.data.histogram_maxangle=h.BinEdges(h.Values==max(h.Values))+h.BinWidth/2;
            % Calculo el ajuste a la distribución de Poisson,
            % Exponencial y Log-Normal
            pd = fitdist(X,'Poisson');
            lambda=pd.lambda;
            pd = fitdist(X,'Exponential');
            mu_exp=pd.mu;
            pd = fitdist(X,"Lognormal");
            mu_logn=pd.mu;
            sigma_logn=pd.sigma;

            % Creo las funciones con los ajustes estimados
            % La distribución de Poisson es discreta
            x2=0:1:rango*180/pi;
            y2 = poisspdf(x2,lambda);
            N=100;
            x3=0:rango*180/pi/N:rango*180/pi;
            y3 = exppdf(x3,mu_exp);
            y4 = lognpdf(x3,mu_logn,sigma_logn);

            % Dibujo los datos con la selección el usuario: hist o densidad
            switch app.PlotKnob.Value
                case 'Histogram'
                    xlabel(app.UIAxes_Hist,'Angle');
                    % ylabel(app.UIAxes_Hist,'N'); % Una función de probabilidad no tiene dimensiones
                    title(app.UIAxes_Hist,['PDF of angles to the ppal pole. Max angle: ',num2str(app.data.histogram_maxangle),' degree']);
                case 'Density'
                    % Normalizo la densidad de ángulos
                    inc=h.BinWidth; % es el ancho de la barra del histograma
                    x=h.BinEdges+inc;
                    x=x(2:length(x)); % ya tengo la misma dimensión que los valores
                    y=h.Values./x; % relativizo los valores contados respecto al ángulo
                    % Para poder compararlo con la función de densidad,
                    % debo de normalizarla
                    area=sum(y)*inc;
                    y=y/area; % ya es una distribución unitaria
                    bar(app.UIAxes_Hist,x,y);
                    xlabel(app.UIAxes_Hist,'Angle');
                    ylabel(app.UIAxes_Hist,'Unitary density of angles');
                    title(app.UIAxes_Hist,['Unitary histogram, density of angles to the ppal pole within the range. Max angle: ',num2str(app.data.histogram_maxangle),' deg']);
                case '1D'
                    % en primer lugar reseteo la gráfica porque es nueva y
                    % asimétrica
                    cla(app.UIAxes_Hist,'reset');
                    % Preparo las coordenadas de los polos de esa familia
                    cone=str2double(app.RangeEditField.Value)/180*pi;
                    polos_estereo_cartesianas=app.data.polos_estereo_cartesianas; % todos los polos en cartesianas
                    polos_pples=app.data.ppal_poles; % los polos principales, pero en notación de clar
                    polos_pples_cart=zeros(size(polos_pples)); % inicio la matriz para almacenar las coordeadas de los polos principales
                    [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) ); % calculo las coordenadas de los ppples
                    app.data.polos_pples_cart=polos_pples_cart; % las guardo en la variable global
                    P=app.data.P; % variable necesaria para aplicar la siguiente función. Podría programar una función que pasara de P, pero no se pierde tanto t
                    [polos_estereoppalasignados, ~] = f_setppal2planes_v02( cone, polos_estereo_cartesianas, polos_pples_cart ,P);
                    A=polos_estereoppalasignados; % Estos son las coordenadas de los polos en cartesianas asignados a la familia seleccionada
                    % la familia que tengo seleccionada en el spinner es
                    % ds, que ya la tengo de antes
                    % me quedo con los polos de esa familia
                    A=A(A(:,3)==ds,1:2);
                    % Traslado las coordenadas al centro
                    A=A-polos_pples_cart(ds,1:2);
                    % el ángulo de rotación. Utilizo el atn de cuatro
                    % cuadrantes para tener la dirección, por si acaso
                    theta=atan2(polos_pples_cart(ds,2),polos_pples_cart(ds,1));
                    R=[cos(theta) sin(theta); -sin(theta) cos(theta)];
                    A=A*R; % Ya tengo los polos rotados.
                    % Los polos en X son en la dirección del buzamiento,
                    % los polos en Y son en la dirección.
                    % Defino un buffer, que ya veré si se mete en
                    % configuración
                    buffer=0.05; % es aprox 5degree
                    % Valores de la dipdir
                    I=find(A(:,2)<=buffer);
                    X_dipdir=A(and(A(:,2)<=buffer,A(:,2)>=-buffer),1);
                    X_strike=A(and(A(:,1)<=buffer,A(:,1)>=-buffer),2);
                    h1=histogram(app.UIAxes_Hist,X_dipdir*180/pi,'Normalization','pdf');
                    hold(app.UIAxes_Hist,'on');
                    h2=histogram(app.UIAxes_Hist,X_strike*180/pi,'Normalization','pdf');
                    hold(app.UIAxes_Hist,'off');

            end

            % si estoy en histograma on densidad, represento los ajustes,
            % pero si estoy en 1D sólo represento el ajuste normal
            switch app.PlotKnob.Value
                case {'Histogram', 'Density'}
                    hold(app.UIAxes_Hist,"on")
                    plot(app.UIAxes_Hist,x2,y2, x3, y3, x3,y4,'LineWidth',2);
                    legend(app.UIAxes_Hist,'Histogram',['Poisson Distribution; \lambda: ',num2str(lambda)],['Exponential \mu: ', num2str(mu_exp)],['Lognormal \mu: ', num2str(mu_logn),' \sigma: ',num2str(sigma_logn)],'location','northeast')
                    hold(app.UIAxes_Hist,"off")
                case '1D'
                    pd1 = fitdist(X_dipdir*180/pi,"Normal");
                    mu_n_dipdir=pd1.mu;
                    sigma_n_dipdir=pd1.sigma;
                    pd2 = fitdist(X_strike*180/pi,"Normal");
                    mu_n_strike=pd2.mu;
                    sigma_n_strike=pd2.sigma;
                    N=100;
                    x1=-rango/2*180/pi:rango*180/pi/N:rango/2*180/pi;
                    y1 = pdf(pd1,x1);
                    y2 = pdf(pd2,x1);
                    hold(app.UIAxes_Hist,"on")
                    plot(app.UIAxes_Hist,x1,y1, x1, y2, 'LineWidth',2);
                    legend(app.UIAxes_Hist,'Histogram Dip dir', 'Histogram Strike',['Dip direction; \mu: ',num2str(mu_n_dipdir), ' - \sigma:',num2str(sigma_n_dipdir)],['Strike \mu: ',num2str(mu_n_strike), ' - \sigma:',num2str(sigma_n_strike)],'location','northeast')
                    hold(app.UIAxes_Hist,"off")
            end


            % He calculado el histograma, pero no es eso lo que voy a
            % mostrar sino la distribución densidad de ángulos - ángulo

            % Guardo los resultados en el listbox
            salida = cell(7,1);
            salida{1} = ['DS: ',num2str(app.DSSpinner.Value)]; % DS
            salida{2} = ['Range: ',app.RangeEditField.Value]; % Rango de cálculo de ángulos
            salida{3} = ['Number of points within the Range: ', num2str(length(I))];
            salida{4} = ['(Dip Dir / Dip): ','(',num2str(floor(app.data.ppal_poles(app.DSSpinner.Value,1))),'/',num2str(floor(app.data.ppal_poles(app.DSSpinner.Value,2))),')'];
            salida{5} = ['Mean: ',num2str(mean(angulos_rad)*180/pi),' deg'];
            salida{6} = ['Max angle: ',num2str(app.data.histogram_maxangle),' deg'];
            salida{7} = ['Lognormal:'];
            salida{8} = ['* mean: ',num2str(mu_logn),'; std_dev: ',num2str(sigma_logn), '; median: ',num2str(exp(mu_logn))];
            %l3 = ['D: ',num2str(D_list(j)) ]; % D
            %l4 = ['Pdip: ',num2str(persistence_dip) ]; % persistencia dirección del dip
            %l5 = ['Pstrike: ',num2str(persistence_strike)]; % persistencia dirección de strike
            %l6 = ['Pmax: ',num2str(persistence_max) ]; % persistencia max
            %l7 = ['Parea: ',num2str(area) ]; % persistencia area
            app.ReportTextArea.Value=salida;
            
        end


        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, datos)
            % Este es el código que se ejecuta cuando se abre esta gui
            app.CallingApp=caller;

            % Toda la información la tengo en datos, la tengo que pasar a
            % app.data y trabajar con esta estructura hasta el final

            app.data.ppal_poles=datos.ppal_poles; % esta cambiará durante la ejecución del programa
            app.data.polos_pples_cart=datos.polos_pples_cart; % esta cambiará durante la ejecución del programa
            app.data.X=datos.X;
            app.data.Y=datos.Y;
            app.data.density=datos.density;
            app.data.polos_estereo_cartesianas=datos.polos_estereo_cartesianas;
            app.data.P=datos.P;

            % Preparo un backup de los polos principales para la función de
            % restaurar
            app.data.backup.ppal_poles=datos.ppal_poles; % esta cambiará durante la ejecución del programa
            app.data.backup.polos_pples_cart=datos.polos_pples_cart; % esta cambiará durante la ejecución del programa

            % Represento la función
            plotdensity(app);

            % Preparo el slider para navegar por las familias
            [n,~]=size(app.data.ppal_poles);
            app.DSSpinner.Limits=[1 n];
            
            % Calculo los ángulos para todos los polos principales

            % tomo el DS
            % familia=app.DSSpinner.Value;
            % ds=app.data.ppal_poles(familia,1:2); % dip dir y dip de la familia principal
            [nppal,~]=size(app.data.ppal_poles); % saco el número de familias principales
            % La siguiente función f_pole2vnor necesita las coordenadas
            [VnPpalPole]=f_pole2vnor( app.data.polos_pples_cart(:,1:2)); % vector normal de la familia ppal seleccionada por defecto, la 1

            
			[VnPolos]=f_pole2vnor(app.data.polos_estereo_cartesianas); % Vectores normales de los todos los polos
			

            % Calculo el ángulo que forma cada polo a su polo principal
			[n,~]=size(VnPolos); % número de puntos, y de polos también
			angulo_polo_principal=zeros(n,nppal); % inicio la matriz en la que guardo los ángulos polo ppal - polo; cada columna un polo ppal
            % Calcular ángulo polo al polo principal
            for ii=1:n % recorre todos los polos
                vpx=VnPolos(ii,1); 
                vpy=VnPolos(ii,2);
                vpz=VnPolos(ii,3);
                for jj=1:nppal % recorro todos los polos principales
                    vppx=VnPpalPole(jj,1);
                    vppy=VnPpalPole(jj,2);
                    vppz=VnPpalPole(jj,3);
                    proj=vpx*vppx+vpy*vppy+vpz*vppz;
                    angulo=acos(proj);
                    if angulo>=pi/2
                        angulo=pi-angulo;
                    end
                    angulo_polo_principal(ii,jj)=angulo;
                end
            end
            % Guardo los ángulos
            app.data.angulo_polo_principal=angulo_polo_principal;

            % Que me calcule los histogramas del primero
            calcula_histograma(app);

            % Que me dibuje la estereográfica por defecto
            PlotButtonGroupSelectionChanged(app);
        end

        % Value changed function: DSSpinner
        function DSSpinnerValueChanged(app, event)
            % value = app.DSSpinner.Value;
            calcula_histograma(app);
            PlotButtonGroupSelectionChanged(app);
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)

            % Función que representa lo que esté seleccionado

            % Voy a mantener el punto de vista de la gráfica
            % en primer lugar, si está activada la densidad, pillo la vista
            % para mantenerla
            View=app.UIAxes_Stereo.View;
            Projection=app.UIAxes_Stereo.Projection;
            CameraPosition=app.UIAxes_Stereo.CameraPosition;
            CameraPositionMode=app.UIAxes_Stereo.CameraPositionMode;
            CameraTarget=app.UIAxes_Stereo.CameraTarget;
            CameraTargetMode=app.UIAxes_Stereo.CameraTargetMode;
            CameraUpVector=app.UIAxes_Stereo.CameraUpVector;
            CameraUpVectorMode=app.UIAxes_Stereo.CameraUpVectorMode;
            CameraViewAngle=app.UIAxes_Stereo.CameraViewAngle;
            CameraViewAngleMode=app.UIAxes_Stereo.CameraViewAngleMode;

            selectedButton = app.PlotButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Density'
                    plotdensity(app);
                case 'Poles'
                    plotpoles(app);
                case 'Poles DS'
                    plotpolesds(app);
            end

            % Finalmente, restituyo el punto de vista
            app.UIAxes_Stereo.View=View;
            app.UIAxes_Stereo.Projection=Projection;
            app.UIAxes_Stereo.CameraPosition=CameraPosition;
            app.UIAxes_Stereo.CameraPositionMode=CameraPositionMode;
            app.UIAxes_Stereo.CameraTarget=CameraTarget;
            app.UIAxes_Stereo.CameraTargetMode=CameraTargetMode;
            app.UIAxes_Stereo.CameraUpVector=CameraUpVector;
            app.UIAxes_Stereo.CameraUpVectorMode=CameraUpVectorMode;
            app.UIAxes_Stereo.CameraViewAngle=CameraViewAngle;
            app.UIAxes_Stereo.CameraViewAngleMode=CameraViewAngleMode;
        end

        % Value changed function: PlotKnob
        function PlotKnobValueChanged(app, event)
            % value = app.PlotKnob.Value;
            calcula_histograma(app);
        end

        % Value changing function: RangeEditField
        function RangeEditFieldValueChanging(app, event)
            % changingValue = event.Value;
            calcula_histograma(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Stereo = uiaxes(app.UIFigure);
            title(app.UIAxes_Stereo, 'Title')
            xlabel(app.UIAxes_Stereo, 'X')
            ylabel(app.UIAxes_Stereo, 'Y')
            zlabel(app.UIAxes_Stereo, 'Z')
            app.UIAxes_Hist = uiaxes(app.UIFigure);
            title(app.UIAxes_Hist, 'Title')
            xlabel(app.UIAxes_Hist, 'X')
            ylabel(app.UIAxes_Hist, 'Y')
            zlabel(app.UIAxes_Hist, 'Z')
            app.DSSpinnerLabel = uilabel(app.UIFigure);
            app.DSSpinnerLabel.Text = 'DS';
            app.DSSpinner = uispinner(app.UIFigure);
            app.DSSpinner.ValueChangedFcn = createCallbackFcn(app, @DSSpinnerValueChanged, true);
            app.DSSpinner.Tooltip = {'Select the DS to calculate the statistic parameters'};
            app.TituloFigura = uilabel(app.UIFigure);
            app.TituloFigura.Text = 'Figure';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'text');
            app.RangeEditField.ValueChangingFcn = createCallbackFcn(app, @RangeEditFieldValueChanging, true);
            app.RangeEditField.Value = '30';
            app.ReportTextAreaLabel = uilabel(app.UIFigure);
            app.ReportTextAreaLabel.Text = 'Report';
            app.ReportTextArea = uitextarea(app.UIFigure);
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.PlotButtonGroup.Title = 'Plot';
            app.DensityButton = uiradiobutton(app.PlotButtonGroup);
            app.DensityButton.Text = 'Density';
            app.DensityButton.Value = true;
            app.PolesButton = uiradiobutton(app.PlotButtonGroup);
            app.PolesButton.Text = 'Poles';
            app.PolesDSButton = uiradiobutton(app.PlotButtonGroup);
            app.PolesDSButton.Text = 'Poles DS';
            app.PlotKnobLabel = uilabel(app.UIFigure);
            app.PlotKnobLabel.Text = 'Plot';
            app.PlotKnob = uiknob(app.UIFigure, 'discrete');
            app.PlotKnob.Items = {'Histogram', 'Density', '1D'};
            app.PlotKnob.ValueChangedFcn = createCallbackFcn(app, @PlotKnobValueChanged, true);
            app.PlotKnob.Value = 'Histogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_density_stats(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end