classdef gui_ppalpoleseditor_v10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditorofprincipalpolesUIFigure  matlab.ui.Figure
        PlotPanel                       matlab.ui.container.Panel
        Button_4                        matlab.ui.control.Button
        NormalisedcontourCheckBox       matlab.ui.control.CheckBox
        PlotLabelsCheckBox              matlab.ui.control.CheckBox
        ProjectionDropDown              matlab.ui.control.DropDown
        ProjectionDropDownLabel         matlab.ui.control.Label
        StereonetDropDown               matlab.ui.control.DropDown
        StereonetDropDownLabel          matlab.ui.control.Label
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        PolesDSButton                   matlab.ui.control.StateButton
        NewWindowButton                 matlab.ui.control.Button
        DensityPanel                    matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        BandwidthEditField              matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel         matlab.ui.control.Label
        BinsSpinner                     matlab.ui.control.Spinner
        BinsSpinnerLabel                matlab.ui.control.Label
        RotationanglesPanel             matlab.ui.container.Panel
        AngulosUITable                  matlab.ui.control.Table
        DensityandpeakssettingsPanel    matlab.ui.container.Panel
        MindensityEditField             matlab.ui.control.NumericEditField
        MindensEditFieldLabel           matlab.ui.control.Label
        NmaxDSEditField                 matlab.ui.control.NumericEditField
        NmaxDSEditFieldLabel            matlab.ui.control.Label
        MinangleEditField               matlab.ui.control.NumericEditField
        MinangleEditFieldLabel          matlab.ui.control.Label
        ConeEditField                   matlab.ui.control.NumericEditField
        ConeEditFieldLabel              matlab.ui.control.Label
        Label_Percentage                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditPanel                       matlab.ui.container.Panel
        DSEditField                     matlab.ui.control.NumericEditField
        DSEditFieldLabel                matlab.ui.control.Label
        Insert                          matlab.ui.control.Image
        MoveUp                          matlab.ui.control.Image
        MoveDown                        matlab.ui.control.Image
        Delete                          matlab.ui.control.Image
        Restart                         matlab.ui.control.Image
        PrincipalPoleMethodButtonGroup  matlab.ui.container.ButtonGroup
        DensitypeakButton               matlab.ui.control.RadioButton
        TituloFigura                    matlab.ui.control.Label
        UITable_Densidad                matlab.ui.control.Table
        SaveExitButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp % Es la función que llama a esta ventana, me permite identificarla cuando al final llame al updatefunction
        data % donde voy a guardar todos mis datos durant este programa
        % Los datos que recibo son:
        % datos.polos_pples % los polos pples, en dip dir y dip
        % datos.polos_pples_cart % los polos pples en XY
        % datos.polos_estereo_cartesianas % Son todos los polos en XY
        % datos.P la matriz de puntos
		% La densidad, tampoco la pasaría porque creo que es mejor calcularla
        % datos.X
        % datos.Y
        % datos.density
    end
    
    methods (Access = private)
        % Estas son las funciones que voy a utilizar en la GUI

        function results = plot_contours(app)
            % Función que representa la función de densidad con los nuevos polos principales
            % Primero me preparo los datos:
            % Miro qué proyección tengo selecionada
            projection=app.ProjectionDropDown.Value;
            polos_pples = app.data.polos_pples;
            % cargo los datos de la densidad, ya computados con la
            % proyección seleccionada
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;

            % A los polos principales, les pongo el punto que lo muestra
            % Tengo que calcular su valor de densidad
            % polos_cartesianas_proyectadas_rotadas=app.data.polos_pples_cartesianas_proyectados_rotados; % Viene de ActualizaTodo, ya están rotados y en sus proyección

            polos_pples_cart=zeros(size(app.UITable_Densidad.Data(:,1:3)));
            % convierto de Clar a cartesianas
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( app.UITable_Densidad.Data(:,1),app.UITable_Densidad.Data(:,2) , app.ProjectionDropDown.Value);
            % Tomo los polos principales, y lo roto si estoy en rotación
            switch app.StereonetDropDown.Value
                case 'Rotated'
                    R = f_calcula_matriz_rotacion(app.AngulosUITable.Data);
                    [polos_pples_cart(:,1),polos_pples_cart(:,2)] = f_rotarpoloscartesianas(polos_pples_cart(:,1),polos_pples_cart(:,2),R,projection);
                case 'Original'
                    % No tengo que rotar
                otherwise
                    % Algo ha fallado
            end
            polos_cartesianas_proyectadas_rotadas=polos_pples_cart; % Le paso los polos principales transformados
            % Calculo la elevación del punto
            polos_cartesianas_proyectadas_rotadas(:,3)=griddata(X,Y,density,polos_cartesianas_proyectadas_rotadas(:,1),polos_cartesianas_proyectadas_rotadas(:,2),app.data.interpolacionpicos);
         
            % Vemos si tengo que representar en ventana emergente o en la GUI
            switch app.data.newwindow
                case 1
                    h=figure; % voy a nueva ventana
                    h.Color=[1 1 1]; % Paso a fondo en blanco
                    ha=gca; % Tengo que trabajar con estos Axis
                case 0
                    ha=app.UIAxes; % Le dejo los Axis de la GUI
                otherwise
                    % Algo va mal
            end

            cla(ha,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app,ha,projection);
            % conservo la gráfica para que todo se dibuje encima
            hold(ha,'on')

            % Pongo el punto del polo principal
            [kk, nc]=size(polos_cartesianas_proyectadas_rotadas);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            scatter3(ha,polos_cartesianas_proyectadas_rotadas(1:kk,1),polos_cartesianas_proyectadas_rotadas(1:kk,2),polos_cartesianas_proyectadas_rotadas(1:kk,3) ,tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0.6350 0.0780 0.1840]);
            switch app.PlotLabelsCheckBox.Value
                case 1
                    % Pongo el label si procede
                    desfasando_en_x=0.05;
                    desfasando_en_y=0.05;
                    desfasando_en_z=0.05;
                    xa=polos_cartesianas_proyectadas_rotadas(1:kk,1)'+desfasando_en_x;
                    xb=polos_cartesianas_proyectadas_rotadas(1:kk,2)'+desfasando_en_y;
                    if nc==2
                        xc=polos_cartesianas_proyectadas_rotadas(1:kk,3)+0;
                    else
                        % xc=polos_pples_cart(1:kk,3)'+0.1;
                        xc(1:kk)=polos_cartesianas_proyectadas_rotadas(1:kk,3)+desfasando_en_z;
                    end

                    % Ahora inserto la etiqueta para ver el polo principal que es
                    for ii=1:kk
                        if isnan(xc(ii))
                            elevacion_punto=0.1;
                        else
                            elevacion_punto=xc(ii);
                        end
                        text(ha,xa(ii),xb(ii),elevacion_punto, ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
                    end
                otherwise
                    % No pongo el label
            end

            % Dibujo las isolineas: CONTOUR PLOT
            numberisolines=80; % número de isoloníneas
            contour3(ha,X,Y,density,numberisolines);
            axis (ha,[-1 1 -1 1]); axis(ha,'square');
            % Doy formato a la gráfica
            app.TituloFigura.Text=['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'];
            xlabel(ha,'axis X'); ylabel(ha,'axis Y');

            % El tema del puntero no está solucionado con el APPDesigner.
            % Habría que hacerlo en una ventana emergente
            % Tengo que ver si represento el original
            switch app.data.newwindow
                case 1
                    % Estoy en emergente, pongo puntero.
                    % También configuro el título de la ventana
                    switch app.StereonetDropDown.Value
                        case 'Rotated'
                            angulos=app.AngulosUITable.Data;
                            % angulos=zeros(size(angulos));
                        case 'Original'
                            % Dejo los ángulos como están
                            angulos=[0 0 0];
                        otherwise
                            % Algo sale mal
                    end
                    % Pongo el título a la ventana
                    h.Name=['Contours of poles: ', app.StereonetDropDown.Value,'; ', projection];
                    % Pongo un título, porque estoy en una ventanta popup
                    ha.Title.String={['Poles Density Plot, Principal Poles. Isolines each ',num2str(100/numberisolines),'%'],'',''}; % Le añado dos líneas, mejor que una línea

                    % Configuro el cursor
                    dcm = datacursormode(gcf);
                    set(dcm,'updatefcn',{@myupdatefcn_rotate,angulos,projection} )
                case 0
                    % Estoy en GUI, no pongo puntero
                otherwise
                    % Algo va mal
            end
            
            % Ya he terminado, desbloqueo la gráfica
            hold(ha,'off')

            % Si estoy en el popup, guardo la figura
            switch app.data.newwindow
                case 1
                    % Guardo el fichero de la nube
                    fichero_pdf=[app.data.pathname, app.data.filename, ' Density - ',app.StereonetDropDown.Value,'.pdf'];
                    fichero_fig=[app.data.pathname, app.data.filename, ' Density - ',app.StereonetDropDown.Value,'.fig'];
                    fichero_png=[app.data.pathname, app.data.filename, ' Density - ',app.StereonetDropDown.Value,'.png'];
                    % pcwrite(ptCloud,fichero,'PLYFormat','binary');
                    savefig(h,fichero_fig); 
                    saveas(h,fichero_pdf);
                    saveas(h,fichero_png);
                otherwise
                    % No guardo nada
            end

            results=0;
        end
        
        function results = falsillariquelme(app,figura,projection)
            results=0;
            % Riquelme -- Program for plotting a stereonet for DSE
            % Written by Adrian Riquelme, September 2016
            % ariquelme@ua.es
            % Department of Civil Engineering, University of Alicante, Spain
            % - Figura es el handle de donde voy a dibujar la falsilla
            % - projection es el tipo del estereograma: Equal-angle o Equal-area

            % First, we define the circle and the axis
            % Code extracted from the wulff net
            N = 50;
            cx = cos(0:pi/N:2*pi);                           % points on circle
            cy = sin(0:pi/N:2*pi);
            xh = [-1 1];                                     % horizontal axis
            yh = [0 0];
            xv = [0 0];                                      % vertical axis
            yv = [-1 1];
            plot(figura,xh,yh,'-.k',xv,yv,'-.k');                     %plot green axes
            hold(figura,"on")
            plot(figura,cx,cy,'--k');                                %plot white circle
            axis(figura,[-1 1 -1 1],'square','off');

            % Now, we draw the circles that refer to the dip angle
            psi = [0:pi/N:2*pi];
            beta=[15 30 45 60 75 90]/180*pi;
            [~,n]=size(beta);
            for i = 1:(n-1)                                   % plot dip circles
                [radio]=f_radio(app,beta(i),projection);
                x = radio .* sin(psi);
                y = radio .* cos(psi);
                plot(figura,x,y,':r');
                text(figura,radio,0,num2str(beta(i)*180/pi),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);
            end
            % Inserto el label del buzamiento de 90
            [radio]=f_radio(app,90*pi/180,projection);
            text(figura,radio,0,num2str(90),'HorizontalAlignment','Left','Verticalalignment','bottom','FontSize',8);
            axis(figura,'square');
            % Finally, we draw the circles that refer to the dip direction
            alpha0 = 0:pi/6:(2*pi-pi/12);
            alpha = pi/2 - alpha0+pi;
            etiqueta = 0:360/12:(330);
            x = 1.2*cos(alpha);
            y = 1.2*sin(alpha);
            [~,n]=size(alpha);
            tamanyoangulos=12;
            for i=1:n
                % le pongo su etiqueta el ángulo respecto al norte
                if etiqueta(i)==0 || etiqueta(i)==180
                    text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','center','VerticalAlignment','cap','FontSize',tamanyoangulos);
                else
                    if etiqueta(i)>0 && etiqueta(i)<180
                        text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Right','FontSize',tamanyoangulos);
                    else
                        text(figura,x(i),y(i),num2str(etiqueta(i)),'HorizontalAlignment','Left','FontSize',tamanyoangulos);
                    end
                end       
                
                xaux=[f_radio(app,15*pi/180,projection)*cos(alpha(i))  cos(alpha(i))];
                yaux=[f_radio(app,15*pi/180,projection)*sin(alpha(i))  sin(alpha(i))];
                plot(figura,xaux,yaux,'r:.');
                % pongo una pequeña línea para marcar dónde va ese label
                xaux=[cos(alpha(i))  1.05*cos(alpha(i))];
                yaux=[sin(alpha(i))  1.05*sin(alpha(i))];
                plot(figura,xaux,yaux,'k-');
                
            end
            hold(figura,"off")
        end
        
        function results = plot_poles(app)
            results=0;
            % Preparo los datos
            proyeccion=app.ProjectionDropDown.Value;
            polos_pples = app.data.polos_pples;
            polos_cartesianas_proyectadas_rotadas=app.data.polos_cartesianas_proyectadas_rotadas;

            % Vemos si tengo que representar en ventana emergente o en la GUI
            switch app.data.newwindow
                case 1
                    h=figure; % voy a nueva ventana
                    h.Color=[1 1 1]; % Paso a fondo en blanco
                    ha=gca; % Tengo que trabajar con estos Axis
                case 0
                    ha=app.UIAxes; % Le dejo los Axis de la GUI
                otherwise
                    % Algo va mal
            end

            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            cla(ha,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app, ha, app.ProjectionDropDown.Value);
            % conservo la gráfica para que todo se dibuje encima
            hold(ha,'on')
            z=zeros(size(polos_cartesianas_proyectadas_rotadas(:,1)));
            z(:,1)=0; % originalmente, -0.1
            % Dibujo todos los polos
            tamanyopunto=1;
            scatter3(ha,polos_cartesianas_proyectadas_rotadas(:,1),polos_cartesianas_proyectadas_rotadas(:,2),z,tamanyopunto);
            % Pongo un punto donde está cada polo principal.
            % Empiezo preparando coordenadas de los polos pples
            % Primero miro si estoy en original o rotada, para rotar o no
            % los polos principales
            switch app.StereonetDropDown.Value
                case 'Original'
                    polos_pples_cart=zeros(size(polos_pples));
                    [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2), app.ProjectionDropDown.Value );
                case 'Rotated'
                    % Ya puedo rotar los polos
                    polos_pples_cart=zeros(size(polos_pples));
                    [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) , app.ProjectionDropDown.Value);
                    R = f_calcula_matriz_rotacion(app.AngulosUITable.Data);
                    [polos_pples_cart(:,1),polos_pples_cart(:,2)] = f_rotarpoloscartesianas(polos_pples_cart(:,1),polos_pples_cart(:,2),R,proyeccion);

                otherwise
                    % Algo falla
            end
            % Pongo el punto del polo principal
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            scatter3(ha,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),0 ,tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0.6350 0.0780 0.1840]);
            switch app.PlotLabelsCheckBox.Value
                case 1
                    % Pongo el label si procede
                    desfasando_en_x=0.05;
                    desfasando_en_y=0.05;
                    desfasando_en_z=0.05;
                    xa=polos_pples_cart(1:kk,1)'+desfasando_en_x;
                    xb=polos_pples_cart(1:kk,2)'+desfasando_en_y;
                    if nc==2
                        xc=0;
                    else
                        % xc=polos_pples_cart(1:kk,3)'+0.1;
                        xc(1:kk)=desfasando_en_z;
                    end
                    % Ahora inserto la etiqueta para ver el polo principal que es
                    for ii=1:kk
                        text(ha,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
                    end
                otherwise
                    % No pongo el label
            end

            % Preparo la información de la figura que he representado
            app.TituloFigura.Text=['Stereographic Projection, Normal Vector Poles: ',num2str(length(polos_cartesianas_proyectadas_rotadas)),' points'];

            % % El tema del puntero no está solucionado con el APPDesigner.
            % Habría que hacerlo en una ventana emergente
            % % Tengo que ver si represento el original
            % switch app.StereonetDropDown.Value
            %     case 'Original'
            %         angulos=app.AngulosUITable.Data;
            %         angulos=zeros(size(angulos));
            %     case 'Rotated'
            %         % Dejo los ángulos como están
            %         angulos=[0 0 0];
            %     otherwise
            %         % Algo sale mal
            % end
            % % dcm = datacursormode(gcf);
            % % datacursormode on;
            % datacursormode(app.UIAxes,'on');
            % % dcm = datacursormode(gcf);
            % set(app.UIAxes,'updatefcn',{@myupdatefcn_rotate,angulos} )
            
            % Ya he terminado, desbloqueo la gráfica
            hold(ha,'off')
        end
        
        function results = plot_poles_ds(app)
            results=0;
            % Preparo los datos
            proyeccion=app.ProjectionDropDown.Value;
            cone=app.ConeEditField.Value/180*pi;
            polos_cartesianas_proyectadas_rotadas=app.data.polos_cartesianas_proyectadas_rotadas;
            polos_pples_cart=zeros(size(app.UITable_Densidad.Data(:,1:3)));
            % convierto de Clar a cartesianas
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( app.UITable_Densidad.Data(:,1),app.UITable_Densidad.Data(:,2) , app.ProjectionDropDown.Value);
            % Tomo los polos principales, y lo roto si estoy en rotación
            switch app.StereonetDropDown.Value
                case 'Rotated'
                    R = f_calcula_matriz_rotacion(app.AngulosUITable.Data);
                    [polos_pples_cart(:,1),polos_pples_cart(:,2)] = f_rotarpoloscartesianas(polos_pples_cart(:,1),polos_pples_cart(:,2),R,proyeccion);
                case 'Original'
                    % No tengo que rotar
                otherwise
                    % Algo ha fallado
            end
            [polos_cartesianas_proyectadas_rotadas_ppalasignados, ~] = f_setppal2planes_v02( cone, polos_cartesianas_proyectadas_rotadas, polos_pples_cart(:,1:2) ,app.data.P,proyeccion);
            A=polos_cartesianas_proyectadas_rotadas_ppalasignados;
            
            % Vemos si tengo que representar en ventana emergente o en la GUI
            switch app.data.newwindow
                case 1
                    h=figure; % voy a nueva ventana
                    h.Color=[1 1 1]; % Paso a fondo en blanco
                    ha=gca; % Tengo que trabajar con estos Axis
                case 0
                    ha=app.UIAxes; % Le dejo los Axis de la GUI
                otherwise
                    % Algo va mal
            end
            
            % dibujamos la plantilla de wulff, ahora la de Riquelme :D
            cla(ha,'reset'); % limpiamos las figuras existentes
            falsillariquelme(app, ha, app.ProjectionDropDown.Value);
            % conservo la gráfica para que todo se dibuje encima
            hold(ha,'on');
            
            % Dibujo los polos según familias
            tamanyopunto=1;
            % Separar puntos por categoría
            puntos_cero = A(A(:, 3) == 0, :);
            puntos_no_cero = A(A(:, 3) > 0, :);
            % Dibuja los puntos con categoría 0 (gris)
            if ~isempty(puntos_cero)
                scatter(ha, puntos_cero(:, 1), puntos_cero(:, 2), tamanyopunto, [0.5 0.5 0.5], 'filled'); % 20 es el tamaño de los puntos
                % hold(ha, 'on'); % Mantener los ejes para añadir más gráficos
            end
            % Dibujo los puntos con categoría mayor que 0 (escala de colores)
            if ~isempty(puntos_no_cero)
                nds = max(puntos_no_cero(:, 3));
                map = aux_colorcet('R4', 'N', nds); % Asumiendo que aux_colorcet está en el path
                colormap(ha, map);
                scatter(ha, puntos_no_cero(:, 1), puntos_no_cero(:, 2), tamanyopunto, puntos_no_cero(:, 3), 'filled');
            end

            % Pongo el punto del polo principal
            [kk, nc]=size(polos_pples_cart);
            if nc==2
                tamanyo=1;
            else
                tamanyo = 20;
            end
            scatter3(ha,polos_pples_cart(1:kk,1),polos_pples_cart(1:kk,2),0 ,tamanyo,'filled','MarkerEdgeColor','k','MarkerFaceColor',[0.6350 0.0780 0.1840]);
            switch app.PlotLabelsCheckBox.Value
                case 1
                    % Pongo el label si procede
                    desfasando_en_x=0.05;
                    desfasando_en_y=0.05;
                    desfasando_en_z=0.05;
                    xa=polos_pples_cart(1:kk,1)'+desfasando_en_x;
                    xb=polos_pples_cart(1:kk,2)'+desfasando_en_y;
                    if nc==2
                        xc=0;
                    else
                        % xc=polos_pples_cart(1:kk,3)'+0.1;
                        xc(1:kk)=desfasando_en_z;
                    end
                    % Ahora inserto la etiqueta para ver el polo principal que es
                    for ii=1:kk
                        text(ha,xa(ii),xb(ii),xc(ii), ['J_{',num2str(ii),'}'],'FontSize',18,'EdgeColor','k','BackgroundColor','w');
                    end
                otherwise
                    % No pongo el label
            end

            % Preparo la información de la figura que he representado
            app.TituloFigura.Text=['Stereographic Projection Assigned Principal Poles: ',num2str(length(A)),' points'];

            % Ya he terminado, desbloqueo la gráfica
            hold(ha,'off');


            % % El tema del puntero no está solucionado con el APPDesigner.
            % Habría que hacerlo en una ventana emergente
            % % Tengo que ver si represento el original
            % switch app.StereonetDropDown.Value
            %     case 'Original'
            %         angulos=app.AngulosUITable.Data;
            %         angulos=zeros(size(angulos));
            %     case 'Rotated'
            %         % Dejo los ángulos como están
            %         angulos=[0 0 0];
            %     otherwise
            %         % Algo sale mal
            % end
            % % dcm = datacursormode(gcf);
            % % datacursormode on;
            % datacursormode(app.UIAxes,'on');
            % % dcm = datacursormode(gcf);
            % set(app.UIAxes,'updatefcn',{@myupdatefcn_rotate,angulos} )
            
        end
        
        function results = UpdateTable(app)
            results=0;
            proyeccion=app.ProjectionDropDown.Value;
            % Se aplica cuando cambiamos los polos principales, y recalcula
            % el % de asignación de puntos a cada polo ppal
            % Preparo las variables
            cone = app.ConeEditField.Value/180*pi;
            polos_cartesianas_proyectadas_rotadas = app.data.polos_cartesianas_proyectadas_rotadas;
            polos_pples=app.data.polos_pples;
            polos_pples_cart=zeros(size(polos_pples));
            [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( polos_pples(:,1),polos_pples(:,2) , app.ProjectionDropDown.Value);
            P=app.data.P;
            % Actualizo también el valor de la densidad, por si lo he rotado
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;
            dipdir=app.UITable_Densidad.Data(:,1); % Orientaciones de polos principales
            dip=app.UITable_Densidad.Data(:,2);
            projection=app.ProjectionDropDown.Value;
            [xq, yq]=f_clar2cart(dipdir,dip,projection); % Coordenadas de polos principales en SR original
            % Si estoy en rotado, hay que rotar los polos
            switch app.StereonetDropDown.Value
                case 'Rotated'
                    angulos=app.AngulosUITable.Data;
                    R = f_calcula_matriz_rotacion(angulos);
                    % Calculo los vectores normales, rotando los polos originales
                    [xq,yq] = f_rotarpoloscartesianas(xq,yq,R,proyeccion); % coordenadas de polos principales rotadas
            end

            % Guardo las coordenadas de los polos principales en el estado
            % de original o rotado
            app.data.polos_pples_cartesianas_proyectados_rotados=[xq yq];
            % Actualizo los valores de densidad
            zq=griddata(X,Y,density,xq,yq,app.data.interpolacionpicos);
            app.UITable_Densidad.Data(:,3)=zq;

            % Asigno cada punto a su polo principal
            [polos_estereoppalasignados, ~] = f_setppal2planes_v02( cone, polos_cartesianas_proyectadas_rotadas, polos_pples_cart ,P,proyeccion);
            [n,~]=size(P); % número de puntos totales
            [nf,~]=size(polos_pples); % número de familias que hay
            for i=1:nf
                % calculo el % de puntos que hay y lo pongo en la cuarta columna
                [npuntos,~]=size(polos_estereoppalasignados(polos_estereoppalasignados(:,3)==i));
                polos_pples(i,4)=npuntos/n*100;
            end
            % Calculo el total de puntos asignados a los polos
            totalpoints=sum(polos_pples(:,4));
            app.Label_Percentage.Text=num2str(totalpoints);
            % set(app.data.text_totalpercent,'String',['Total %: ',num2str(totalpoints)]);
            % actualizo la tabla de salida
            app.UITable_Densidad.Data=polos_pples;
            app.data.polos_pples=polos_pples; % Guardo la edición
            
        end
        
        function results = plot(app)
            results=0;
            % Función que representa lo que esté seleccionado
            % Voy a mantener el punto de vista de la gráfica
            % en primer lugar, si está activada la densidad, pillo la vista
            % para mantenerla
            View=app.UIAxes.View;
            Projection=app.UIAxes.Projection;
            CameraPosition=app.UIAxes.CameraPosition;
            CameraPositionMode=app.UIAxes.CameraPositionMode;
            CameraTarget=app.UIAxes.CameraTarget;
            CameraTargetMode=app.UIAxes.CameraTargetMode;
            CameraUpVector=app.UIAxes.CameraUpVector;
            CameraUpVectorMode=app.UIAxes.CameraUpVectorMode;
            CameraViewAngle=app.UIAxes.CameraViewAngle;
            CameraViewAngleMode=app.UIAxes.CameraViewAngleMode;
            
            if app.PolesDSButton.Value==1
                plot_poles_ds(app); % Dibujo los polos asignados a un DS
            elseif app.Button_3.Value==1
                plot_poles(app); % Dibujo los polos
            elseif app.Button_2.Value==1
                plot_contours(app); % Dibujo la densidad en contourplot
            else % no se ha pulsado ningún botón de representación, pero tengo que dibujar, así que dibujo la densidad
                plot_poles_ds(app); % Dibujo los polos asignados a un DS
            end

            % Finalmente, restituyo el punto de vista
            app.UIAxes.View=View;
            app.UIAxes.Projection=Projection;
            app.UIAxes.CameraPosition=CameraPosition;
            app.UIAxes.CameraPositionMode=CameraPositionMode;
            app.UIAxes.CameraTarget=CameraTarget;
            app.UIAxes.CameraTargetMode=CameraTargetMode;
            app.UIAxes.CameraUpVector=CameraUpVector;
            app.UIAxes.CameraUpVectorMode=CameraUpVectorMode;
            app.UIAxes.CameraViewAngle=CameraViewAngle;
            app.UIAxes.CameraViewAngleMode=CameraViewAngleMode;
        end
        
        function results = UpdateTableDS(app,ds)
            results=0;
            % todo los datos del ds que acabo de modificar y actualizo la densidad, tabla y
            % representación
            X=app.data.X;
            Y=app.data.Y;
            density=app.data.density;
            dipdir=app.UITable_Densidad.Data(ds,1);
            dip=app.UITable_Densidad.Data(ds,2);
            projection=app.ProjectionDropDown.Value;
            [xq, yq]=f_clar2cart(dipdir,dip,projection);
            zq=griddata(X,Y,density,xq,yq,app.data.interpolacionpicos);
            app.UITable_Densidad.Data(ds,3)=zq;

            % guardar la tabla
            app.data.polos_pples=app.UITable_Densidad.Data;
            % Actualizo todo
            normales=app.data.normales; % vectores normales cargados
            rotacion=app.StereonetDropDown.Value;
            proyeccion=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            normaliza=app.NormalisedcontourCheckBox.Value;
            calculapicosdensidad=0; % Al actualizar tabla, no calculo picos
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicosdensidad);

        end
        
        function results = rota_polosydensidad(app)
            % Rota los polos y calcula la densidad
            % Original machaca los datos al inicio directamente
            proyeccion=app.ProjectionDropDown.Value;
            normaliza=app.NormalisedcontourCheckBox.Valued;
            proyeccion=app.ProjectionDropDown.Value;
            switch app.StereonetDropDown.Value
                case 'Original'
                    % Como está seleccionada la original, lo machaco todo
                    % app.data.polos_pples_cart=app.data.polos_pples_cart;
                    app.data.X=app.data.X_original;
                    app.data.Y=app.data.Y_original;
                    app.data.density=app.data.density_original;
                    app.data.polos_estereo_cartesianas=app.data.polos_estereo_cartesianas_original;
                    % Los polos principales siguen siendo los mismos
                case 'Rotated'
                    % Tengo que rotar todo el sistema original
                    angulos=app.AngulosUITable.Data;
                    R = f_calcula_matriz_rotacion(angulos);
                    % Calculo los vectores normales, rotando los polos originales
                    [pcx_r,pcy_r] = f_rotarpoloscartesianas(app.data.polos_estereo_cartesianas_original(:,1),app.data.polos_estereo_cartesianas_original(:,2),R,proyeccion);
                    app.data.polos_estereo_cartesianas(:,1)=pcx_r;
                    app.data.polos_estereo_cartesianas(:,2)=pcy_r;

                    % Ahora voy con la densidad
                    nsec=2^app.BinsSpinner.Value;
                    angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
                    maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
                    % [~, ~,app.data.density, app.data.X, app.data.Y]=f_prepara_planos_kde(app.data.polos_estereo_cartesianas,nsec, angulovpples, maxpples);
                    [~, ~, app.data.density, app.data.X, app.data.Y]=f_densidad_y_polospples_v00(app.data.polos_estereo_cartesianas,nsec, angulovpples, maxpples,normaliza,proyeccion);
                    % Nota: polos_pples y polos_pples_cart no hacen falta
                    % porque ya trabajo con la tabla
            end
            % Con todo rotado, actualizo tabla y gráfica
            % Represento la función
            plot(app);
            % Actualizo la tabla
            UpdateTable(app);
            results=0;
        end
        
        function [radio] = f_radio(app,beta,projection)
            % Función que calcula el radio del círculo en función del ángulo de buzamiento
            switch projection
                case 'Equal-angle'
                    radio = sin(beta)/(1+cos(beta));
                case 'Equal-area'
                    radio = 2^(0.5)*sin(beta/2);
                case 'Equal-proportion'
                    radio = beta / (pi/2);
                otherwise
                    % Algo falla
            end
        end
        
        function [polos_estereo_polares, polos_estereo_cartesianas,polos_pples, polos_pples_cart,density, X, Y] = f_calcula_polos_densidad(app)
            % Se supone que se ha cambiado la proyección, por lo que se
            % calcula de nuevo los polos y la densidad
            % Primero calculo los polos a partir de las normales, que está en planos_estereo
            % La proyección en preferences.Projection
            projection=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            nsec=2^app.BinsSpinner.Value;
            angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
            maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
            [polos_estereo_polares, polos_estereo_cartesianas]=f_calcula_coordenadas_polosvn(app.data.planos_estereo, projection);
            % calculo el contour of poles
            [polos_pples, polos_pples_cart,density, X, Y]=f_densidad_y_polospples_v00(polos_estereo_cartesianas,nsec, angulovpples, maxpples,normaliza,projection);

        end
        
        
        function [polos_estereo_polares, polos_estereo_cartesianas, polos_pples_cart,density, X, Y] = f_Update_Poles_Density_Ppal(app,nsec,angulovpples,maxpples,normales,projection,normaliza,calculapicosdensidad)
            % Esta función toma las normales y los polos principales de la tabla, y los recalcula con la proyección que esté seleccionada y la normalización 
            % Inicio las variables que necesito
            % nsec=2^app.BinsSpinner.Value;
            % angulovpples=app.MinangleEditField.Value; % mínimo ángulo entre polos principales
            % maxpples=app.NmaxDSEditField.Value; % máximo número de polos principales
            % normales=app.data.planos_estereo; % vectores normales cargados
            % projection=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            % normaliza=app.NormalisedcontourCheckBox.Value;
            % Tomo las normales, y calculo los polos
            [polos_estereo_polares, polos_estereo_cartesianas]=f_calcula_coordenadas_polosvn(normales, projection);
            % Ahora calculo la densidad
            [polos_pples, polos_pples_cart,density, X, Y]=f_densidad_y_polospples_v00(polos_estereo_cartesianas,nsec, angulovpples, maxpples,normaliza,projection);
            % Los polos principales son los picos de densidad
            % Veo si mantengo los de la tabla o dejo los nuevos calcuados
            switch calculapicosdensidad
                case 0
                    % No calculo picos y mantengo los de la tabla
                    polos_pples=app.UITable_Densidad.Data(:,1:2); % machaco los calculados con los de la tabla
                    [~, polos_pples_cart]=f_calcula_coordenadas_polosvn(polos_pples,projection);
                case 1
                    % dejo los que acabo de calcular, los machaco en la tabla
                    [numpolospples,~]=size(polos_pples);
                    tabla=zeros(numpolospples,4);
                    tabla(:,1:3)=polos_pples;
                    app.UITable_Densidad.Data=tabla;
                    % Actualizo el % de puntos
                    UpdateTable(app);
                otherwise
                    % Algo va mal
            end
            % Los guardo
            
        end
        
        function results = ActualizaTodo(app,normales,rotacion,proyeccion,normaliza,calculapicos,mindensity)
            % Función que recalcula todo: polos rotados, densidad y picos
            % rotacion es 'Original' o 'Rotated'
            % proyeccion es 'Equal-angle' o 'Equal-area'
            % normaliza convierte (reduce) el vmax de densidad a 1. Es un checkbox de la GUI
            % mindensity es el mínimo valor en tanto por uno de la densidad para aceptar ese polo principal
            switch rotacion
                case 'Original'
                    % Como está seleccionada la original, lo dejo como está
                    normales_rotadas=normales;
                case 'Rotated'
                    % Tengo que rotar todo el sistema original
                    angulos=app.AngulosUITable.Data;
                    R = f_calcula_matriz_rotacion(angulos);
                    % Calculo los vectores normales, rotando los polos originales
                    normales_rotadas=(R*normales')';
                    % En la rotación puedo hacer que algunos vectores apunten al
                    % hemisferio inferior, lo normalizo para que todos apunten arriba y los buzamientos sean positivos por definición
                    I=find(normales_rotadas(:,3)<0); 
                    normales_rotadas(I,:)=-normales_rotadas(I,:);app.data.polos_pples_cartesianas_proyectados_rotados;
            end
            % Convierto las normales a vector buzamiento con notación de Clar 
            [ dipdir, dip ] = f_vnorm2clar_v02( normales_rotadas );
            % Calculo los polos rotados, o no si estoy en original
            [ pcx_r,pcy_r ] = f_clar2cart( dipdir,dip, proyeccion );
            polos_cartesianas_proyectadas_rotadas=[pcx_r pcy_r];
            app.data.polos_cartesianas_proyectadas_rotadas=polos_cartesianas_proyectadas_rotadas;

            % Ahora voy con la densidad
            nsec=2^app.BinsSpinner.Value; angulovpples=app.MinangleEditField.Value; maxpples=app.NmaxDSEditField.Value; % Datos para densidad y polos pples
            % [polos_pples, polos_pples_cartesianas_proyectados_rotados, density, X, Y]=f_densidad_y_polospples_v00(polos_cartesianas_proyectadas_rotadas,nsec, angulovpples, maxpples,normaliza,proyeccion);
            bandwidth=app.BandwidthEditField.Value;
            switch bandwidth
                case 0
                    % no hago nada, puede calcular con los dos
                otherwise
                    % no puedo calcular con kde2d, lo hará con mvksdensity
                    % lo cambio en el desplegable para que se vea
                    app.MethodDropDown.Value="mvksdensity";
            end
            mindensity=app.MindensityEditField.Value;
            [polos_pples, polos_pples_cartesianas_proyectados_rotados, density, X, Y]=f_densidad_y_polospples_v01(polos_cartesianas_proyectadas_rotadas,nsec, angulovpples, maxpples,normaliza,proyeccion,bandwidth,mindensity);

            % Guardo los resultados
            app.data.density=density; app.data.X=X; app.data.Y=Y;
            % Calcula picos me dice si tomo los nuevos polos principales que acabo de calcular o dejo los que vienen de la tabla
            switch calculapicos
                case 1
                    % Acepto los polos principales extraidos y los meto en la tabla
                    % Como acabo de calcularlos, tengo la densidad
                    % Si los polos principales están calculados en un espacio rotado, para tener su orientación en el espacio real hay que deshacer la transformación
                    switch rotacion
                        case 'Original'
                            % Como está seleccionada la original, las orientaciones están bien
                            % Los polos_pples son los que tengo de f_densidad_y_polospples_v00 
                        case 'Rotated'
                            % [ normales_polos_pples_rotadas ] = f_pole2vnor_v02( polos_pples_cartesianas_proyectados_rotados,proyeccion );
                            % normales_polos_pples=normales_polos_pples_rotadas*R;
                            % [ dipdir, dip ] = f_vnorm2clar_v02( normales_polos_pples );
                            % Para mejorar eficiencia, lo meto en un paso
                            [ dipdir, dip ] = f_vnorm2clar_v02( f_pole2vnor_v02( polos_pples_cartesianas_proyectados_rotados,proyeccion )*R );
                            polos_pples(:,1)=dipdir;
                            polos_pples(:,2)=dip;
                    end
                    [numpolosprincipales,~]=size(polos_pples);
                    tabla=zeros(numpolosprincipales,4);
                    tabla(:,1:3)=polos_pples(:,1:3);
                    app.UITable_Densidad.Data=tabla; % Actualizo la tabla de resultados
                    app.data.polos_pples_cartesianas_proyectados_rotados=polos_pples_cartesianas_proyectados_rotados;
                    app.data.polos_pples=polos_pples;
                    % Como he cambiado los polos principales, asigno cada punto a su polo principal
                    cone = app.ConeEditField.Value/180*pi;
                    [polos_estereoppalasignados, ~] = f_setppal2planes_v02( cone, app.data.polos_cartesianas_proyectadas_rotadas, polos_pples_cartesianas_proyectados_rotados ,app.data.P,proyeccion);
                    [n,~]=size(app.data.P); % número de puntos totales
                    [nf,~]=size(polos_pples); % número de familias que hay
                    for i=1:nf
                        % calculo el % de puntos que hay y lo pongo en la cuarta columna
                        [npuntos,~]=size(polos_estereoppalasignados(polos_estereoppalasignados(:,3)==i));
                        tabla(i,4)=npuntos/n*100;
                    end
                    % Calculo el total de puntos asignados a los polos
                    totalpoints=sum(tabla(:,4));
                    app.Label_Percentage.Text=num2str(totalpoints);
                    % actualizo la tabla de salida
                    app.UITable_Densidad.Data=tabla;
                    app.data.polos_pples=tabla; % Guardo la edición de los polos principales, con su densidad y % de puntos
                case 0
                    % no acepto los nuevos polos principales y mantengo los que tengo en la tabla
                    % Por si acaso, tomo los principales de la tabla y
                    % calculo sus cartesianas
                    tabla=app.UITable_Densidad.Data;
                    dipdir=tabla(:,1);
                    dip=tabla(:,2);
                    [ xq,yq ] = f_clar2cart( dipdir, dip, proyeccion );
                    % Aunque no calcule los picos, los tengo que rotar
                    switch rotacion
                        case 'Original'
                            % Como está seleccionada la original, las orientaciones están bien
                            % Los polos_pples son los que tengo de f_densidad_y_polospples_v00 
                            % polos_pples_cartesianas_proyectados_rotados=[xq yq];
                            app.data.polos_pples_cartesianas_proyectados_rotados=[xq yq];
                        case 'Rotated'
                            % Los polos principales en cartesianas son xq, yq, que voy a rotar
                            [pcx_r,pcy_r] = f_rotarpoloscartesianas(xq,yq,R,proyeccion);
                            % polos_pples_cartesianas_proyectados_rotados=[pcx_r pcy_r];
                            app.data.polos_pples_cartesianas_proyectados_rotados=[pcx_r pcy_r];
                            % Los valores de la tabla están bien, no se tocan
                    end
                otherwise
                    % Algo falla
            end
            % Con todo rotado, actualizo la figura gráfica
            plot(app);
            results=0;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, datos)
            app.CallingApp=caller;

            % Estoy en pruebas, vamos a ver cómo sacar los picos
            % "nearest", "linear", "natural" y "cubic"
            app.data.interpolacionpicos='linear';

            % Pongo color blanco en el fondo
            % app.EditorofprincipalpolesUIFigure.Color=[0.96,0.96,0.96]; %
            % Color original

            % Toda la información la tengo en datos, la tengo que pasar a
            % app.data y trabajar con esta estructura hasta el final

            app.data.pathname=datos.pathname; % Info del fichero
            app.data.filename=datos.filename;
            app.data.preferences=datos.preferences; % Preferencias o settings
            % Guardo la proyección que tengo en la GUI
            app.ProjectionDropDown.Value=datos.preferences.Projection;

            % Guardo los polos principales o familias, que vienen de la GUI
            % principal
            app.data.polos_pples=datos.polos_pples; % Son los que ya tengo
            app.data.polos_pples_cart=datos.polos_pples_cart;
            app.data.polos_pples_cartesianas_proyectados_rotados=datos.polos_pples_cart; % A esta variable le doy una solución inicial
            app.data.normales=datos.normales;
            app.data.P=datos.P; % coordenadas de los puntos

            % Calculo datos de polos y densidad con el sistema de proyección. Este función toma los polos principales de la tabla, por eso la he actualizado antes
            % Esta función se nutre de la tabla, por lo que machaco los polos principales que he cargado de la GUI principal
            [npolospples,nc]=size(app.data.polos_pples);
            tabla=zeros(npolospples,4);
            tabla(:,1:nc)=app.data.polos_pples;
            app.UITable_Densidad.Data=tabla;

            % Ahora calculo las coordenadas de los polos, los pples y la
            % densidad
            % app.data.normales=datos.normales; % las normales
            % app.data.planos_estereo=datos.planos_estereo; % Las coordenadas cartesianas de los polos
            
            rotacion=app.StereonetDropDown.Value;
            normales=datos.normales; % vectores normales cargados
            proyeccion=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            normaliza=app.NormalisedcontourCheckBox.Value;
            calculapicosdensidad=0; % En el Startup pillo los polos principales de la tabla
            app.data.newwindow=0; % Inicio dibujando cosas en la GUI
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicosdensidad);

            % Los polos principales no necesito ponerlos porque no los ha
            % cambiado al ser la Startup

            % Los datos anteriores son los originales, los guardo en backup
            app.data.polos_pples_original=app.data.polos_pples; % esta cambiará durante la ejecución del programa
            app.data.polos_pples_cart_original=app.data.polos_pples_cart; % esta cambiará durante la ejecución del programa
            app.data.X_original=app.data.X;
            app.data.Y_original=app.data.Y;
            app.data.density_original=app.data.density;
            % app.data.polos_estereo_cartesianas_original=app.data.polos_estereo_cartesianas;
            app.data.polos_estereo_cartesianas_original=app.data.polos_cartesianas_proyectadas_rotadas;

            % Verificar si la variable datos.angulos existe en el workspace
            if isfield(datos, 'angulos')
                switch length(datos.angulos)
                    case 0
                        % No tengo datos, lo inicio en 0
                        app.data.angulos = [0 0 0];
                        app.AngulosUITable.Data = [0 0 0];
                    otherwise
                        % Guardar datos.angulos en app.data.angulos
                        app.data.angulos = datos.angulos;
                        % Guardar datos.angulos en app.AngulosUITable.Data
                        app.AngulosUITable.Data = datos.angulos;
                end
            else
                % Guardar el vector [0 0 0] en app.data.angulos y app.AngulosUITable.Data
                app.data.angulos = [0 0 0];
                app.AngulosUITable.Data = [0 0 0];
            end

            % Preparo un backup de los polos principales para la función de
            % restaurar
            app.data.backup.polos_pples=app.data.polos_pples; % esta cambiará durante la ejecución del programa
            app.data.backup.polos_pples_cart=app.data.polos_pples_cart; % esta cambiará durante la ejecución del programa
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            app.data.polos_pples=app.UITable_Densidad.Data; % valores de los polos principales editados
            app.data.angulos=app.AngulosUITable.Data; % ángulos que he editado, que puede pasar a la la gui de colores y optimización
            app.data.proyeccion=app.ProjectionDropDown.Value;
            app.data.normaliza=app.NormalisedcontourCheckBox.Value;
            updatefunction_editppalpoles(app.CallingApp,app.data);
            delete(app);
        end

        % Close request function: EditorofprincipalpolesUIFigure
        function EditorofprincipalpolesUIFigureCloseRequest(app, event)
            % Cuando cierro la ventana sin guardar, activo de nuevo la
            % ventana principal y elimino esta
            app.CallingApp.DiscontinuitySetExtractor4UIFigure.Visible="on";
            delete(app)          
        end

        % Value changed function: ConeEditField
        function ConeEditFieldValueChanged(app, event)
            value = app.ConeEditField.Value;
            % compruebo que no estoy fuera de los límites
            if value<1 ||  value>90
                uialert(app.EditorofprincipalpolesUIFigure,'The cone angle must be a value between 0 and 90 degrees.','Invalid Angle Value');
            else
                % El valor es correcto y no tengo que hacer nada
                % como ha cambiado el ángulo del cono, actualizo el plot
                plot(app);
                % También actualizo la tabla para el porcentaje de puntos
                % asignado
                UpdateTable(app);
            end
            

        end

        % Value changed function: PolesDSButton
        function PolesDSButtonValueChanged(app, event)
            value = app.PolesDSButton.Value;
            if value==1
                app.Button_2.Value=0;
                app.Button_3.Value=0;
                plot_poles_ds(app);
            else
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            if value==1
                app.PolesDSButton.Value=0;
                app.Button_3.Value=0;
                plot_contours(app);
            else
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            if value==1
                app.PolesDSButton.Value=0;
                app.Button_2.Value=0;
                plot_poles(app);
            else
            end
        end

        % Value changed function: DSEditField
        function DSEditFieldValueChanged(app, event)
            value = app.DSEditField.Value;
            % Qué hacer cuando cambiamos este valor manualmente o
            % automáticamente
            polos_pples=app.data.polos_pples; % los polos principales, como la tabla
            % compruebo que no estoy fuera de los límites
            [nds,~]=size(polos_pples);
            if value<1 || value>nds
                uialert(app.EditorofprincipalpolesUIFigure,{'The selected principal pole is invalid'},'Input error','Icon','warning');
                app.DSEditField.Value=1;
            else
                % No tengo que hacer nada
            end
        end

        % Image clicked function: Insert
        function InsertImageClicked(app, event)
            % Tengo que insertar un nuevo polo principal
            polos_pples=app.data.polos_pples; % los polos principales, como la tabla
            % Tomo el número máximo de polos principales
            [nds,~]=size(polos_pples);
            nuevods=app.DSEditField.Value;
            % Inserto en esa posición una nueva fila
            polos_pples(nuevods+1:nds+1,:)=polos_pples(nuevods:nds,:);
            % La nueva fila la establezco en el cero
            polos_pples(nuevods,:)=0;
            % Guardo los datos
            app.UITable_Densidad.Data=polos_pples;
            % Actualizo la tabla con la densidad nueva y represento la figura
            UpdateTableDS(app, nuevods);
        end

        % Cell selection callback: UITable_Densidad
        function UITable_DensidadCellSelection(app, event)
            indices = event.Indices;
            % he seleccionado una celda
            ds=indices(1);
            app.DSEditField.Value=ds;
        end

        % Cell edit callback: UITable_Densidad
        function UITable_DensidadCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % En primer lugar, compruebo que los valores que se han metido
            % son válidos
            switch indices(2)
                case 1
                    % es dirección de buzamiento
                    if newData<0 || newData>360
                        uialert(app.EditorofprincipalpolesUIFigure,{'The dip direction angle value must be between 0 and 360 degrees'},'Input error','Icon','warning');
                    else
                        % Está bien, actualizo todo
                        ds=indices(1);
                        UpdateTableDS(app,ds);
                    end
                case 2
                    % es dirección de buzamiento
                    if newData<0 || newData>90
                        uialert(app.EditorofprincipalpolesUIFigure,{'The dip angle value must be between 0 and 90 degrees'},'Input error','Icon','warning');
                    else
                        % Está bien, actualizo todo
                        ds=indices(1);
                        UpdateTableDS(app,ds);
                    end
                otherwise

            end
        end

        % Image clicked function: Delete
        function DeleteImageClicked(app, event)
            % elimino el polo principal seleccionado
            polos_pples=app.UITable_Densidad.Data; % los polos principales, como la tabla
            % Tomo el número máximo de polos principales
            [nds,~]=size(polos_pples);
            if nds==1
                % no puedo quitarlo, al menos debe de haber 1
                uialert(app.EditorofprincipalpolesUIFigure,{'At least there must be a principal pole'},'Error','Icon','warning');
            else
                % sí que puedo
            dsborrar=app.DSEditField.Value;
            % Inserto en esa posición una nueva fila
            polos_pples(dsborrar:nds-1,:)=polos_pples(dsborrar+1:nds,:);
            polos_pples(nds,:)=[]; % elimino la última fila
            % si el dsborrar es igual al número de polos principales, debo
            % de actualizar el polo principal seleccionado quitándole uno
            if dsborrar==nds
                app.DSEditField.Value=dsborrar-1;
            end
            % Guardo los datos
            app.data.polos_pples=polos_pples;
            % Actualizo la tabla y represento la figura
            UpdateTable(app); 
            plot(app);
            end

        end

        % Image clicked function: Restart
        function RestartImageClicked(app, event)
            % Restauro los polos principales originales
            app.data.polos_pples=app.data.backup.polos_pples;
            app.data.polos_pples_cart=app.data.backup.polos_pples_cart;
            % Actualizo la tabla 
            UpdateTable(app);
            % Represento la función
            plot_contours(app);

        end

        % Image clicked function: MoveUp
        function MoveUpImageClicked(app, event)
            % Tomo el DS seleccionado y lo muevo una posición arriba
            % Sólo puedo hacerlo si el DS es mayor o igual a 2
            ds=app.DSEditField.Value;
            if ds>1
                % puedorrrr
                app.UITable_Densidad.Data([ds ds-1],:)=app.UITable_Densidad.Data([ds-1 ds],:);
                % actualizo la selección del ds
                app.DSEditField.Value=ds-1;
            else
                % no puedorrrrr
                uialert(app.EditorofprincipalpolesUIFigure,{'The selection is 1, I cannot move it up'},'Error','Icon','warning');
            end
            UpdateTableDS(app, ds);
        end

        % Image clicked function: MoveDown
        function MoveDownImageClicked(app, event)
            % Tomo el DS seleccionado y lo muevo una posición arriba
            % Sólo puedo hacerlo si el DS es menor que la dimensión
            % máxima
            ds=app.DSEditField.Value;
            [nds,~]=size(app.UITable_Densidad.Data);
            if ds<nds
                % puedorrrr
                app.UITable_Densidad.Data([ds ds+1],:)=app.UITable_Densidad.Data([ds+1 ds],:);
                % actualizo la selección del ds
                app.DSEditField.Value=ds+1;
            else
                % no puedorrrrr
                uialert(app.EditorofprincipalpolesUIFigure,{['The selection is ',num2str(ds),', I cannot move it down']},'Error','Icon','warning');
            end
            UpdateTableDS(app, ds);            
        end

        % Value changed function: StereonetDropDown
        function StereonetDropDownValueChanged(app, event)
            rotacion=app.StereonetDropDown.Value;
            normales=app.data.normales; % vectores normales cargados
            proyeccion=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            normaliza=app.NormalisedcontourCheckBox.Value;
            calculapicosdensidad=0; % Cambio la proyección, mantengo los picos
            app.data.newwindow=0; % Inicio dibujando cosas en la GUI
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicosdensidad);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            normales=app.data.normales; % vectores normales cargados
            rotacion=app.StereonetDropDown.Value;
            proyeccion=app.ProjectionDropDown.Value;
            normaliza=app.NormalisedcontourCheckBox.Value;
            mindensity=app.MindensityEditField.Value;
            calculapicos=1;
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicos,mindensity);
        end

        % Cell edit callback: AngulosUITable
        function AngulosUITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            % He cambiado algún ángulo, que muestre el rotado
            app.StereonetDropDown.Value='Rotated';
            rotacion=app.StereonetDropDown.Value;
            normales=app.data.normales; % vectores normales cargados
            proyeccion=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            normaliza=app.NormalisedcontourCheckBox.Value;
            calculapicosdensidad=0; % Estoy rotando, no recalculo picos
            app.data.newwindow=0; % Inicio dibujando cosas en la GUI
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicosdensidad);

        end

        % Button pushed function: NewWindowButton
        function NewWindowButtonPushed(app, event)
            app.data.newwindow=1;
            plot(app);
            app.data.newwindow=0;
        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            % Con la nueva proyección lo calculo todo
            % Preparo los datos
            rotacion=app.StereonetDropDown.Value;
            normales=app.data.normales; % vectores normales cargados
            proyeccion=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            normaliza=app.NormalisedcontourCheckBox.Value;
            calculapicosdensidad=0; % En el Startup pillo los polos principales de la tabla
            app.data.newwindow=0; % Inicio dibujando cosas en la GUI
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicosdensidad);
            plot(app); % Dibujo lo que haya con la nueva proyección
        end

        % Value changed function: PlotLabelsCheckBox
        function PlotLabelsCheckBoxValueChanged(app, event)
            value = app.PlotLabelsCheckBox.Value;
            plot(app);
        end

        % Value changed function: NormalisedcontourCheckBox
        function NormalisedcontourCheckBoxValueChanged(app, event)
            rotacion=app.StereonetDropDown.Value;
            normales=app.data.normales; % vectores normales cargados
            proyeccion=app.ProjectionDropDown.Value; % Proyección Equal-area o Equal-angle
            normaliza=app.NormalisedcontourCheckBox.Value;
            calculapicosdensidad=0; % Cambio la proyección, mantengo los picos
            app.data.newwindow=0; % Inicio dibujando cosas en la GUI
            ActualizaTodo(app, normales, rotacion, proyeccion, normaliza, calculapicosdensidad);
            plot(app); % Dibujo lo que haya con la nueva proyección
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Check the Computer Vision Toolbox version
            toolboxInfo = ver('vision');
            if isempty(toolboxInfo)
                % Computer Vision Toolbox is not installed.
                % Your alternative code goes here.
                uialert(app.UIFigure, 'The Computer Vision Toolbox is not installed. Running alternative code.', 'Warning');
                % ... (Your alternative code here) ...
            elseif verLessThan('vision', '6.2')
                % Computer Vision Toolbox version is less than 6.2.
                % Your alternative code goes here.
                uialert(app.UIFigure, 'The Computer Vision Toolbox version is below 6.2. Running alternative code.', 'Warning');
                % ... (Your alternative code here) ...
            else
                % Computer Vision Toolbox is installed and version is 6.2 or higher.
                % Your original code to display the classified point cloud.
                
                cone=app.ConeEditField.Value;
                polos_cartesianas_proyectadas=app.data.polos_cartesianas_proyectadas_rotadas;
                polos_pples_cart=zeros(size(app.UITable_Densidad.Data(:,1:3)));
                % convierto de Clar a cartesianas
                [ polos_pples_cart(:,1),polos_pples_cart(:,2) ] = f_clar2cart( app.UITable_Densidad.Data(:,1),app.UITable_Densidad.Data(:,2) , app.ProjectionDropDown.Value);
                P=app.data.P;
                proyeccion=app.ProjectionDropDown.Value;
                [polos_estereoppalasignados, puntos_ppalasignados] = f_setppal2planes_v02( cone, polos_cartesianas_proyectadas, polos_pples_cart ,P,proyeccion);
                app.data.polos_estereoppalasignados=polos_estereoppalasignados;
                app.data.puntos_ppalasignados=puntos_ppalasignados;
                xyzPoints = app.data.puntos_ppalasignados(:,1:3);
                centroGravedad = min(xyzPoints);
                xyzPoints_desplazado = xyzPoints - centroGravedad;
                ptCloud = pointCloud(xyzPoints_desplazado);
                ptCloud.Intensity = puntos_ppalasignados(:,4);
                h = figure;
                pcshow(ptCloud, ColorSource="Intensity");

                nds = max(ptCloud.Intensity);
                map = aux_colorcet('R4', 'N', nds); % Assuming aux_colorcet is in the path
                colormap(h, map);

                h.Color = [1 1 1];
                h.CurrentAxes.Color = [1 1 1];
                h.CurrentAxes.GridColor = [0 0 0];
                h.CurrentAxes.XColor = [0 0 0];
                h.CurrentAxes.YColor = [0 0 0];
                h.CurrentAxes.ZColor = [0 0 0];
                h.CurrentAxes.Projection = 'orthographic';
                h.Name = '3D point cloud coloured using one colour for each DS';

                tick_positions = 1:nds;
                c = colorbar;
                c.Ticks = tick_positions;
                c.TickLabels = num2cell(1:nds);
                c.Limits = [0.5 nds+0.5];
                c.TickLength = 0;
                c.FontSize = 10;
                clim([0.5 nds+0.5]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EditorofprincipalpolesUIFigure = uifigure('Visible', 'off');
            app.EditorofprincipalpolesUIFigure.Name = 'Editor of principal poles';
            app.EditorofprincipalpolesUIFigure.CloseRequestFcn = createCallbackFcn(app, @EditorofprincipalpolesUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.EditorofprincipalpolesUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveExitButton = uibutton(app.EditorofprincipalpolesUIFigure, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.UITable_Densidad = uitable(app.EditorofprincipalpolesUIFigure);
            app.UITable_Densidad.ColumnName = {'Dip Dir º'; 'Dip º'; 'Density'; '%'};
            app.UITable_Densidad.ColumnEditable = [true true false false];
            app.UITable_Densidad.CellEditCallback = createCallbackFcn(app, @UITable_DensidadCellEdit, true);
            app.UITable_Densidad.CellSelectionCallback = createCallbackFcn(app, @UITable_DensidadCellSelection, true);
            app.TituloFigura = uilabel(app.EditorofprincipalpolesUIFigure);
            app.TituloFigura.Text = '';
            app.PrincipalPoleMethodButtonGroup = uibuttongroup(app.EditorofprincipalpolesUIFigure);
            app.PrincipalPoleMethodButtonGroup.Title = 'Principal Pole Method';
            app.DensitypeakButton = uiradiobutton(app.PrincipalPoleMethodButtonGroup);
            app.DensitypeakButton.Text = 'Density peak';
            app.DensitypeakButton.Value = true;
            app.EditPanel = uipanel(app.EditorofprincipalpolesUIFigure);
            app.EditPanel.Title = 'Edit';
            app.Restart = uiimage(app.EditPanel);
            app.Restart.ImageClickedFcn = createCallbackFcn(app, @RestartImageClicked, true);
            app.Restart.ImageSource = fullfile(pathToMLAPP, 'img', 'fa-refresh.png');
            app.Delete = uiimage(app.EditPanel);
            app.Delete.ImageClickedFcn = createCallbackFcn(app, @DeleteImageClicked, true);
            app.Delete.Tooltip = {'Remove the selected principal pole'};
            app.Delete.ImageSource = fullfile(pathToMLAPP, 'img', 'fa-calendar-minus-o.png');
            app.MoveDown = uiimage(app.EditPanel);
            app.MoveDown.ImageClickedFcn = createCallbackFcn(app, @MoveDownImageClicked, true);
            app.MoveDown.Tooltip = {'Move down one position'};
            app.MoveDown.ImageSource = fullfile(pathToMLAPP, 'img', 'fa-angle-down.png');
            app.MoveUp = uiimage(app.EditPanel);
            app.MoveUp.ImageClickedFcn = createCallbackFcn(app, @MoveUpImageClicked, true);
            app.MoveUp.Tooltip = {'Move up one position'};
            app.MoveUp.ImageSource = fullfile(pathToMLAPP, 'img', 'fa-angle-up.png');
            app.Insert = uiimage(app.EditPanel);
            app.Insert.ImageClickedFcn = createCallbackFcn(app, @InsertImageClicked, true);
            app.Insert.Tooltip = {'Insert a new principal pole'};
            app.Insert.ImageSource = fullfile(pathToMLAPP, 'img', 'fa-calendar-plus-o.png');
            app.DSEditFieldLabel = uilabel(app.EditPanel);
            app.DSEditFieldLabel.Text = 'DS';
            app.DSEditField = uieditfield(app.EditPanel, 'numeric');
            app.DSEditField.ValueChangedFcn = createCallbackFcn(app, @DSEditFieldValueChanged, true);
            app.DSEditField.Value = 1;
            app.DensityandpeakssettingsPanel = uipanel(app.EditorofprincipalpolesUIFigure);
            app.DensityandpeakssettingsPanel.Title = 'Density and peaks settings';
            app.Label = uilabel(app.DensityandpeakssettingsPanel);
            app.Label.Text = '%:';
            app.Label_Percentage = uilabel(app.DensityandpeakssettingsPanel);
            app.Label_Percentage.Text = '0';
            app.ConeEditFieldLabel = uilabel(app.DensityandpeakssettingsPanel);
            app.ConeEditFieldLabel.Text = 'Cone [º]';
            app.ConeEditField = uieditfield(app.DensityandpeakssettingsPanel, 'numeric');
            app.ConeEditField.ValueChangedFcn = createCallbackFcn(app, @ConeEditFieldValueChanged, true);
            app.ConeEditField.Value = 30;
            app.MinangleEditFieldLabel = uilabel(app.DensityandpeakssettingsPanel);
            app.MinangleEditFieldLabel.Text = 'Min angle';
            app.MinangleEditField = uieditfield(app.DensityandpeakssettingsPanel, 'numeric');
            app.MinangleEditField.Limits = [0 90];
            app.MinangleEditField.Tooltip = {'Minimum angle between normals of the principal discontinuity sets.'};
            app.MinangleEditField.Value = 30;
            app.NmaxDSEditFieldLabel = uilabel(app.DensityandpeakssettingsPanel);
            app.NmaxDSEditFieldLabel.Text = 'Nmax DS';
            app.NmaxDSEditField = uieditfield(app.DensityandpeakssettingsPanel, 'numeric');
            app.NmaxDSEditField.Limits = [1 Inf];
            app.NmaxDSEditField.Tooltip = {'Maximun number of principal planes to extract from the density function'};
            app.NmaxDSEditField.Value = 10;
            app.MindensEditFieldLabel = uilabel(app.DensityandpeakssettingsPanel);
            app.MindensEditFieldLabel.Text = 'Min dens';
            app.MindensityEditField = uieditfield(app.DensityandpeakssettingsPanel, 'numeric');
            app.MindensityEditField.Tooltip = {'Minimum value of the density to accept a peak as a principal pole. Note that a value of 1 represents the maximum density, while 0.1 corresponds to 10% of the maximum density.'};
            app.MindensityEditField.Value = 0.01;
            app.RotationanglesPanel = uipanel(app.EditorofprincipalpolesUIFigure);
            app.RotationanglesPanel.Title = 'Rotation angles [º]';
            app.AngulosUITable = uitable(app.RotationanglesPanel);
            app.AngulosUITable.ColumnName = {'Alpha Z'; 'Beta Y'; 'Gamma X'};
            app.AngulosUITable.RowName = {};
            app.AngulosUITable.ColumnEditable = true;
            app.AngulosUITable.CellEditCallback = createCallbackFcn(app, @AngulosUITableCellEdit, true);
            app.AngulosUITable.Tooltip = {'Enter the rotation angles (Euler''s angles) in degrees.'};
            app.DensityPanel = uipanel(app.EditorofprincipalpolesUIFigure);
            app.DensityPanel.Title = 'Density';
            app.BinsSpinnerLabel = uilabel(app.DensityPanel);
            app.BinsSpinnerLabel.Text = 'Bins';
            app.BinsSpinner = uispinner(app.DensityPanel);
            app.BinsSpinner.Limits = [2 Inf];
            app.BinsSpinner.Tooltip = {'Level of the Bins. The number of bins is 2^(level). Recommended values are 6 or 7.'};
            app.BinsSpinner.Value = 6;
            app.BandwidthEditFieldLabel = uilabel(app.DensityPanel);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.DensityPanel, 'numeric');
            app.BandwidthEditField.Limits = [0 Inf];
            app.MethodDropDownLabel = uilabel(app.DensityPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.DensityPanel);
            app.MethodDropDown.Items = {'KDE2D', 'ksdensity', 'mvksdensity'};
            app.MethodDropDown.Value = 'KDE2D';
            app.Button = uibutton(app.DensityPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'image_fa-area-chart.png');
            app.Button.Tooltip = {'Find principal poles on the stereonet (get the peaks)'};
            app.Button.Text = '';
            app.PlotPanel = uipanel(app.EditorofprincipalpolesUIFigure);
            app.PlotPanel.Title = 'Plot';
            app.NewWindowButton = uibutton(app.PlotPanel, 'push');
            app.NewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @NewWindowButtonPushed, true);
            app.NewWindowButton.Text = 'New Window';
            app.PolesDSButton = uibutton(app.PlotPanel, 'state');
            app.PolesDSButton.ValueChangedFcn = createCallbackFcn(app, @PolesDSButtonValueChanged, true);
            app.PolesDSButton.Tooltip = {'Show poles assigned to the corresponding discontinuity set'};
            app.PolesDSButton.Icon = fullfile(pathToMLAPP, 'imagen_stereonet_poles_classified_02.png');
            app.PolesDSButton.Text = '';
            app.Button_3 = uibutton(app.PlotPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Tooltip = {'Show poles on the stereonet'};
            app.Button_3.Icon = fullfile(pathToMLAPP, 'imagen_stereonet_poles.png');
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.PlotPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Tooltip = {'Show the contour plot'};
            app.Button_2.Icon = fullfile(pathToMLAPP, 'imagen_stereonet.png');
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.StereonetDropDownLabel = uilabel(app.PlotPanel);
            app.StereonetDropDownLabel.Text = 'Stereonet';
            app.StereonetDropDown = uidropdown(app.PlotPanel);
            app.StereonetDropDown.Items = {'Original', 'Rotated'};
            app.StereonetDropDown.ValueChangedFcn = createCallbackFcn(app, @StereonetDropDownValueChanged, true);
            app.StereonetDropDown.Value = 'Original';
            app.ProjectionDropDownLabel = uilabel(app.PlotPanel);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.PlotPanel);
            app.ProjectionDropDown.Items = {'Equal-angle', 'Equal-area', 'Equal-proportion'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'Equal-angle';
            app.PlotLabelsCheckBox = uicheckbox(app.PlotPanel);
            app.PlotLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotLabelsCheckBoxValueChanged, true);
            app.PlotLabelsCheckBox.Text = 'Plot Labels';
            app.PlotLabelsCheckBox.Value = true;
            app.NormalisedcontourCheckBox = uicheckbox(app.PlotPanel);
            app.NormalisedcontourCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalisedcontourCheckBoxValueChanged, true);
            app.NormalisedcontourCheckBox.Tooltip = {'Sets the maximum densisty to 1'};
            app.NormalisedcontourCheckBox.Text = 'Normalised contour';
            app.NormalisedcontourCheckBox.Value = true;
            app.Button_4 = uibutton(app.PlotPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'imagen_3DPC_clasificada_cubo.png');
            app.Button_4.Tooltip = {'Show the 3DPC coloured according to the assigned DS'};
            app.Button_4.Text = '';
            app.ContextMenu = uicontextmenu(app.EditorofprincipalpolesUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.NewWindowButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.EditorofprincipalpolesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_ppalpoleseditor_v10(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditorofprincipalpolesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditorofprincipalpolesUIFigure)
        end
    end
end