classdef gui_preferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveandExitButton          matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        StereogramTab              matlab.ui.container.Tab
        NormalisedcontourCheckBox  matlab.ui.control.CheckBox
        ProjectionDropDown         matlab.ui.control.DropDown
        ProjectionDropDownLabel    matlab.ui.control.Label
        Tab2                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CallingApp % Es la función que llama a esta ventana, me permite identificarla cuando al final llame al updatefunction
        data % donde voy a guardar todos mis datos durante este programa
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, datos)
            app.CallingApp=caller;

            % Toda la información la tengo en datos, la tengo que pasar a
            % app.data y trabajar con esta estructura hasta el final
            app.data.preferences=datos.preferences;
            % Establezco las preferencias que tengo guardadas
            app.NormalisedcontourCheckBox.Value=app.data.preferences.Normalisedcontour;
            app.ProjectionDropDown.Value=app.data.preferences.Projection;

        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            updatefunction_preferences(app.CallingApp,app.data);
            % Esto llama a la función pública de la app principal y le pasa
            % los datos de app.data, que en la ppal será datos
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            SaveandExitButtonPushed(app);
            app.CallingApp.DisconutnuitySetExtractor3UIFigure.Visible="on";
            delete(app);
        end

        % Value changed function: NormalisedcontourCheckBox
        function NormalisedcontourCheckBoxValueChanged(app, event)
            value = app.NormalisedcontourCheckBox.Value;
            app.data.preferences.Normalisedcontour=value;
        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            value = app.ProjectionDropDown.Value;
            app.data.preferences.Projection=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StereogramTab = uitab(app.TabGroup);
            app.StereogramTab.Title = 'Stereogram';
            app.ProjectionDropDownLabel = uilabel(app.StereogramTab);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.StereogramTab);
            app.ProjectionDropDown.Items = {'Equal-angle', 'Equal-area'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'Equal-angle';
            app.NormalisedcontourCheckBox = uicheckbox(app.StereogramTab);
            app.NormalisedcontourCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalisedcontourCheckBoxValueChanged, true);
            app.NormalisedcontourCheckBox.Text = 'Normalised contour';
            app.NormalisedcontourCheckBox.Value = true;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.SaveandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_preferences(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end